classdef HybridBCI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HybridBCIUIFigure              matlab.ui.Figure
        tbMain                         matlab.ui.container.TabGroup
        ConfigurationTab               matlab.ui.container.Tab
        EEGSwitch                      matlab.ui.control.Switch
        EEGSwitchLabel                 matlab.ui.control.Label
        ExperimentPanel                matlab.ui.container.Panel
        lBaseline                      matlab.ui.control.Label
        SentenceLabel                  matlab.ui.control.Label
        SentenceBaseline               matlab.ui.control.NumericEditField
        BlockLabel                     matlab.ui.control.Label
        BlockBaseline                  matlab.ui.control.NumericEditField
        lEEGDuration                   matlab.ui.control.Label
        efEEGDuration                  matlab.ui.control.NumericEditField
        lNIRSDuration                  matlab.ui.control.Label
        efNIRSDuration                 matlab.ui.control.NumericEditField
        lThinking                      matlab.ui.control.Label
        PresentationEditFieldLabel     matlab.ui.control.Label
        efPresentation                 matlab.ui.control.NumericEditField
        NIRSSwitch                     matlab.ui.control.Switch
        NIRSSwitchLabel                matlab.ui.control.Label
        TriggersPanel                  matlab.ui.container.Panel
        ParallelPorSearchButton        matlab.ui.control.Button
        btnTestParallel                matlab.ui.control.Button
        HardwareListBoxLabel           matlab.ui.control.Label
        lstbxHardwareDevices           matlab.ui.control.ListBox
        SoftwareListBoxLabel           matlab.ui.control.Label
        lstbxSoftwareDevices           matlab.ui.control.ListBox
        PortLabel                      matlab.ui.control.Label
        ParallelPort                   matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        ExperimentersPanel             matlab.ui.container.Panel
        NewExperimenterEditFieldLabel  matlab.ui.control.Label
        efNewExperimenter              matlab.ui.control.EditField
        lstbxExperimenters             matlab.ui.control.ListBox
        bthAddExperimenter             matlab.ui.control.Button
        ddEEGDevices                   matlab.ui.control.DropDown
        ddNIRSDevices                  matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        PreviewResultConfig            matlab.ui.control.TextArea
        ExperimentTab                  matlab.ui.container.Tab
        DesignPanel                    matlab.ui.container.Panel
        PatientIDDropDownLabel         matlab.ui.control.Label
        ddPatient                      matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        ddModel                        matlab.ui.control.DropDown
        BlockSpinnerLabel              matlab.ui.control.Label
        BlockSpinner                   matlab.ui.control.Spinner
        SentenceNumberSpinnerLabel     matlab.ui.control.Label
        SentenceNumberSpinner          matlab.ui.control.Spinner
        FeedbackDropDownLabel          matlab.ui.control.Label
        ddFeedback                     matlab.ui.control.DropDown
        FeedBackSourceDropDownLabel    matlab.ui.control.Label
        ddFeedBackSource               matlab.ui.control.DropDown
        TaskDropDownLabel              matlab.ui.control.Label
        ddTask                         matlab.ui.control.DropDown
        btnStart                       matlab.ui.control.Button
        PreviewPanel                   matlab.ui.container.Panel
        PreviewResult                  matlab.ui.control.TextArea
        EEGLampLabel                   matlab.ui.control.Label
        EEGLamp                        matlab.ui.control.Lamp
        NIRSLampLabel                  matlab.ui.control.Label
        NIRSLamp                       matlab.ui.control.Lamp
        btnEEGRun                      matlab.ui.control.Button
        btnNIRSRun                     matlab.ui.control.Button
        cbTestWithNoDataAcquisition    matlab.ui.control.CheckBox
        ApplicationsTab                matlab.ui.container.Tab
        ApplicationPanel               matlab.ui.container.Panel
        ModelDropDown_2Label           matlab.ui.control.Label
        ddModelApplications            matlab.ui.control.DropDown
        btnConfigApplication           matlab.ui.control.Button
        FeedBackSourceDropDown_2Label  matlab.ui.control.Label
        ddFeedBackSourceApplication    matlab.ui.control.DropDown
        PatientIDDropDownLabel_2       matlab.ui.control.Label
        ddPatientApplication           matlab.ui.control.DropDown
        ParadigmLabel                  matlab.ui.control.Label
        ddApplications                 matlab.ui.control.DropDown
        BlockSpinner_2Label            matlab.ui.control.Label
        BlockSpinnerApplication        matlab.ui.control.Spinner
        PreviewPanelApplications       matlab.ui.container.Panel
        PreviewResultApplications      matlab.ui.control.TextArea
        btnEEGRUN_Applications         matlab.ui.control.Button
        btnNIRSRUN_Applications        matlab.ui.control.Button
        EEGLamp_2Label                 matlab.ui.control.Label
        EEGLamp_2                      matlab.ui.control.Lamp
        NIRSLamp_2Label                matlab.ui.control.Label
        NIRSLamp_2                     matlab.ui.control.Lamp
        btnStartApplications           matlab.ui.control.Button
        cbTestWithNoDataAcquisition_Application  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        isConfigured = 0;
        
        selectedModel;
        
        configinfo = [];
        
        parallelPort;
        
        psyToolBxHandler = [];
        
        stopRoutin = 0;
        
        triggerMessage = {' Feedback - Yes',' Feedback - No','Feedback - Open',' Thinking - Yes',' Presentation - Yes',' Presentation - No',' Presentation - Open',' Thinking - No','Bolck Start',' Baseline - Yes',' Baseline - No',' Baseline - Open',' Thinking - Open',' Bug found',' Block End'};
        
        feedBackSource = [];
        
        pathExperimentersList = [pwd filesep 'db' filesep 'Experimenters.mat']; % Path to matfile with list of experimenters
        pathTasks             = [pwd filesep 'db' filesep 'Tasks.mat']; % Path to matfile with list of tasks
        
        pathEEGDevices        = [pwd filesep 'lib' filesep 'Devices' filesep 'EEGDevices']; % Path to list of EEG Devices
        pathNIRSDevices       = [pwd filesep 'lib' filesep 'Devices' filesep 'NIRSDevices'];% Path to list of NIRS Devices
        
        pathHWDevices         = [pwd filesep 'lib' filesep 'Devices' filesep 'TriggerDevices' filesep 'Hardware']; % Path to list of device objects
        pathSWDevices         = [pwd filesep 'lib' filesep 'Devices' filesep 'TriggerDevices' filesep 'Software'];
        
        pathApplications      = [pwd filesep 'lib' filesep 'Applications' filesep 'Paradigms']% Description


        tempFile = []; % temp file for communicating with EEG and NIRS matlab
        
                
        modelBuilderApp;
        
        model; % The model used for feedback
        triggerDevices; % the devices used for trigger
        
        paradigm % The paradigm used for the application
        
        % Added by Jayro
        predicted_labels % To save predicted labels during experiments
        
        isPsychToolBoxInstalled = true;
    end
    
    
    methods (Access = public)
        
        function ShowMessage(app, msg, textArea)
        % ShowMessage  shows a message in the Preview Panel.
        % msg: string to print.
        % textArea: if a TextArea is passed, its' value will be
        % concatenated to the msg.
        
            msg = strcat({'- '}, msg);
            textArea.Value = [msg; textArea.Value; ];
            drawnow;
        end
        
        function InitializeToolboxes(app)
        % InitializeToolboxes checks if Psychotoolbox is isntalled intializes it.
        
            % Check if Psychtoolbox is installed
            try
                PsychtoolboxVersion;
                app.isPsychToolBoxInstalled = true;
            catch ME
                app.isPsychToolBoxInstalled = false;
                %error('Psychtoolbox is not installed');
                return;
            end
            
            app.ShowMessage('Initilizing Psychtoolbox - v2', app.PreviewResult);
            
            %%%%% Initialization of the Sound Drive
            % Initialize the sound drive, check very low latency option.
            InitializePsychSound(); % changed to default (no value)
            app.ShowMessage('Initilized', app.PreviewResult);
            freq = 44100; % set into the needed frequency
            nrchannels=2; % define if mono or stereo channels
            
            %config_io; %Uncomment
            
            %%% sanity check for the desired SF
            try
                app.ShowMessage('Try', app.PreviewResult);
                % Try with the 'freq'uency we wanted:
                app.psyToolBxHandler = PsychPortAudio('Open', [], [], 0, freq, nrchannels);
            catch
                app.ShowMessage('Catch', app.PreviewResult);
                % Failed. Retry with default frequency as suggested by device:
                app.ShowMessage('Could not open device at wanted playback frequency of Hz. Will retry with device default frequency.', app.PreviewResult);
                app.ShowMessage('Sound may sound a bit out of tune, ...', app.PreviewResult);
                
                psychlast ('reset');
                app.psyToolBxHandler = PsychPortAudio('Open', [], [], 0, [], nrchannels);
            end
            app.ShowMessage('Done', app.PreviewResult);
            
            
        end
        
        function msg = SendTrigger(app, trg)
        % SendTrigger goes through all the set triggers and call them one by one
        % and send the trigger.
        % trg: the tigger number (int).
        
            % check if it is the test mode dont send triggers
            if ~app.cbTestWithNoDataAcquisition.Value && ~app.cbTestWithNoDataAcquisition_Application.Value
                for dev = app.triggerDevices
                    dev{1}.sendTrigger(trg)
                    disp(dev{1});
                    disp(trg);
                end
            end
            
            
            msg = sprintf('Trigger: %d',trg);
        end
        
        function  RunQuestionParadigm(app)
        % RunQuestionParadigm rund the question paradigm with the configuration set in the in the Experiment panel in the
        % Configuration Tab. 
        
            % Check if Stop is clicked and return
            if app.stopRoutin == 1
                return;
            end
            
            %Reset
            app.predicted_labels = [];
            
            % Feedback audio files
            feedback_path  = [app.configinfo.subpath filesep 'Audios' filesep 'Feedback'];
            wavDanke       = importdata([feedback_path filesep 'Danke.wav']);
            wavJa          = importdata([feedback_path filesep 'Ja.wav']);
            wavNein        = importdata([feedback_path filesep 'Nein.wav']);
            
            
            [sentences,Fh,trigger] = CLISLAB_BuildSentences(app.configinfo.subpath,app.configinfo.SentenceNumber,1,app.configinfo.blocknum);
            
            buffer   = [];
            for i=1:length(sentences)
                audiodata = sentences{i};
                
                [samplecount, ninchannels] = size(audiodata);
                audiodata                  = repmat(transpose(audiodata), 2 / ninchannels, 1);
                buffer(i)                  = PsychPortAudio('CreateBuffer', [], audiodata);%puts all 20 HEADS + 20 lw + 5 eS sentences in a buffer
            end
            
            if strcmp(app.configinfo.EEG,'Off')
                ITinterval = app.configinfo.NIRSDuration;
            elseif strcmp(app.configinfo.NIRS,'Off')
                ITinterval = app.configinfo.EEGDuration;
            else
                ITinterval = max(app.configinfo.NIRSDuration, app.configinfo.EEGDuration);
            end
            
            
            num_correct = 0;
            num_incorrect = 0;
            
            
            % Check if Stope is clicked and return
            WaitSecs(.00001);
            if app.stopRoutin == 1
                return;
            end
            
            app.SendTrigger(0); % Clear Port
            
            trgMsg = app.SendTrigger(9);
            app.ShowMessage(trgMsg, app.PreviewResult);
            WaitSecs(app.configinfo.BaselineBlock);
            
            %Main Loop for stimulation
            for i=1:length(trigger)
                
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                
                if trigger(i)==1     %strcmpi(trigger(i),'A')
                    flag  = 1;       % start of true sentence
                    flag2 = 5;       % start of LW true sentence
                    flag3 = 4;       % start of ISI true sentence
                    flag4 = 10;      % start of baseline true sentence
                elseif trigger(i)==0 %strcmpi(trigger(i),'B')
                    flag  = 2;       % start of false sentence
                    flag2 = 6;       % start of LW false sentence
                    flag3 = 8;       % start of ISI false sentence
                    flag4 = 11;      % start of baseline false sentence
                elseif trigger(i)==2
                    flag  = 3;       % start of open sentence
                    flag2 = 7;       % start of LW open sentence
                    flag3 = 13;      % start of ISI open sentence
                    flag4 = 12;      % start of baseline open sentence
                end
                
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                
                app.ShowMessage(['trial ' num2str(i)], app.PreviewResult);
                app.ShowMessage([ 'Question ' num2str(i) ' / ' num2str(app.configinfo.SentenceNumber)], app.PreviewResult);
                
                %% BASELINE
                trgMsg = app.SendTrigger(flag4);
                app.ShowMessage(trgMsg, app.PreviewResult);
                
                WaitSecs(app.configinfo.BaselineSentence);
                
                PsychPortAudio('FillBuffer', app.psyToolBxHandler, buffer(i));
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                
                %% Added by Ale to avoid missing triggers
                %WaitSecs(0.1);
                
                %% PLAY SENTENCE
                trgMsg = app.SendTrigger(flag2);
                app.ShowMessage(trgMsg, app.PreviewResult);
                PsychPortAudio('Start', app.psyToolBxHandler, 1, 1, 0);
                psyStatus = PsychPortAudio('GetStatus', app.psyToolBxHandler);
                while psyStatus.State ~= 0
                    pause(0.0001);
                    psyStatus = PsychPortAudio('GetStatus', app.psyToolBxHandler);
                end
                
                
                % Check if Stope is clicked and return
                WaitSecs(.0001);
                if app.stopRoutin == 1
                    return;
                end
                
                %% THINKING PERIOD
                trgMsg = app.SendTrigger( flag3);
                app.ShowMessage(trgMsg, app.PreviewResult);
                
                % Added by Ale
                WaitSecs(ITinterval);
                
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                
                
                %% FEEDBACK
                trgMsg = app.SendTrigger(flag);
                app.ShowMessage(trgMsg, app.PreviewResult);
                
                if strcmp(app.configinfo.FeedBackType,'No')
                    wavFeedback  = wavDanke;
                elseif app.cbTestWithNoDataAcquisition.Value
                    
                    pred = -1;
                    while pred == -1
                        i = input('The answer is (y/n): ', 's');
                        if strcmp(i, 'y')
                            pred = 1;
                            wavFeedback  = wavJa;
                        elseif strcmp(i, 'n')
                            pred = 0;
                            wavFeedback  = wavNein;
                        else
                            pred = -1;
                            disp('Invalid input, use y or n only');
                        end
                    end
                    app.predicted_labels = cat(1, app.predicted_labels, pred);
                    
                    
                else
                    % Majid: last trail for EEG and NIRS are now saved and load seprately
                    % Load last trial
                    % Ale: ~lastTrialEEG AND ~lastTrialNIRS instead of ~lastTrialEEG OR ~lastTrialNIRS
                    %                     while ~exist('lastTrialEEG.mat','file') && ~exist('lastTrialNIRS.mat','file')
                    %                         WaitSecs(0.1)
                    %                     end
                    
                    
                    if strcmp(app.feedBackSource,'EEG')
                        while ~exist('lastTrialEEG.mat','file')
                            WaitSecs(0.1)
                        end
                        lastTrial = importdata('lastTrialEEG.mat');
                        delete('lastTrialEEG.mat');
                        % Reject channels
                        lastTrial = app.rejectChannels(lastTrial);
                        
                        % Preprocessing
                        % corrected by wu on 2019.05.14
                        %                         [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_EEG_Online_Preprocessing(lastTrial.baseline, lastTrial.thinkingPeriod, lastTrial.channelLabels, app.model.sRate, app.model.filterType, app.model.interprocess, app.model.amplitudeCorrection);
                        [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_EEG_Online_Preprocessing(lastTrial.baseline, lastTrial.thinkingPeriod, lastTrial.channelLabels, app.model.sRate, app.model.filterType, app.model.processing, app.model.selectedSource,app.model.selectedBands);
                        % Feature Extraction
                        % corrected by wu on 2019.05.14
                        %                           [features, featuresLabels]= CLISLAB_EEG_Online_ExtractFeatures(preprocessedThinkingData,app.model.sRate,app.model.selectedSource ,app.model.usedFeatures);
                        [features, featuresLabels]= CLISLAB_EEG_Online_ExtractFeatures(preprocessedThinkingData,app.model.sRate,app.model.usedFeatures);
                    elseif strcmp(app.feedBackSource,'NIRS')
                        while ~exist('lastTrialNIRS.mat','file')
                            WaitSecs(0.1)
                        end
                        lastTrial = importdata('lastTrialNIRS.mat');
                        delete('lastTrialNIRS.mat');
                        % Reject channels
                        lastTrial = app.rejectChannels(lastTrial);
                        
                        % Preprocessing
                        [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_NIRS_Online_Preprocessing(lastTrial.baseline, lastTrial.thinkingPeriod, app.model.sRate, app.model.conversionType, app.model.baselineReference, app.model.filterType, app.model.filterCutFreqs, app.model.processing);
                        % Feature Extraction
                        %featureList = app.model.selectedFeatures(:,1);
                        %featureListUnique = unique(featureList);
                        [features, featuresLabels]= CLISLAB_NIRS_Online_ExtractFeatures(preprocessedThinkingData,app.model.sRate,app.model.selectedSource, app.model.usedFeatures);
                        
                        % Selected features
                        %idxFeat = all(ismember(featuresLabels,app.model.selectedFeatures,'rows'),2);
                        %features = features(idxFeat);
                    else
                        %EEG+NIRS
                    end
                    
                    %Selected Features
                    [features,featuresLabels] = CLISLAB_SelectFeatures(features, featuresLabels,app.model.selectedFeatures)
                    
                    
                    % Classification
                    [pred, prob, acc] = CLISLAB_PredictLabel(app.model,features,lastTrial.questionLabel);
                    
                    % Show feedback
                    string_classif = sprintf('Classification: %d (%.3f%%)',pred,prob);
                    
                    % Save Predicted Label
                    % Added by Jayro
                    app.predicted_labels = cat(1, app.predicted_labels, pred);
                    
                    if pred == lastTrial.questionLabel
                        predCorrect = 1;
                        num_correct = num_correct+1;
                        string_classif = sprintf('%s - Correct - %d/%d',string_classif,num_correct,num_incorrect);
                    else
                        predCorrect = 0;
                        num_incorrect = num_incorrect+1;
                        string_classif = sprintf('%s - Incorrect - %d/%d',string_classif,num_correct,num_incorrect);
                    end
                    
                    app.ShowMessage(string_classif, app.PreviewResult);
                    
                    % Load feedback audio file
                    if strcmp(app.configinfo.FeedBackType,'Text') || (strcmp(app.configinfo.FeedBackType,'Only Corrects') && ~predCorrect)
                        wavFeedback  = wavDanke;
                    elseif pred == 0
                        wavFeedback  = wavJa;
                    elseif pred == 1
                        wavFeedback  = wavNein;
                    else % this should never happen
                        wavFeedback  = wavDanke;
                    end
                end
                
                % Play feedback
                PsychPortAudio('FillBuffer', app.psyToolBxHandler, wavFeedback.data');
                PsychPortAudio('Start', app.psyToolBxHandler, 1, 1, 0);
                psyStatus = PsychPortAudio('GetStatus', app.psyToolBxHandler);
                while psyStatus.State ~= 0
                    pause(0.0001);
                    psyStatus = PsychPortAudio('GetStatus', app.psyToolBxHandler);
                end
                
                
            end
            
            WaitSecs(1);
            trgMsg = app.SendTrigger(15);
            app.ShowMessage(trgMsg, app.PreviewResult);
            if ~strcmp(app.configinfo.FeedBackType,'No')
                tot_class = sprintf('Total classification: %d/%d (%.1f%%)',num_correct,num_incorrect,100*num_correct/app.configinfo.SentenceNumber);
                app.ShowMessage(tot_class, app.PreviewResult);
            end
            
            app.UpdateMaxBlockNumber;
            app.BlockSpinner.Value = app.BlockSpinner.Value + 1;
            app.EnableUI(true);
            
        end
        
        function EnableUI(app, en)
        % Once the experiment is being run, the configuration settings are
        % disabled, and again after the block is finished thay can be
        % activated again.
        % en: boolean to indicated enable/disable configuration GUI
        % elements.
        
            if en
                for i = 1:size(app.DesignPanel.Children,1)
                    app.DesignPanel.Children(i).Enable = 'on';
                end
                
                app.btnStart.Text='Start';
                if strcmp(app.ddFeedback.Value,'No')
                    app.ddFeedBackSource.Enable = 'off';
                    app.ddModel.Enable = 'off';
                end
            else
                for i = 1:size(app.DesignPanel.Children,1)
                    app.DesignPanel.Children(i).Enable = 'off';
                end
                
                app.btnStart.Text = 'Stop';
                
                app.EEGLamp.Color  = 'r';
                app.NIRSLamp.Color = 'r';
                
            end
        end
        
        function UpdateExperimenterList(app)
        % UpdateExperimenterList Updates the experimenter's list in the GUI
        % from file. 
        
            load(app.pathExperimentersList);
            app.lstbxExperimenters.Items = experimenters;
            
        end
        
        function UpdateTasks(app)
        % Refresh tasks list after adding a new one
        % Added by Jayro
        
            load(app.pathTasks);
            app.ddTask.Items = Tasks;
        end
        
        function UpdateModelList(app)
        % UpdateModelList updates model's list in the GUI based on the all
        % saved models in the subject's folder.
        % Added by Ale
        
            allModelFiles = dir(fullfile(app.configinfo.subpath,'Models',app.ddFeedBackSource.Value,'*_model.mat'));
            [~,idx] = sort([allModelFiles.datenum],'descend');
            allModelFiles = allModelFiles(idx);
            app.ddModel.Items =  {'None', 'Build New Model' ,allModelFiles.name};
        end
        
        function UpdateModelListApplication(app)
        % UpdateModelList updates model's list in the GUI based on the all
        % saved models in the subject's folder.
        
            allModelFiles = dir(fullfile(app.configinfo.subpath,'Models',app.ddFeedBackSource.Value,'*_model.mat'));
            [~,idx] = sort([allModelFiles.datenum],'descend');
            allModelFiles = allModelFiles(idx);
            app.ddModelApplications.Items =  {'None', 'Build New Model' ,allModelFiles.name};
        end
        
        function InitUI(app)
        % Setting and configuring UI elemetns. 
        
            % Centering GUI
            movegui(app.HybridBCIUIFigure,"center");
            
            app.ddEEGDevices.Visible   = false;
            app.ddNIRSDevices.Visible  = false;
            app.lThinking.Visible      = false;
            app.lEEGDuration.Visible   = false;
            app.efEEGDuration.Visible  = false;
            app.efNIRSDuration.Visible = false;
            app.lNIRSDuration.Visible  = false;
            
            
            app.ddEEGDevices.Items  = CLISLAB_GetListFilesFromFolder(app.pathEEGDevices,'mlapp',0);
            app.ddNIRSDevices.Items = CLISLAB_GetListFilesFromFolder(app.pathNIRSDevices,'mlapp',0);
            
            app.lstbxHardwareDevices.Items = CLISLAB_GetListFilesFromFolder(app.pathHWDevices,'m',0);
            app.lstbxSoftwareDevices.Items = CLISLAB_GetListFilesFromFolder(app.pathSWDevices,'m',0);
            
            
            app.ddPatient.Items =  cat(2,"-",CLISLAB_GetListFoldersInFolder([pwd filesep 'Subjects' ]))';
            
            app.UpdateExperimenterList();
            
            app.ddPatientApplication.Items = app.ddPatient.Items;
            allParadigms = CLISLAB_GetListFilesFromFolder(app.pathApplications,'m',0);
            app.ddApplications.Items = ["None" allParadigms];
            
            tasks = load(app.pathTasks);
            app.ddTask.Items = tasks.Tasks;
            
            app.configinfo.FigHybridBCI = app.HybridBCIUIFigure;
            
            app.InitializeToolboxes();
        end
        
    end
    
    methods (Access = private)
        
        function UpdateMaxBlockNumber(app)
        % UpdateMaxBlockNumber checks for the last saved blocked number and
        % update the number in the block spinner. 
        
            allBlocks = dir(fullfile(app.configinfo.subpath,date,'config*.mat'));
            allBlocksNames = string({allBlocks.name});
            allBlocksNum = regexp(allBlocksNames,'\d*','match');
            allBlocksNum = str2double(string(allBlocksNum));
            lastNum = max(allBlocksNum);
            if isempty(lastNum)
                app.BlockSpinner.Limits = [1 Inf];
                %app.BlockSpinner.Enable = 'off';
            else
                app.BlockSpinner.Limits = [1 lastNum+1];
                %app.BlockSpinner.Enable = 'on';
            end
        end
        
        function UpdateMaxBlockNumberApplication(app)
        % UpdateMaxBlockNumber checks for the last saved blocked number and
        % update the number in the block spinner. 
        
            allBlocks = dir(fullfile(app.configinfo.subpath,date,'config*.mat'));
            allBlocksNames = string({allBlocks.name});
            allBlocksNum = regexp(allBlocksNames,'\d*','match');
            allBlocksNum = str2double(string(allBlocksNum));
            lastNum = max(allBlocksNum);
            if isempty(lastNum)
                app.BlockSpinnerApplication.Limits = [1 Inf];
                %app.BlockSpinner.Enable = 'off';
            else
                app.BlockSpinnerApplication.Limits = [1 lastNum+1];
                %app.BlockSpinner.Enable = 'on';
            end
        end
        
        function trial = rejectChannels(app,trial)
        % rejectChannels rejects a channel in the all selcted trials.
        
            % Load the channels from the model and delete the rejected ones
            channelsModel = app.model.channels;
            %channelsModel(rejectedChannels) = [];
            
            % Load the channels from the trial
            channelsTrial = trial.channelLabels;
            
            % check if all the channels of the model are present in the trial
            if any(~ismember(channelsModel,channelsTrial))
                error(['In the trial are missing channels: ' strjoin(channelsModel(~ismember(channelsModel,channelsTrial)))]);
            end
            
            % take only channels used in the model
            [channelsUsed, channelsOrder] = ismember(channelsTrial,channelsModel);
            
            trial.channelLabels = channelsTrial(channelsUsed);
            trial.baseline = trial.baseline(channelsUsed,:);
            trial.thinkingPeriod = trial.thinkingPeriod(channelsUsed,:);
            
            % order in the same order of the model
            trial.channelLabels = trial.channelLabels(channelsOrder(channelsUsed));
            trial.baseline = trial.baseline(channelsOrder(channelsUsed),:);
            trial.thinkingPeriod = trial.thinkingPeriod(channelsOrder(channelsUsed),:);
            
        end
        
        function err = configureDevices(app)
        % configureDevices loops over all selcted trigger devices (hardware and software)
        % and initialize them and congiure them based on the information
        % provided in the configuration tab. 
        
            err = 0;
            % List all the devices hw and sw
            allDevices = [app.lstbxHardwareDevices.Value app.lstbxSoftwareDevices.Value];
            allProperties = [];
            allPropDeviceNum = [];
            
            % Clear the selected devices
            for d = 1:length(app.triggerDevices)
                app.triggerDevices{d}.exit();
            end
            app.triggerDevices = [];
            
            % For each device check if there is any property to set
            for d = 1:length(allDevices)
                deviceHandle = str2func(allDevices{d});
                app.triggerDevices{d} = deviceHandle();
                allProp = properties(allDevices{d});
                for p = 1:length(allProp)
                    try
                        defInput = app.triggerDevices{d}.(allProp{p});
                    catch
                        defInput = [];
                    end
                    allProperties = [allProperties; {d allDevices{d} allProp{p} num2str(defInput)}];
                end
            end
            
            if ~isempty(allProperties)
                prompt = allProperties(:,2) + ": " + allProperties(:,3);
                title = 'Configure devices';
                dims = 1;
                defInputs = allProperties(:,4);
                answer = inputdlg(prompt,title,dims,defInputs);
                if isempty(answer) || any(cellfun(@isempty,answer))
                    err = 1;
                else
                    for a = 1:length(answer)
                        devNum = allProperties{a, 1};
                        app.triggerDevices{devNum}.(allProperties{a, 3}) = answer{a};
                        
                    end
                end
            end
        end
        
        function enableApplicationsUI(app,en)
        % enableApplicationsUI enables or disable the configuration UI
        % elements once starting / stoping a block.
        % en: boolean, indicating state of the GUI elements
        % (enable/diable).
        
            if en
                app.btnConfigApplication.Enable = 'On';
                app.ddFeedBackSourceApplication.Enable = 'On';
                app.ddModelApplications.Enable = 'On';
                %app.btnStartApplications.Enable = 'On';
                app.btnStartApplications.Text = 'Start';
            else
                app.btnConfigApplication.Enable = 'Off';
                app.ddFeedBackSourceApplication.Enable = 'Off';
                app.ddModelApplications.Enable = 'Off';
                %app.btnStartApplications.Enable = 'Off';
                app.btnStartApplications.Text = 'Stop';
            end
        end
        
        function runApplicationParadigm(app)
        % runApplicationParadigm runs selcted application paradigm and
        % controls the state transitions. It uses the selected model for
        % prediction and controlling the states. 
        
            % Check if Stope is clicked and return
            if app.stopRoutin == 1
                return;
            end
            
            % Trigger numbers
            flagFb  = 3;
            flagPr = 7;
            flagTh = 13;
            flagBl = 12;
            
            % Thinking period
            if strcmp(app.configinfo.EEG,'Off')
                ITinterval = app.configinfo.NIRSDuration;
            elseif strcmp(app.configinfo.NIRS,'Off')
                ITinterval = app.configinfo.EEGDuration;
            else
                ITinterval = max(app.configinfo.NIRSDuration, app.configinfo.EEGDuration);
            end
            
            % Initialization of the paradigm
            isFinish = app.paradigm.next();
            
            app.SendTrigger(0); % Clear Port
            
            trgMsg = app.SendTrigger(9);
            app.ShowMessage(trgMsg, app.PreviewResultApplications);
            WaitSecs(app.configinfo.BaselineBlock);
            
            while ~isFinish
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                
                %% Check and print current state
                st = app.paradigm.getCurrentState();
                logApp = sprintf('Current state: %s',st);
                app.ShowMessage(logApp, app.PreviewResultApplications);
                %% Change from fprintf to write in terminal
                fprintf([logApp '\n'])
                
                %% Wait for baseline
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                trgMsg = app.SendTrigger(flagBl);
                app.ShowMessage(trgMsg, app.PreviewResultApplications);
                WaitSecs(app.configinfo.BaselineBlock);
                
                %% Present stimulus
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                trgMsg = app.SendTrigger(flagPr);
                app.ShowMessage(trgMsg, app.PreviewResultApplications);
                
                app.paradigm.present_stimulus();
                
                %% Wait for thinking
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                trgMsg = app.SendTrigger(flagTh);
                app.ShowMessage(trgMsg, app.PreviewResultApplications);
                
                WaitSecs(ITinterval);


                % if it is only simulation 
                if app.cbTestWithNoDataAcquisition_Application.Value
                    pred = -1;
                    while pred == -1
                        i = input('The answer is (y/n): ', 's');
                        if strcmp(i, 'y')
                            pred = 1;
                        elseif strcmp(i, 'n')
                            pred = 0;
                        else
                            pred = -1;
                            disp('Invalid input, use y or n only');
                        end
                    end
                else
                    
                    %% Load trial
                    while ~exist('lastTrialEEG.mat','file') && ~exist('lastTrialNIRS.mat','file') || app.cbTestWithNoDataAcquisition_Application.Value
                        WaitSecs(0.1)
                    end
                
                    %% Process raw data
                    if strcmp(app.feedBackSource,'EEG')
                        lastTrial = importdata('lastTrialEEG.mat');
                        delete('lastTrialEEG.mat');
                        % Reject channels
                        lastTrial = app.rejectChannels(lastTrial);
                        
                        % Preprocessing
                        % corrected by wu on 2019.05.14
                        %                         [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_EEG_Online_Preprocessing(lastTrial.baseline, lastTrial.thinkingPeriod, lastTrial.channelLabels, app.model.sRate, app.model.filterType, app.model.interprocess, app.model.amplitudeCorrection);
                        [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_EEG_Online_Preprocessing(lastTrial.baseline, lastTrial.thinkingPeriod, lastTrial.channelLabels, app.model.sRate, app.model.filterType, app.model.processing, app.model.selectedSource,app.model.selectedBand);
                        % Feature Extraction
                        % corrected by wu on 2019.05.14
                        %                           [features, featuresLabels]= CLISLAB_EEG_Online_ExtractFeatures(preprocessedThinkingData,app.model.sRate,app.model.selectedSource ,app.model.usedFeatures);
                        [features, featuresLabels]= CLISLAB_EEG_Online_ExtractFeatures(preprocessedThinkingData,app.model.sRate,app.model.usedFeatures);
                    elseif strcmp(app.feedBackSource,'NIRS')
                        lastTrial = importdata('lastTrialNIRS.mat');
                        delete('lastTrialNIRS.mat');
                        % Reject channels
                        lastTrial = app.rejectChannels(lastTrial);
                        
                        % Preprocessing
                        [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_NIRS_Online_Preprocessing(lastTrial.baseline, lastTrial.thinkingPeriod, app.model.sRate, app.model.filterType, app.model.processing);
                        
                        % Feature Extraction
                        featureList = app.model.selectedFeatures(:,1);
                        featureListUnique = unique(featureList);
                        [features, featuresLabels]= CLISLAB_NIRS_Online_ExtractFeatures(preprocessedThinkingData,app.model.sRate,app.model.selectedSource, featureListUnique);
                        
                    else
                        %EEG+NIRS
                    end
                    
                    % Selected features
                    [features,featuresLabels] = CLISLAB_SelectFeatures(features, featuresLabels,model.model.selectedFeatures);
                    %                 idxFeat = all(ismember(featuresLabels,app.model.selectedFeatures,'rows'),2);
                    %                 features = features(idxFeat);
                    
                    %% Classification
                    [pred, prob, acc] = CLISLAB_PredictLabel(app.model,features,lastTrial.questionLabel);
                    string_classif = sprintf('Classification: %d (%.3f%%)',pred,prob);
                    %fprintf([string_classif '\n']);
                    app.ShowMessage(string_classif,app.PreviewResultApplications);
                end
                
                %% Update paradigm for next state
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%% TODO: CHECK IF YES IS 0 OR 1 %%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                isFinish = app.paradigm.next(pred);
                
                %% Give feedback
                % Check if Stope is clicked and return
                WaitSecs(.00001);
                if app.stopRoutin == 1
                    return;
                end
                trgMsg = app.SendTrigger(flagFb);
                app.ShowMessage(trgMsg, app.PreviewResultApplications);
                
                fb = app.paradigm.present_feedback(pred);
                fprintf('Feedback: %s\n',fb);
                
            end
            trgMsg = app.SendTrigger(15);
            app.ShowMessage(trgMsg, app.PreviewResultApplications);
            
            app.UpdateMaxBlockNumberApplication;
            app.BlockSpinnerApplication.Value = app.BlockSpinnerApplication.Value + 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Added by Ale
            addpath(genpath('Config'),genpath('lib'));
            app.InitUI();
            
        end

        % Value changed function: EEGSwitch
        function EEGSwitchValueChanged(app, event)
            eegVisibility = app.EEGSwitch.Value;
            
            app.ddEEGDevices.Visible  = eegVisibility;
            app.lEEGDuration.Visible  = eegVisibility;
            app.efEEGDuration.Visible = eegVisibility;
            
            if strcmp(app.EEGSwitch.Value,app.NIRSSwitch.Value)
                app.lThinking.Visible = app.EEGSwitch.Value;
            else
                app.lThinking.Visible = 'On';
            end
            
            
        end

        % Value changed function: NIRSSwitch
        function NIRSSwitchValueChanged(app, event)
            nirsVisibility = app.NIRSSwitch.Value;
            
            app.ddNIRSDevices.Visible = nirsVisibility;
            app.lNIRSDuration.Visible = nirsVisibility;
            app.efNIRSDuration.Visible = nirsVisibility;
            if strcmp(app.EEGSwitch.Value,app.NIRSSwitch.Value)
                app.lThinking.Visible = app.EEGSwitch.Value;
            else
                app.lThinking.Visible = 'On';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            if strcmp(app.EEGSwitch.Value,'Off') && strcmp(app.NIRSSwitch.Value,'Off')
                msgbox('You have to select at least one of the devices !','Error','error', 'modal');
            elseif isempty(app.lstbxHardwareDevices.Value) && isempty(app.lstbxSoftwareDevices.Value)
                msgbox('You have to select at least one device for trigger!','Error','error', 'modal');
            elseif isempty(app.lstbxExperimenters.Value)
                msgbox('You have to select at least one expriementer !','Error','error', 'modal');
            else
                
                err = app.configureDevices();
                if err
                    msgbox('You have to configure the trigger devices','Error','error', 'modal');
                    return
                end
                
                app.configinfo.TriggerDevices          = [app.lstbxHardwareDevices.Value app.lstbxSoftwareDevices.Value];
                
                app.configinfo.NIRS             = app.NIRSSwitch.Value;
                if strcmp(app.NIRSSwitch.Value,'On')
                    app.configinfo.NIRSDuration     = app.efNIRSDuration.Value;
                    app.configinfo.NIRSDevice       = app.ddNIRSDevices.Value;
                end
                
                app.configinfo.EEG           = app.EEGSwitch.Value;
                if strcmp(app.EEGSwitch.Value,'On')
                    app.configinfo.EEGDuration   = app.efEEGDuration.Value;
                    app.configinfo.EEGDevice        = app.ddEEGDevices.Value;
                end
                
                app.configinfo.BaselineSentence   = app.SentenceBaseline.Value;
                app.configinfo.BaselineBlock      = app.BlockBaseline.Value;
                
                app.configinfo.BaselinePresentation = app.efPresentation.Value;
                
                app.configinfo.Experimenters  = [];
                
                app.configinfo.date = datetime();
                
                app.configinfo.Experimenters = app.lstbxExperimenters.Value;
                
                app.parallelPort = app.ParallelPort.Value;
                
                isEEGRecording = 0;
                if strcmp(app.EEGSwitch.Value,'On')
                    isEEGRecording = 1;
                end
                app.btnEEGRun.Enable = isEEGRecording;
                app.btnEEGRUN_Applications.Enable = isEEGRecording;
                
                isNIRSRecording = 0;
                if strcmp(app.NIRSSwitch.Value,'On')
                    isNIRSRecording = 1;
                end
                app.btnNIRSRun.Enable = isNIRSRecording;
                app.btnNIRSRUN_Applications.Enable = isNIRSRecording;
                
                msgbox('Configuration Saved Successfully','Succed','none');
                app.isConfigured = 1;
                app.ShowMessage('Configuration saved successfully', app.PreviewResult)
                app.tbMain.SelectedTab=app.ExperimentTab;
            end
        end

        % Button pushed function: ParallelPorSearchButton
        function ParallelPorSearchButtonPushed(app, event)
            system('devmgmt.msc');
        end

        % Value changed function: ddPatient
        function ddPatientValueChanged(app, event)
            app.configinfo.PatientID = app.ddPatient.Value;
            subpath = [pwd filesep 'Subjects' filesep app.configinfo.PatientID filesep];
            app.configinfo.subpath = subpath;
            app.ddFeedback.Enable = 'On'
            
            %Added by Ale
            app.UpdateModelList();
            
            % Max block number is one plus the blocks already done in the day
            app.UpdateMaxBlockNumber();
            
        end

        % Value changed function: ddModel
        function ddModelValueChanged(app, event)
            value = app.ddModel.Value;
            if strcmp(value,'None')
                app.selectedModel = [];
            elseif strcmp(value,'Build New Model')
                
                app.ddModel.Enable = 'Off';
                if strcmp(app.feedBackSource, 'EEG')
                    app.modelBuilderApp = ModelBuilder(app,app.ddPatient.Value,app.feedBackSource);
                elseif strcmp(app.feedBackSource,'NIRS')
                    app.modelBuilderApp = ModelBuilder(app,app.ddPatient.Value,app.feedBackSource);
                else
                    % DO SOMETHING
                end
            else
                app.selectedModel = value;
                
                % Load the model and popup a message with model parameters
                % Added by Jayro Martínez
                modelPath = [app.configinfo.subpath filesep 'Models' filesep app.feedBackSource filesep app.selectedModel];
                app.model = importdata(modelPath);
                
                patientID_msg           = [];
                datetime_msg            = [];
                tr_data_msg             = [];
                source_msg              = [];
                selectedFeatures_msg    = [];
                filter_msg              = [];
                consistency_msg         = [];
                dimensionality_msg      = [];
                processing_msg          = [];
                classifier_msg          = [];
                accuracy_msg            = [];
                
                if isfield(app.model, 'patientID')
                    patientID_msg = app.model.patientID;
                else
                    patientID_msg = 'Unknown';
                end
                
                if isfield(app.model, 'dateTime')
                    datetime_msg = char(app.model.dateTime);
                else
                    datetime_msg = 'Unknown';
                end
                
                if isfield(app.model, 'trainingData')
                    train_data = char(app.model.trainingData);
                    for i = 1:size(train_data,1)
                        tr_data_msg = [tr_data_msg '   ' train_data(i,:)];
                    end
                else
                    tr_data_msg = 'Unknown';
                end
                
                if strcmp(app.feedBackSource,'EEG')
                    source_aux = {'EEG' 'EOG' 'EMG'};
                elseif strcmp(app.feedBackSource,'NIRS')
                    source_aux = {'HbO' 'HbR' 'HbT'};
                end
                if isfield(app.model, 'selectedSource')
                    source_aux_msg = source_aux(app.model.selectedSource);
                    for i = 1:size(source_aux_msg,2)
                        source_msg = [source_msg '   ' char(source_aux_msg(i))];
                    end
                else
                    source_msg = 'Unknown';
                end
                
                if isfield(app.model, 'selectedFeatures')
                    selectedFeatures_msg = int2str(size(app.model.selectedFeatures,1));
                else
                    selectedFeatures_msg = 'Unknown';
                end
                
                if isfield(app.model, 'filterType')
                    filter_msg = app.model.filterType;
                else
                    filter_msg = 'Unknown';
                end
                
                if ~isfield(app.model, 'consistencyFunction')
                    consistency_msg = 'Unknown';
                else
                    consistency_msg = app.model.consistencyFunction;
                end
                
                if ~isfield(app.model, 'dimensionalityFunction')
                    dimensionality_msg = 'Unknown';
                else
                    dimensionality_msg = app.model.dimensionalityFunction;
                end
                
                if isfield(app.model, 'processing')
                    processing_aux = app.model.processing;
                    for j = 1:size(processing_aux,2)
                        processing_msg = [processing_msg '   ' char(processing_aux(j))];
                    end
                else
                    processing_msg = 'Unknown';
                end
                
                if isfield(app.model, 'classifier')
                    classifier_msg = app.model.classifier;
                else
                    classifier_msg = 'Unknown';
                end
                
                if isfield(app.model, 'accuracy')
                    accuracy_msg = [num2str(app.model.accuracy) '%'];
                else
                    accuracy_msg = 'Unknown';
                end
                
                if strcmp(app.feedBackSource,'EEG')
                    mbox = msgbox({['- Patient ID: ' patientID_msg]; ['- Datetime: ' datetime_msg]; ['- Training Data: ' tr_data_msg]; ['- Sources: ' source_msg]; ['- Number of Features: ' selectedFeatures_msg]; ['- Filter: ' filter_msg]; ['- Feature ConsistencyConsistency: ', consistency_msg]; ['- Dimensionality Reduction: ', dimensionality_msg]; ['- Processing: ' processing_msg]; ['- Classifier: ' classifier_msg]; ['- Accuracy: ' accuracy_msg]}, 'Model Parameters');
                elseif strcmp(app.feedBackSource,'NIRS')
                    mbox = msgbox({['- Patient ID: ' patientID_msg]; ['- Datetime: ' datetime_msg]; ['- Training Data: ' tr_data_msg]; ['- Sources: ' source_msg]; ['- Number of Features: ' selectedFeatures_msg]; ['- Filter: ' filter_msg]; ['- Feature ConsistencyConsistency: ', consistency_msg]; ['- Dimensionality Reduction: ', dimensionality_msg]; ['- Processing: ' processing_msg]; ['- Classifier: ' classifier_msg]; ['- Accuracy: ' accuracy_msg]}, 'Model Parameters');
                end
            end
        end

        % Button pushed function: btnStart
        function btnStartPushed(app, event)
            
            % check if configuration file is saved
            if app.isConfigured == 0
                app.tbMain.SelectedTab = app.tbMain.Children(1);
                msgbox('You have to save configuration first','Warning','warning', 'modal');
                return;
            end
            
            % check if Psychtoolbox is isntalled
            if ~app.isPsychToolBoxInstalled
                msgbox('Psychtoolboxshould be installed before ruuing the experiment. (http://psychtoolbox.org/)','Warning','warning', 'modal');
                return;
            end
            
            % if start clicked
            if strcmp(app.btnStart.Text, 'Start')
                if strcmp(app.ddPatient.Value,'-')
                    msgbox('Please select patient ID','Error','error', 'modal');
                    return;
                elseif (~strcmp(app.ddFeedback.Value,'No') && ~app.cbTestWithNoDataAcquisition.Value ) && (strcmp(app.ddModel.Value,'None') || strcmp(app.ddModel.Value,'Build New Model'))
                    msgbox('Please select a model','Error','error', 'modal');
                    return;
                end
                
                if exist('lastTrialEEG.mat','file') || exist('lastTrialNIRS.mat','file')
                    delete lastTrial*.mat
                end
                
                app.stopRoutin = 0;
                app.EnableUI(false);
                
                
                app.configinfo.blocknum       = app.BlockSpinner.Value;
                app.configinfo.FeedBackType   = app.ddFeedback.Value;
                app.configinfo.FeedBackSource = app.ddFeedBackSource.Value;
                app.configinfo.SelectedModel  = app.ddModel.Value;
                app.configinfo.SentenceNumber = app.SentenceNumberSpinner.Value;
                app.configinfo.model          = app.model
                app.configinfo.task           = app.ddTask.Value;
                
                app.tempFile.isEEGUpdated   = false;
                app.tempFile.isNIRSUpdated  = false;
                
                app.ShowMessage('Checking files !', app.PreviewResult);
                
                try
                    if strcmp(app.EEGSwitch.Value,'On') && ~exist([app.configinfo.subpath date filesep 'EEG' filesep 'mat'],'dir')
                        mkdir([app.configinfo.subpath date filesep 'EEG'  filesep 'mat']);
                    end
                    if strcmp(app.NIRSSwitch.Value,'On') && ~exist([app.configinfo.subpath date filesep 'NIRS' filesep 'mat'],'dir')
                        mkdir([app.configinfo.subpath date filesep 'NIRS' filesep 'mat']);
                    end
                catch
                    msgbox('Please close the application and re run it as administrator','Error','error', 'modal');
                    return;
                end
                
                
                app.ShowMessage('Saving Config File ...', app.PreviewResult);
                configfile = [app.configinfo.subpath date filesep 'config_Block' num2str(app.configinfo.blocknum)]; % the configuration file is saved in the main folder
                if exist([configfile '.mat'], 'file')
                    answer = questdlg('This block is already existed, would you like to rewrite the data ?!', 'Warning - File lost possiblity', 'Yes','No','No');
                    switch answer
                        case 'Yes'
                            app.ShowMessage('File being replaced', app.PreviewResult);
                        case 'No'
                            app.EnableUI(true);
                            return;
                    end
                end
                
                configinfo = app.configinfo;
                save(configfile,'configinfo');
                app.tempFile.pathToConfigFile = [configfile '.mat'];
                tempFile = app.tempFile;
                save([pwd filesep 'tempFile.mat'],'tempFile');
                %clear configinfo tmp;
                %clear pathToConfigFile;
                
                % TBF : Check if devices are connected and turn lamps
                app.ShowMessage('Waiting for connection !', app.PreviewResult);
                
                % Detectting if nirs and eeg are okay
                %while (~(~xor(strcmp(app.configinfo.EEG,'On'), app.configinfo.isEEGUpdated) && ~xor(strcmp(app.configinfo.NIRS,'On'), app.configinfo.isNIRSUpdated))) && app.stopRoutin == 0
                
                isEEGOkay = false;
                isNIRSOkay = false;
                
                if app.cbTestWithNoDataAcquisition.Value
                    isEEGOkay = true;
                    isNIRSOkay = true;
                end
                
                while (~isEEGOkay || ~isNIRSOkay) && app.stopRoutin == 0
                    
                    if strcmp(app.configinfo.EEG,'On')
                        app.ShowMessage('Please update in EEG UI', app.PreviewResult);
                    end
                    if strcmp(app.configinfo.NIRS,'On')
                        app.ShowMessage('Please update in NIRS UI', app.PreviewResult);
                    end
                    
                    WaitSecs(5);
                    
                    load('tempFile.mat');
                    app.tempFile = tempFile;
                    %app.configinfo = configinfo;
                    
                    isEEGOkay  = ~( strcmp(app.configinfo.EEG,'On')  && ~tempFile.isEEGUpdated);
                    isNIRSOkay = ~( strcmp(app.configinfo.NIRS,'On') && ~tempFile.isNIRSUpdated);
                    
                    clear tempFile;
                    
                end
                
                if ~strcmp(app.configinfo.FeedBackType, 'No') && ~app.cbTestWithNoDataAcquisition.Value
                    modelPath = [app.configinfo.subpath filesep 'Models' filesep app.feedBackSource filesep app.selectedModel];
                    app.model = importdata(modelPath);
                end
                if app.tempFile.isEEGUpdated
                    app.EEGLamp.Color = 'g';
                end
                if app.tempFile.isNIRSUpdated
                    app.NIRSLamp.Color = 'g';
                end
                
                
                app.RunQuestionParadigm();


                app.EEGLamp.Color = 'r';
                app.NIRSLamp.Color = 'r';
                
                % Added by Jayro
                
                %configfile = [app.configinfo.subpath date filesep 'config_Block' num2str(app.configinfo.blocknum)]; % the configuration file is saved in the main folder
                %configfile_path = [configfile '.mat'];
                %configfile = load(configfile_path);
                
                app.configinfo.finishTime = datetime();
                app.configinfo.predictedLabels = app.predicted_labels;
                configinfo = app.configinfo;
                save(configfile,'configinfo','-append');
                app.predicted_labels = [];
                clear configinfo tmp;
                clear pathToConfigFile;
                
                
                % if stoped clicked
            else
                app.stopRoutin = 1;
                trgMsg = app.SendTrigger(14);
                app.ShowMessage(trgMsg, app.PreviewResult);
                PsychPortAudio('Stop', app.psyToolBxHandler, 2);
                app.ShowMessage('Expriment Stopped', app.PreviewResult);
                app.EnableUI(true);
                app.EEGLamp.Color = 'r';
                app.NIRSLamp.Color = 'r';
                app.UpdateMaxBlockNumber();
                
                % Added by Jayro
                configfile = [app.configinfo.subpath date filesep 'config_Block' num2str(app.configinfo.blocknum)]; % the configuration file is saved in the main folder
                app.configinfo.finishTime = datetime();
                app.configinfo.predictedLabels = app.predicted_labels;
                configinfo = app.configinfo;
                save(configfile,'configinfo','-append');
                app.predicted_labels = [];
                clear configinfo tmp;
                clear pathToConfigFile;
            end
            
        end

        % Callback function
        function NewSessionButtonPushed(app, event)
            app.SessionSpinner.Value = app.SessionSpinner.Value + 1;
        end

        % Callback function
        function NewBlockButtonPushed(app, event)
            app.BlockSpinner.Value = app.BlockSpinner.Value + 1;
        end

        % Value changed function: ddFeedback
        function ddFeedbackValueChanged(app, event)
            if strcmp(app.ddFeedback.Value,'No') || strcmp(app.ddFeedback.Value,'Simulation(Manual)')
                app.ddFeedBackSource.Enable = 'Off';
                app.ddModel.Enable = 'Off';
            else
                app.ddFeedBackSource.Enable = 'on';
                app.FeedBackSourceDropDownLabel.Enable = 'on';
                app.ddModel.Enable = 'On';
                app.ModelDropDownLabel.Enable = 'on';
                if isempty(app.feedBackSource) % Added by Jayro
                    app.feedBackSource = 'EEG';
                end
            end
            
        end

        % Value changed function: ddFeedBackSource
        function ddFeedBackSourceValueChanged(app, event)
            value = app.ddFeedBackSource.Value;
            
            if strcmp(value, 'Simulation(Manual)')
                app.cbTestWithNoDataAcquisition.Value = 1;
            else
                app.cbTestWithNoDataAcquisition.Value = 0;
                app.feedBackSource = value;
                app.UpdateModelList();
            end
        end

        % Button pushed function: bthAddExperimenter
        function bthAddExperimenterButtonPushed(app, event)
            if isempty(app.efNewExperimenter.Value)
                msgbox('Experimenter name cannot be empty','Error','error', 'modal');
                
            else
                % Added by Ale
                oldList = load(app.pathExperimentersList);
                if find(strcmpi(oldList.experimenters,app.efNewExperimenter.Value))
                    msgbox('Experimenter already in the list','Error','error', 'modal');
                    app.efNewExperimenter.Value = '';
                    return
                end
                oldList.experimenters{end+1} = app.efNewExperimenter.Value;
                experimenters = oldList.experimenters;
                save(app.pathExperimentersList, 'experimenters');
                %CLISLAB_AddToFile(app.pathExperimentersList,app.efNewExperimenter.Value);
                app.UpdateExperimenterList();
                % Added by Ale
                app.efNewExperimenter.Value = '';
            end
        end

        % Button pushed function: btnEEGRUN_Applications, btnEEGRun
        function btnEEGRunButtonPushed(app, event)
            % Modified by Ale
            system(['matlab -minimize -r "addpath(genpath(''Config''), genpath(''lib''));' app.configinfo.EEGDevice '" &']);
        end

        % Button pushed function: btnTestParallel
        function btnTestParallelButtonPushed(app, event)
            
            app.configureDevices()
            % Modified by Ale
            for triggerNum = 1:15
                trgMsg = app.SendTrigger(triggerNum);
                app.ShowMessage(trgMsg, app.PreviewResultConfig);
                WaitSecs(0.5);
            end
            
            % Modified by Jayro
            % We delete all trigger devices to avoid socket overlapping
            for i = 1:length(app.triggerDevices)
                app.triggerDevices{i}.exit();
            end
            
        end

        % Button pushed function: btnNIRSRUN_Applications, 
        % btnNIRSRun
        function btnNIRSRunButtonPushed(app, event)
            system(['matlab -minimize -r "addpath(genpath(''Config''), genpath(''lib''));' app.configinfo.NIRSDevice '" &']);
        end

        % Value changed function: ddApplications
        function ddApplicationsValueChanged(app, event)
            value = app.ddApplications.Value;
            try
                app.paradigm.quit()
            catch
            end
            if strcmp(value,'None')
                app.paradigm = [];
                app.enableApplicationsUI(0);
                return
            else
                app.ddFeedBackSourceApplication.Enable = 'on';
                app.ddModelApplications.Enable = 'on';
            end
            paradigmMainPath = fullfile('lib','Applications','Paradigms');
            paradigmName = extractAfter(value,'Paradigm_');
            addpath(paradigmMainPath,genpath(fullfile(paradigmMainPath,paradigmName)));
            paradigmFunc = str2func(value);
            app.paradigm = paradigmFunc(app.configinfo);
            app.enableApplicationsUI(1);
        end

        % Button pushed function: btnConfigApplication
        function btnConfigApplicationPushed(app, event)
            app.configinfo.configParadigm = app.paradigm.config();
        end

        % Button pushed function: btnStartApplications
        function btnStartApplicationsPushed(app, event)
            
            % check if configuration file is saved
            if app.isConfigured == 0
                app.tbMain.SelectedTab = app.tbMain.Children(1);
                msgbox('You have to save configuration first','Warning','warning', 'modal');
                return;
            end
            
            if strcmp(app.btnStartApplications.Text, 'Start')
                
                if strcmp(app.ddPatientApplication.Value,'-')
                    msgbox('Please select patient ID','Error','error', 'modal');
                    return;
                end
                
                app.stopRoutin = 0;
                app.btnStartApplications.Text = 'Stop';
                
                %%% SAVE CONFIGINFOFILE
                app.configinfo.paradigm = app.ddApplications.Value;
                app.configinfo.blocknum = app.BlockSpinnerApplication.Value;
                app.configinfo.FeedBackSource = app.ddFeedBackSourceApplication.Value;
                app.configinfo.SelectedModel = app.ddModelApplications.Value;
                
                app.tempFile.isEEGUpdated   = false;
                app.tempFile.isNIRSUpdated  = false;
                
                app.ShowMessage('Checking files !', app.PreviewResult);
                
                try
                    if (strcmp(app.ddFeedBackSourceApplication.Value, 'EEG') || strcmp(app.ddFeedBackSourceApplication.Value, 'Simulation(Manual)') )&& ~exist([app.configinfo.subpath date filesep 'EEG' filesep 'mat'],'dir')
                        mkdir([app.configinfo.subpath date filesep 'EEG'  filesep 'mat']);
                    end
                    if strcmp(app.ddFeedBackSourceApplication.Value,'NIRS') && ~exist([app.configinfo.subpath date filesep 'NIRS' filesep 'mat'],'dir')
                        mkdir([app.configinfo.subpath date filesep 'NIRS' filesep 'mat']);
                    end
                catch
                    msgbox('Please close the application and re run it as administrator','Error','error', 'modal');
                    %TBF: here UI should be updated
                    return;
                end
                
                app.ShowMessage('Saving Config File ...', app.PreviewResultApplications);
                configfile = [app.configinfo.subpath date filesep 'config_Block' num2str(app.configinfo.blocknum)]; % the configuration file is saved in the main folder
                if exist([configfile '.mat'], 'file')
                    answer = questdlg('This block is already existed, would you like to rewrite the data ?!', 'Warning - File lost possiblity', 'Yes','No','No');
                    switch answer
                        case 'Yes'
                            app.ShowMessage('File being replaced', app.PreviewResultApplications);
                        case 'No'
                            app.enableApplicationsUI(true);
                            return;
                    end
                end
                
                configinfo = app.configinfo;
                save(configfile,'configinfo');
                app.tempFile.pathToConfigFile = [configfile '.mat'];
                tempFile = app.tempFile;
                save([pwd filesep 'tempFile.mat'],'tempFile');
                
                isEEGOkay = false;
                isNIRSOkay = false;
               
                if app.cbTestWithNoDataAcquisition_Application.Value
                    isEEGOkay = true;
                    isNIRSOkay = true;
                end
                
                while (~isEEGOkay || ~isNIRSOkay) && app.stopRoutin == 0
                    
                    if strcmp(app.configinfo.EEG,'On')
                        app.ShowMessage('Please update in EEG UI', app.PreviewResultApplications);
                    end
                    if strcmp(app.configinfo.NIRS,'On')
                        app.ShowMessage('Please update in NIRS UI', app.PreviewResultApplications);
                    end
                    
                    WaitSecs(5);
                    
                    load('tempFile.mat');
                    app.tempFile = tempFile;
                    %app.configinfo = configinfo;
                    
                    isEEGOkay  = ~( strcmp(app.configinfo.EEG,'On')  && ~tempFile.isEEGUpdated);
                    isNIRSOkay = ~( strcmp(app.configinfo.NIRS,'On') && ~tempFile.isNIRSUpdated);
                    
                    clear tempFile;
                    
                end
                
                if ~app.cbTestWithNoDataAcquisition_Application.Value
                    modelPath = [app.configinfo.subpath filesep 'Models' filesep app.feedBackSource filesep app.selectedModel];
                    app.model = importdata(modelPath);
                end
                
                if app.tempFile.isEEGUpdated
                    app.EEGLamp.Color = 'g';
                end
                if app.tempFile.isNIRSUpdated
                    app.NIRSLamp.Color = 'g';
                end
                
                app.runApplicationParadigm();
                
                % Save log
                app.configinfo.logParadigm = app.paradigm.getLog();
                save(configfile,'configinfo');
                
                app.EEGLamp.Color = 'r';
                app.NIRSLamp.Color = 'r';
                
            else
                app.stopRoutin = 1;
                trgMsg = app.SendTrigger(14);
                app.ShowMessage(trgMsg, app.PreviewResultApplications);
                app.paradigm.stopAudio;
                
                app.EEGLamp.Color = 'r';
                app.NIRSLamp.Color = 'r';
                
                % Save log
                app.configinfo.logParadigm = app.paradigm.getLog();
                % save(configfile,'configinfo');
                
                app.btnStartApplications.Text = 'Start';
            end
        end

        % Value changed function: ddPatientApplication
        function ddPatientApplicationValueChanged(app, event)
            app.configinfo.PatientID = app.ddPatientApplication.Value;
            subpath = [pwd filesep 'Subjects' filesep app.configinfo.PatientID filesep];
            app.configinfo.subpath = subpath;
            
            %Added by Ale
            app.UpdateModelListApplication();
            
            % Max block number is one plus the blocks already done in the day
            app.UpdateMaxBlockNumberApplication();
        end

        % Value changed function: ddFeedBackSourceApplication
        function ddFeedBackSourceApplicationValueChanged(app, event)
            app.feedBackSource = app.ddFeedBackSourceApplication.Value;
            app.UpdateModelListApplication();
            
            if strcmp(app.ddFeedBackSourceApplication.Value, 'Simulation(Manual)')
                app.cbTestWithNoDataAcquisition_Application.Value = 1;
            else
                app.cbTestWithNoDataAcquisition_Application.Value = 0;
            end
            
        end

        % Value changed function: ddModelApplications
        function ddModelApplicationsValueChanged(app, event)
            value = app.ddModelApplications.Value;
            if strcmp(value,'None')
                app.selectedModel = [];
            elseif strcmp(value,'Build New Model')
                % Why 500?!?
                % Modified by Ale
                %app.ddModel.Enable = 'Off';
                if strcmp(app.feedBackSource, 'EEG')
                    app.modelBuilderApp = ModelBuilder(app,app.ddPatientApplication.Value,app.feedBackSource);
                elseif strcmp(app.feedBackSource,'NIRS')
                    app.modelBuilderApp = modelBuilder(app,app.ddPatientApplication.Value,app.feedBackSource);
                    %app.modelBuilderApp = NIRS_modelBuilder(app,app.ddPatient.Value,app.feedBackSource);
                else
                    % DO SOMETHING
                end
            else
                app.selectedModel = value;
                
                % Load the model and popup a message with model parameters
                % Added by Jayro Martínez
                modelPath = [app.configinfo.subpath filesep 'Models' filesep app.feedBackSource filesep app.selectedModel];
                app.model = importdata(modelPath);
                
                patientID_msg = [];
                datetime_msg = [];
                tr_data_msg = [];
                select_best_feat_msg = [];
                source_msg = [];
                selectedFeatures_msg = [];
                filter_msg = [];
                amplitude_msg = [];
                processing_msg = [];
                classifier_msg = [];
                accuracy_msg = [];
                
                if isfield(app.model, 'patientID')
                    patientID_msg = app.model.patientID;
                else
                    patientID_msg = 'Unknown';
                end
                
                if isfield(app.model, 'dateTime')
                    datetime_msg = char(app.model.dateTime);
                else
                    datetime_msg = 'Unknown';
                end
                
                if isfield(app.model, 'trainingData')
                    train_data = char(app.model.trainingData);
                    for i = 1:size(train_data,1)
                        tr_data_msg = [tr_data_msg '   ' train_data(i,:)];
                    end
                else
                    tr_data_msg = 'Unknown';
                end
                
                if ~isfield(app.model, 'isSelectiveFeatures')
                    select_best_feat_msg = '- Select Best Features: Unknown';
                elseif app.model.isSelectiveFeatures == 0
                    select_best_feat_msg = '- Select Best Features: False';
                else
                    select_best_feat_msg = '- Select Best Features: True';
                    select_best_feat_msg = [select_best_feat_msg ' Channels: ' int2str(app.model.numChanForSelectFeatures)];
                    select_best_feat_msg = [select_best_feat_msg ' Bands: ' int2str(app.model.numBandForSelectFeatures)];
                end
                
                if strcmp(app.feedBackSource,'EEG')
                    source_aux = {'EEG' 'EOG' 'EMG'};
                elseif strcmp(app.feedBackSource,'NIRS')
                    source_aux = {'HbO' 'HbR' 'HbT'};
                end
                if isfield(app.model, 'selectedSource')
                    source_aux_msg = source_aux(app.model.selectedSource);
                    for i = 1:size(source_aux_msg,2)
                        source_msg = [source_msg '   ' char(source_aux_msg(i))];
                    end
                else
                    source_msg = 'Unknown';
                end
                
                if isfield(app.model, 'selectedFeatures')
                    selectedFeatures_msg = int2str(size(app.model.selectedFeatures,1));
                else
                    selectedFeatures_msg = 'Unknown';
                end
                
                if isfield(app.model, 'filterType')
                    filter_msg = app.model.filterType;
                else
                    filter_msg = 'Unknown';
                end
                
                if isfield(app.model, 'processing')
                    processing_aux = app.model.processing;
                    for j = 1:size(processing_aux,2)
                        processing_msg = [processing_msg '   ' char(processing_aux(j))];
                    end
                else
                    processing_msg = 'Unknown';
                end
                
                if isfield(app.model, 'classifier')
                    classifier_msg = app.model.classifier;
                else
                    classifier_msg = 'Unknown';
                end
                
                if isfield(app.model, 'accuracy')
                    accuracy_msg = [num2str(app.model.accuracy) '%'];
                else
                    accuracy_msg = 'Unknown';
                end
                
                if strcmp(app.feedBackSource,'EEG')
                    mbox = msgbox({['- Patient ID: ' patientID_msg]; ['- Datetime: ' datetime_msg]; ['- Training Data: ' tr_data_msg]; [select_best_feat_msg]; ['- Sources: ' source_msg]; ['- Number of Features: ' selectedFeatures_msg]; ['- Filter: ' filter_msg]; ['- Processing: ' processing_msg]; ['- Classifier: ' classifier_msg]; ['- Accuracy: ' accuracy_msg]}, 'Model Parameters');
                elseif strcmp(app.feedBackSource,'NIRS')
                    mbox = msgbox({['- Patient ID: ' patientID_msg]; ['- Datetime: ' datetime_msg]; ['- Training Data: ' tr_data_msg]; [select_best_feat_msg]; ['- Sources: ' source_msg]; ['- Number of Features: ' selectedFeatures_msg]; ['- Filter: ' filter_msg]; ['- Processing: ' processing_msg]; ['- Classifier: ' classifier_msg]; ['- Accuracy: ' accuracy_msg]}, 'Model Parameters');
                end
            end
        end

        % Value changed function: ddTask
        function ddTaskValueChanged(app, event)
            value = app.ddTask.Value;
            
            if strcmp('Add Task', value)
                newTask = inputdlg('Enter the new task','New Task');
                oldTasks = load(app.pathTasks);
                oldTasks.Tasks{end} = newTask{1};
                oldTasks.Tasks{end+1} = 'Add Task';
                Tasks = oldTasks.Tasks;
                save(app.pathTasks, 'Tasks');
                app.UpdateTasks();
                
            end
        end

        % Value changed function: 
        % cbTestWithNoDataAcquisition_Application
        function cbTestWithNoDataAcquisition_ApplicationValueChanged(app, event)
            value = app.cbTestWithNoDataAcquisition_Application.Value;
            if value
                app.ddFeedBackSourceApplication.Value = 'Simulation(Manual)';
            else
                app.ddFeedBackSourceApplication.Value = 'EEG';
            end
            
        end

        % Value changed function: cbTestWithNoDataAcquisition
        function cbTestWithNoDataAcquisitionValueChanged(app, event)
            value = app.cbTestWithNoDataAcquisition.Value;
            if value
                app.ddFeedBackSource.Value = 'Simulation(Manual)';
            else
                app.ddFeedBackSource.Value = 'EEG';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HybridBCIUIFigure = uifigure('Visible', 'off');
            app.HybridBCIUIFigure.Name = 'Hybrid BCI';
            app.tbMain = uitabgroup(app.HybridBCIUIFigure);
            app.ConfigurationTab = uitab(app.tbMain);
            app.ConfigurationTab.Title = 'Configuration';
            app.EEGSwitch = uiswitch(app.ConfigurationTab, 'slider');
            app.EEGSwitch.ValueChangedFcn = createCallbackFcn(app, @EEGSwitchValueChanged, true);
            app.EEGSwitchLabel = uilabel(app.ConfigurationTab);
            app.EEGSwitchLabel.Text = 'EEG';
            app.ExperimentPanel = uipanel(app.ConfigurationTab);
            app.ExperimentPanel.Title = 'Experiment';
            app.lBaseline = uilabel(app.ExperimentPanel);
            app.lBaseline.Text = 'Baseline:';
            app.SentenceLabel = uilabel(app.ExperimentPanel);
            app.SentenceLabel.Text = 'Sentence';
            app.SentenceBaseline = uieditfield(app.ExperimentPanel, 'numeric');
            app.SentenceBaseline.Value = 5;
            app.BlockLabel = uilabel(app.ExperimentPanel);
            app.BlockLabel.Text = 'Block ';
            app.BlockBaseline = uieditfield(app.ExperimentPanel, 'numeric');
            app.BlockBaseline.Value = 10;
            app.lEEGDuration = uilabel(app.ExperimentPanel);
            app.lEEGDuration.Text = 'EEG     ';
            app.efEEGDuration = uieditfield(app.ExperimentPanel, 'numeric');
            app.efEEGDuration.Value = 5;
            app.lNIRSDuration = uilabel(app.ExperimentPanel);
            app.lNIRSDuration.Text = 'NIRS';
            app.efNIRSDuration = uieditfield(app.ExperimentPanel, 'numeric');
            app.efNIRSDuration.Value = 10;
            app.lThinking = uilabel(app.ExperimentPanel);
            app.lThinking.Text = 'Thinking:';
            app.PresentationEditFieldLabel = uilabel(app.ExperimentPanel);
            app.PresentationEditFieldLabel.Text = 'Presentation';
            app.efPresentation = uieditfield(app.ExperimentPanel, 'numeric');
            app.efPresentation.Value = 3;
            app.NIRSSwitch = uiswitch(app.ConfigurationTab, 'slider');
            app.NIRSSwitch.ValueChangedFcn = createCallbackFcn(app, @NIRSSwitchValueChanged, true);
            app.NIRSSwitchLabel = uilabel(app.ConfigurationTab);
            app.NIRSSwitchLabel.Text = 'NIRS';
            app.TriggersPanel = uipanel(app.ConfigurationTab);
            app.TriggersPanel.Title = 'Triggers';
            app.ParallelPorSearchButton = uibutton(app.TriggersPanel, 'push');
            app.ParallelPorSearchButton.ButtonPushedFcn = createCallbackFcn(app, @ParallelPorSearchButtonPushed, true);
            app.ParallelPorSearchButton.Text = 'Search';
            app.btnTestParallel = uibutton(app.TriggersPanel, 'push');
            app.btnTestParallel.ButtonPushedFcn = createCallbackFcn(app, @btnTestParallelButtonPushed, true);
            app.btnTestParallel.Text = 'Test';
            app.HardwareListBoxLabel = uilabel(app.TriggersPanel);
            app.HardwareListBoxLabel.Text = 'Hardware';
            app.lstbxHardwareDevices = uilistbox(app.TriggersPanel);
            app.lstbxHardwareDevices.Items = {};
            app.lstbxHardwareDevices.Multiselect = 'on';
            app.lstbxHardwareDevices.Value = {};
            app.SoftwareListBoxLabel = uilabel(app.TriggersPanel);
            app.SoftwareListBoxLabel.Text = 'Software';
            app.lstbxSoftwareDevices = uilistbox(app.TriggersPanel);
            app.lstbxSoftwareDevices.Items = {};
            app.lstbxSoftwareDevices.Multiselect = 'on';
            app.lstbxSoftwareDevices.Value = {};
            app.PortLabel = uilabel(app.TriggersPanel);
            app.PortLabel.Text = 'Port';
            app.ParallelPort = uieditfield(app.TriggersPanel, 'text');
            app.ParallelPort.Value = '3FF8';
            app.SaveButton = uibutton(app.ConfigurationTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExperimentersPanel = uipanel(app.ConfigurationTab);
            app.ExperimentersPanel.Title = 'Experimenters';
            app.NewExperimenterEditFieldLabel = uilabel(app.ExperimentersPanel);
            app.NewExperimenterEditFieldLabel.Text = 'New Experimenter';
            app.efNewExperimenter = uieditfield(app.ExperimentersPanel, 'text');
            app.lstbxExperimenters = uilistbox(app.ExperimentersPanel);
            app.lstbxExperimenters.Items = {};
            app.lstbxExperimenters.Multiselect = 'on';
            app.lstbxExperimenters.Value = {};
            app.bthAddExperimenter = uibutton(app.ExperimentersPanel, 'push');
            app.bthAddExperimenter.ButtonPushedFcn = createCallbackFcn(app, @bthAddExperimenterButtonPushed, true);
            app.bthAddExperimenter.Text = 'ADD';
            app.ddEEGDevices = uidropdown(app.ConfigurationTab);
            app.ddEEGDevices.Items = {'BrainVision'};
            app.ddEEGDevices.Value = 'BrainVision';
            app.ddNIRSDevices = uidropdown(app.ConfigurationTab);
            app.ddNIRSDevices.Items = {};
            app.ddNIRSDevices.Value = {};
            app.Panel = uipanel(app.ConfigurationTab);
            app.Panel.Title = 'Panel';
            app.PreviewResultConfig = uitextarea(app.Panel);
            app.PreviewResultConfig.Editable = 'off';
            app.ExperimentTab = uitab(app.tbMain);
            app.ExperimentTab.Title = 'Experiment';
            app.DesignPanel = uipanel(app.ExperimentTab);
            app.DesignPanel.Title = 'Design';
            app.PatientIDDropDownLabel = uilabel(app.DesignPanel);
            app.PatientIDDropDownLabel.Text = 'Patient ID';
            app.ddPatient = uidropdown(app.DesignPanel);
            app.ddPatient.Items = {};
            app.ddPatient.ValueChangedFcn = createCallbackFcn(app, @ddPatientValueChanged, true);
            app.ddPatient.Value = {};
            app.ModelDropDownLabel = uilabel(app.DesignPanel);
            app.ModelDropDownLabel.Enable = 'off';
            app.ModelDropDownLabel.Text = 'Model';
            app.ddModel = uidropdown(app.DesignPanel);
            app.ddModel.Items = {};
            app.ddModel.ValueChangedFcn = createCallbackFcn(app, @ddModelValueChanged, true);
            app.ddModel.Enable = 'off';
            app.ddModel.Value = {};
            app.BlockSpinnerLabel = uilabel(app.DesignPanel);
            app.BlockSpinnerLabel.Text = 'Block';
            app.BlockSpinner = uispinner(app.DesignPanel);
            app.BlockSpinner.Limits = [1 Inf];
            app.BlockSpinner.Value = 1;
            app.SentenceNumberSpinnerLabel = uilabel(app.DesignPanel);
            app.SentenceNumberSpinnerLabel.Text = 'Sentence Number';
            app.SentenceNumberSpinner = uispinner(app.DesignPanel);
            app.SentenceNumberSpinner.Value = 10;
            app.FeedbackDropDownLabel = uilabel(app.DesignPanel);
            app.FeedbackDropDownLabel.Text = 'Feedback ';
            app.ddFeedback = uidropdown(app.DesignPanel);
            app.ddFeedback.Items = {'No', 'Text', 'Audio', 'Only Corrects', ''};
            app.ddFeedback.ValueChangedFcn = createCallbackFcn(app, @ddFeedbackValueChanged, true);
            app.ddFeedback.Enable = 'off';
            app.ddFeedback.Value = 'No';
            app.FeedBackSourceDropDownLabel = uilabel(app.DesignPanel);
            app.FeedBackSourceDropDownLabel.Enable = 'off';
            app.FeedBackSourceDropDownLabel.Text = 'FeedBackSource';
            app.ddFeedBackSource = uidropdown(app.DesignPanel);
            app.ddFeedBackSource.Items = {'EEG', 'NIRS', 'EEG + NIRS', 'Simulation(Manual)'};
            app.ddFeedBackSource.ValueChangedFcn = createCallbackFcn(app, @ddFeedBackSourceValueChanged, true);
            app.ddFeedBackSource.Enable = 'off';
            app.ddFeedBackSource.Value = 'EEG';
            app.TaskDropDownLabel = uilabel(app.DesignPanel);
            app.TaskDropDownLabel.Text = 'Task';
            app.ddTask = uidropdown(app.DesignPanel);
            app.ddTask.Items = {};
            app.ddTask.ValueChangedFcn = createCallbackFcn(app, @ddTaskValueChanged, true);
            app.ddTask.Value = {};
            app.btnStart = uibutton(app.ExperimentTab, 'push');
            app.btnStart.ButtonPushedFcn = createCallbackFcn(app, @btnStartPushed, true);
            app.btnStart.Text = 'Start';
            app.PreviewPanel = uipanel(app.ExperimentTab);
            app.PreviewPanel.Title = 'Preview';
            app.PreviewResult = uitextarea(app.PreviewPanel);
            app.PreviewResult.Editable = 'off';
            app.EEGLampLabel = uilabel(app.ExperimentTab);
            app.EEGLampLabel.Text = 'EEG';
            app.EEGLamp = uilamp(app.ExperimentTab);
            app.NIRSLampLabel = uilabel(app.ExperimentTab);
            app.NIRSLampLabel.Text = 'NIRS';
            app.NIRSLamp = uilamp(app.ExperimentTab);
            app.btnEEGRun = uibutton(app.ExperimentTab, 'push');
            app.btnEEGRun.ButtonPushedFcn = createCallbackFcn(app, @btnEEGRunButtonPushed, true);
            app.btnEEGRun.Enable = 'off';
            app.btnEEGRun.Text = 'RUN';
            app.btnNIRSRun = uibutton(app.ExperimentTab, 'push');
            app.btnNIRSRun.ButtonPushedFcn = createCallbackFcn(app, @btnNIRSRunButtonPushed, true);
            app.btnNIRSRun.Enable = 'off';
            app.btnNIRSRun.Text = 'RUN';
            app.cbTestWithNoDataAcquisition = uicheckbox(app.ExperimentTab);
            app.cbTestWithNoDataAcquisition.ValueChangedFcn = createCallbackFcn(app, @cbTestWithNoDataAcquisitionValueChanged, true);
            app.cbTestWithNoDataAcquisition.Text = 'Test with no data acquisition';
            app.ApplicationsTab = uitab(app.tbMain);
            app.ApplicationsTab.Title = 'Applications';
            app.ApplicationPanel = uipanel(app.ApplicationsTab);
            app.ApplicationPanel.Title = 'Application';
            app.ModelDropDown_2Label = uilabel(app.ApplicationPanel);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ddModelApplications = uidropdown(app.ApplicationPanel);
            app.ddModelApplications.Items = {};
            app.ddModelApplications.ValueChangedFcn = createCallbackFcn(app, @ddModelApplicationsValueChanged, true);
            app.ddModelApplications.Enable = 'off';
            app.ddModelApplications.Value = {};
            app.btnConfigApplication = uibutton(app.ApplicationPanel, 'push');
            app.btnConfigApplication.ButtonPushedFcn = createCallbackFcn(app, @btnConfigApplicationPushed, true);
            app.btnConfigApplication.Enable = 'off';
            app.btnConfigApplication.Text = 'Config';
            app.FeedBackSourceDropDown_2Label = uilabel(app.ApplicationPanel);
            app.FeedBackSourceDropDown_2Label.Text = 'FeedBackSource';
            app.ddFeedBackSourceApplication = uidropdown(app.ApplicationPanel);
            app.ddFeedBackSourceApplication.Items = {'EEG', 'NIRS', 'EEG + NIRS', 'Simulation(Manual)', ''};
            app.ddFeedBackSourceApplication.ValueChangedFcn = createCallbackFcn(app, @ddFeedBackSourceApplicationValueChanged, true);
            app.ddFeedBackSourceApplication.Enable = 'off';
            app.ddFeedBackSourceApplication.Value = 'EEG';
            app.PatientIDDropDownLabel_2 = uilabel(app.ApplicationPanel);
            app.PatientIDDropDownLabel_2.Text = 'Patient ID';
            app.ddPatientApplication = uidropdown(app.ApplicationPanel);
            app.ddPatientApplication.Items = {};
            app.ddPatientApplication.ValueChangedFcn = createCallbackFcn(app, @ddPatientApplicationValueChanged, true);
            app.ddPatientApplication.Value = {};
            app.ParadigmLabel = uilabel(app.ApplicationPanel);
            app.ParadigmLabel.Text = 'Paradigm';
            app.ddApplications = uidropdown(app.ApplicationPanel);
            app.ddApplications.Items = {'None', ''};
            app.ddApplications.ValueChangedFcn = createCallbackFcn(app, @ddApplicationsValueChanged, true);
            app.ddApplications.Value = 'None';
            app.BlockSpinner_2Label = uilabel(app.ApplicationPanel);
            app.BlockSpinner_2Label.Text = 'Block';
            app.BlockSpinnerApplication = uispinner(app.ApplicationPanel);
            app.BlockSpinnerApplication.Limits = [1 Inf];
            app.BlockSpinnerApplication.Value = 1;
            app.PreviewPanelApplications = uipanel(app.ApplicationsTab);
            app.PreviewPanelApplications.Title = 'Preview';
            app.PreviewResultApplications = uitextarea(app.PreviewPanelApplications);
            app.PreviewResultApplications.Editable = 'off';
            app.btnEEGRUN_Applications = uibutton(app.ApplicationsTab, 'push');
            app.btnEEGRUN_Applications.ButtonPushedFcn = createCallbackFcn(app, @btnEEGRunButtonPushed, true);
            app.btnEEGRUN_Applications.Enable = 'off';
            app.btnEEGRUN_Applications.Text = 'RUN';
            app.btnNIRSRUN_Applications = uibutton(app.ApplicationsTab, 'push');
            app.btnNIRSRUN_Applications.ButtonPushedFcn = createCallbackFcn(app, @btnNIRSRunButtonPushed, true);
            app.btnNIRSRUN_Applications.Enable = 'off';
            app.btnNIRSRUN_Applications.Text = 'RUN';
            app.EEGLamp_2Label = uilabel(app.ApplicationsTab);
            app.EEGLamp_2Label.Text = 'EEG';
            app.EEGLamp_2 = uilamp(app.ApplicationsTab);
            app.NIRSLamp_2Label = uilabel(app.ApplicationsTab);
            app.NIRSLamp_2Label.Text = 'NIRS';
            app.NIRSLamp_2 = uilamp(app.ApplicationsTab);
            app.btnStartApplications = uibutton(app.ApplicationsTab, 'push');
            app.btnStartApplications.ButtonPushedFcn = createCallbackFcn(app, @btnStartApplicationsPushed, true);
            app.btnStartApplications.Text = 'Start';
            app.cbTestWithNoDataAcquisition_Application = uicheckbox(app.ApplicationsTab);
            app.cbTestWithNoDataAcquisition_Application.ValueChangedFcn = createCallbackFcn(app, @cbTestWithNoDataAcquisition_ApplicationValueChanged, true);
            app.cbTestWithNoDataAcquisition_Application.Text = 'Test with no data acquisition';
            % Show the figure after all components are created
            app.HybridBCIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HybridBCI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HybridBCIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HybridBCIUIFigure)
        end
    end
end