classdef ModelBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelBuildingUIFigure           matlab.ui.Figure
        tbgMain                         matlab.ui.container.TabGroup
        tbData                          matlab.ui.container.Tab
        DataListBoxLabel                matlab.ui.control.Label
        lbData                          matlab.ui.control.ListBox
        lbChannels                      matlab.ui.control.ListBox
        cbBadChannels                   matlab.ui.control.CheckBox
        tbEEGPreprocessing              matlab.ui.container.Tab
        FilterDropDownLabel             matlab.ui.control.Label
        ddFilterEEG                     matlab.ui.control.DropDown
        cbEEG                           matlab.ui.control.CheckBox
        cbEMG                           matlab.ui.control.CheckBox
        cbEOG                           matlab.ui.control.CheckBox
        lblSource                       matlab.ui.control.Label
        lblEEGBands                     matlab.ui.control.Label
        cbWideband                      matlab.ui.control.CheckBox
        cbDelta                         matlab.ui.control.CheckBox
        cbTheta                         matlab.ui.control.CheckBox
        cbAlpha                         matlab.ui.control.CheckBox
        cbBeta                          matlab.ui.control.CheckBox
        edfWidebandLow                  matlab.ui.control.NumericEditField
        edfDeltaLow                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        edfWidebandHigh                 matlab.ui.control.NumericEditField
        edfDeltaHigh                    matlab.ui.control.NumericEditField
        edfThetaLow                     matlab.ui.control.NumericEditField
        edfAlphaLow                     matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        edfThetaHigh                    matlab.ui.control.NumericEditField
        edfAlphaHigh                    matlab.ui.control.NumericEditField
        edfBetaLow                      matlab.ui.control.NumericEditField
        Label_13                        matlab.ui.control.Label
        edfBetaHigh                     matlab.ui.control.NumericEditField
        btnAdd                          matlab.ui.control.Button
        btnRemove                       matlab.ui.control.Button
        lbPreprocessing_Before          matlab.ui.control.ListBox
        lbPreprocessing_After           matlab.ui.control.ListBox
        ProcessingpipelineLabel         matlab.ui.control.Label
        tbNIRSPreprocessing             matlab.ui.container.Tab
        lblBaselineCorrection           matlab.ui.control.Label
        ddBaselineReference             matlab.ui.control.DropDown
        OxyDeoConversionLabel           matlab.ui.control.Label
        ddOxyDeoxyConversion            matlab.ui.control.DropDown
        FilterLabel                     matlab.ui.control.Label
        ddNIRSFilter                    matlab.ui.control.DropDown
        FilterRangeEditFieldLabel       matlab.ui.control.Label
        edfNIRSLowcut                   matlab.ui.control.NumericEditField
        edfNIRSHighcut                  matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        PreprocessingPipelineListBoxLabel  matlab.ui.control.Label
        lbNIRSPreprocessingBefore       matlab.ui.control.ListBox
        btnNIRSadd                      matlab.ui.control.Button
        btnNIRSremove                   matlab.ui.control.Button
        lbNIRSPreprocessingAfter        matlab.ui.control.ListBox
        tbEEGFeature                    matlab.ui.container.Tab
        lbFeatures                      matlab.ui.control.ListBox
        FeatureextractionLabel          matlab.ui.control.Label
        tbNIRSFeature                   matlab.ui.container.Tab
        lbNIRSFeatures                  matlab.ui.control.ListBox
        cbHbO                           matlab.ui.control.CheckBox
        cbHbT                           matlab.ui.control.CheckBox
        cbHbR                           matlab.ui.control.CheckBox
        NIRS_FeatureextractionLabel     matlab.ui.control.Label
        tbDimensionality                matlab.ui.container.Tab
        DimensionalityreductionmethodDropDownLabel  matlab.ui.control.Label
        ddDimensionalityReductionMethod  matlab.ui.control.DropDown
        ofFeaturesSliderLabel           matlab.ui.control.Label
        sldFeatures                     matlab.ui.control.Slider
        cbApplyDimensionalityReduction  matlab.ui.control.CheckBox
        cbConsistency                   matlab.ui.control.CheckBox
        FeatureConsistencymethodDropDownLabel  matlab.ui.control.Label
        ddConsistency                   matlab.ui.control.DropDown
        tbClassifier                    matlab.ui.container.Tab
        ClassifierDropDownLabel         matlab.ui.control.Label
        ddClassifier                    matlab.ui.control.DropDown
        btnBuildModel                   matlab.ui.control.Button
        ResultsTextArea                 matlab.ui.control.TextArea
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        btnClear                        matlab.ui.control.Button
        tbValidation                    matlab.ui.container.Tab
        ValidationDataLabel             matlab.ui.control.Label
        lbValidationData                matlab.ui.control.ListBox
        TextArea                        matlab.ui.control.TextArea
        btnValidate                     matlab.ui.control.Button
        ModelDropDownLabel              matlab.ui.control.Label
        ddModel                         matlab.ui.control.DropDown
        btnClearValidation              matlab.ui.control.Button
        btnNext                         matlab.ui.control.Button
        btnClearTest                    matlab.ui.control.Button
        btnLoadModel                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        %Added by Ale
        CallingApp;
        
        subpath = [];
        
        isEEG = true;
        rejectChannel  = false;
        
        rejectedChannels = [];
        selectedBlocks   = [];
        thinkinPeriods   = [];
        baseLines        = [];
        qLables          = [];
        channelLabels    = [];
        selectedBands    = [];
        
        isEEGSelected = 1;
        isEMGSelected = 1;
        isEOGSelected = 1;
        
        sRate = 1;
        pID = 0;
        
        source = ''; % Description
        progBar; % Description
        
        trialsPerBlock = [];
    end
    
    methods (Access = private)
        
        function results = GetFeaturesList(app)
            results = {'TD_max&min','TD_area','TD_peak','FD_area','FD_max&min','FD_meanSpectra','FD_peak'};
        end
        
        function ShowMessage(app, msg)
            msg = strcat({'- '}, msg);
            app.ResultsTextArea.Value = [msg; app.ResultsTextArea.Value; ];
            drawnow;
        end
        
        function ShowValidationMessage(app, msg)
            msg = strcat({'- '}, msg);
            app.TextArea.Value = [msg; app.TextArea.Value; ];
            drawnow;
        end
        
        function ShowTestMessage(app, msg)
            msg = strcat({'- '}, msg);
            app.TestTextArea.Value = [msg; app.TestTextArea.Value;];
            drawnow;
        end
        
        function CheckFileConsistency_ModelBuilding(app, newBlock)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % This function check the channels in common of the files selected to build a model
            % If there are no common channels it shows an error message
            % It we have common channels it removes the non common channels for the data
            %
            % Added by Jayro Martínez
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            consistency_level = [];
            
            % We check for the intersection between the channel lists
            member_index = ismember(app.channelLabels, newBlock.dataRecived.ChannelLabels);
            
            % If there are no common channels we show an error message
            if sum(member_index) == 0
                app.lbData.Value = app.lbData.Items(1);
                msgbox('Files do not have channels in common. Please change file selection','Error','error', 'modal');
                return
            end
            % If we have common channels we:
            %     - Remove the non common channels in app
            %     - Store the data in newBlock in the correct channel order (so we only have the common channels
            %     - Concatenate app and newBlock
            
            %We concatenate the number of trials per block
            if strcmp(app.source,'NIRS')
              app.trialsPerBlock.nirs = [app.trialsPerBlock.nirs, size(newBlock.dataRecived.thinkingPeriodes,3)];
            elseif strcmp(app.source, 'EEG')
              app.trialsPerBlock.eeg = [app.trialsPerBlock.eeg, size(newBlock.dataRecived.thinkingPeriodes,3)];
            end
            
            app.thinkinPeriods = app.thinkinPeriods(member_index,:,:);
            app.baseLines = app.baseLines(member_index,:,:);
            new_thinkinPeriods = [];
            new_baseLines = [];
            
            common_channels = app.channelLabels(member_index);
            app.channelLabels = common_channels;
            for i = 1:length(common_channels)
                chan_index = ismember(newBlock.dataRecived.ChannelLabels, app.channelLabels(i));
                new_thinkinPeriods = cat(1, new_thinkinPeriods, newBlock.dataRecived.thinkingPeriodes(chan_index,:,:));
                new_baseLines = cat(1, new_baseLines, newBlock.dataRecived.baseLines(chan_index,:,:));
            end
            
            app.thinkinPeriods = cat(3, app.thinkinPeriods, new_thinkinPeriods);
            app.baseLines = cat(3, app.baseLines, new_baseLines);
            app.qLables = cat(2, app.qLables, newBlock.dataRecived.questionLabels);
            
        end
        
        function [results, consistency_level] = CheckFileConsistency_ModelValidation(app, data_concat)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % This function check the channels in common of the files selected to validate a model
            % Output:
            %           results: List of common channels
            %           consistency_level:
            %                               =>  1: There are channels in common
            %                               =>  0: there are NO channels in common
            % Added by Jayro Martínez
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            common = data_concat(1).dataRecived.ChannelLabels;
            consistency_level = [];
            
            % We check for the intersection between the channel lists
            for i = 2:size(data_concat,2)
                common  = intersect(common, data_concat(i).dataRecived.ChannelLabels);
            end
            
            results = common;
            
            % We check if there are channels in common
            if(length(common) > 0)
                consistency_level = 1;
            else
                consistency_level = 0;
            end
            
        end
        
        function UpateProgressBasr(app, value)
            switch value
                case 1
                    app.EditField.BackgroundColor = [0 1 0];
                case 2
                    app.EditField_4.BackgroundColor = [0 1 0];
                case 3
                    app.EditField_3.BackgroundColor = [0 1 0];
                case 4
                    app.EditField_2.BackgroundColor = [0 1 0];
                case 0
                    app.EditField.BackgroundColor   = [0.5 0.5 0.5];
                    app.EditField_2.BackgroundColor = [0.5 0.5 0.5];
                    app.EditField_3.BackgroundColor = [0.5 0.5 0.5];
                    app.EditField_4.BackgroundColor = [0.5 0.5 0.5];
            end
        end
        
        
        function SaveModel(app, model, nameFromUser)
            % Save Model
            if ~isempty(nameFromUser)
                nameFromUser = ['_' nameFromUser];
            end
            app.ShowMessage('Saving Model');
            
            if ~exist([app.subpath 'Models' filesep app.source],'dir')
                mkdir([app.subpath 'Models' filesep app.source]);
            end
            
            modelDayIndex = size(dir(fullfile(app.subpath,'Models',app.source,[date '*_model.mat'])),1) + 1;
            modelname = fullfile(app.subpath, 'Models' ,app.source, [date '_' num2str(modelDayIndex) nameFromUser '_model']);
            save(modelname,'model');
            app.ShowMessage('Model Saved.');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, pID, source)
            % path
            pathClassifers  = [pwd filesep 'lib' filesep 'Classification' filesep 'Classifiers' filesep 'Classifiers'];
            
            pathEEGFilters  = [pwd filesep 'lib' filesep 'EEG' filesep 'PreProcessing' filesep 'Filters'];
            pathEEGFeatures = [pwd filesep 'lib' filesep 'EEG' filesep 'Features'];
            pathEEGInter    = [pwd filesep 'lib' filesep 'EEG' filesep 'PreProcessing' filesep 'InterPreprocessing'];
            
            pathNIRSConversion = [pwd filesep 'lib' filesep 'NIRS' filesep 'PreProcessing' filesep 'OxyDeoxyConversion'];
            pathNIRSFilters    = [pwd filesep 'lib' filesep 'NIRS' filesep 'PreProcessing' filesep 'Filters'];
            pathNIRSInter      = [pwd filesep 'lib' filesep 'NIRS' filesep 'PreProcessing' filesep 'InterPreprocessing'];
            pathNIRSFeatures   = [pwd filesep 'lib' filesep 'NIRS' filesep 'Features']
            % Centering GUI
            movegui(app.ModelBuildingUIFigure,"center");
            
            app.CallingApp = mainApp;
            app.progBar = uiprogressdlg(app.CallingApp.HybridBCIUIFigure,'Title','Build model','Indeterminate','on');
            app.source = source;
            app.subpath = [pwd filesep 'Subjects' filesep pID filesep];
            
            % find eeg and nirs tabs
            eegTabPreprocess  = app.tbEEGPreprocessing;
            eegTabFeatures    = app.tbEEGFeature;
            nirsTabPreprocess = app.tbNIRSPreprocessing;
            nirsTabFeatures   = app.tbNIRSFeature;
            
            app.ddDimensionalityReductionMethod.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'lib' filesep 'Classification' filesep 'DimensionReduction'],'m',0);
            app.ddConsistency.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'lib' filesep 'Classification' filesep 'FeatureConsistency'],'m',0);
            
            app.ddClassifier.Items = CLISLAB_GetListFilesFromFolder(pathClassifers, 'm',0);
            
            if strcmp(source,'EEG')
                % remove nirs tabs from the gui
                nirsTabPreprocess.Parent = [];
                nirsTabFeatures.Parent   = [];
                
                allfiles = dir(fullfile(app.subpath,'**','*EEGdata.mat'));
                app.ddFilterEEG.Items            = CLISLAB_GetListFilesFromFolder(pathEEGFilters,'m',0);
                app.lbFeatures.Items             = CLISLAB_GetListFilesFromFolder(pathEEGFeatures,'m',0);
                app.lbPreprocessing_Before.Items = CLISLAB_GetListFilesFromFolder(pathEEGInter,'m',0);
                
                if ~exist([pwd filesep 'Subjects' filesep pID filesep 'Models' filesep 'EEG'])
                   mkdir([pwd filesep 'Subjects' filesep pID filesep 'Models' filesep 'EEG']); 
                end
                app.ddModel.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'Subjects' filesep pID filesep 'Models' filesep 'EEG'], 'mat', 0);
                
                % Added by Jayro
%                 preprocessing_before_list = [];
%                 preprocessing_before_list = cat(2,preprocessing_before_list, CLISLAB_GetListFilesFromFolder([pwd filesep 'lib' filesep 'Devices' filesep 'EEG' filesep 'PreProcessing' filesep 'AmplitudeCorrections'],'m',0));
%                 preprocessing_before_list = cat(2,preprocessing_before_list, CLISLAB_GetListFilesFromFolder([pwd filesep 'lib' filesep 'Devices' filesep 'EEG' filesep 'PreProcessing' filesep 'InterPreprocessing'],'m',0));
%                 app.lbPreprocessing_Before.Items = preprocessing_before_list;
                
            elseif strcmp(source,'NIRS')
                % remove eeg tabs from the gui
                eegTabPreprocess.Parent = [];
                eegTabFeatures.Parent = [];
                allfiles = dir(fullfile(app.subpath,'**','*NIRSdata.mat'));
                
                app.ddOxyDeoxyConversion.Items      = CLISLAB_GetListFilesFromFolder(pathNIRSConversion,'m',0);
                app.ddNIRSFilter.Items              = CLISLAB_GetListFilesFromFolder(pathNIRSFilters,'m',0);
                app.lbNIRSPreprocessingBefore.Items = CLISLAB_GetListFilesFromFolder(pathNIRSInter,'m',0);
                app.lbNIRSFeatures.Items            = CLISLAB_GetListFilesFromFolder(pathNIRSFeatures,'m',0);
                
                if ~exist([pwd filesep 'Subjects' filesep pID filesep 'Models' filesep 'NIRS'])
                    mkdir([pwd filesep 'Subjects' filesep pID filesep 'Models' filesep 'NIRS']);
                end
                app.ddModel.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'Subjects' filesep pID filesep 'Models' filesep 'NIRS'], 'mat', 0);
                
                
            else
                %TBC
            end
            
            % Added by Ale
            [~,idx] = sort([allfiles.datenum],'descend');
            allfiles = allfiles(idx);
            
            %app.sRate = 500;
            app.pID = pID;
            app.lbData.Items = {'-' allfiles.name};
            app.lbValidationData.Items = {'-' allfiles.name};


        end

        % Button pushed function: btnBuildModel
        function btnBuildModelButtonPushed(app, event)
            app.UpateProgressBasr(0);
            
            if isempty(app.thinkinPeriods)
                app.tbgMain.SelectedTab = app.tbData;
                app.btnNext.Visible = true;
                return;
            end


            % Rejecting Channels
            if app.rejectChannel
                [~ , app.rejectedChannels] = ismember(app.lbChannels.Value, app.lbChannels.Items);
                app.thinkinPeriods(app.rejectedChannels,:,:) = [];
                app.baseLines(app.rejectedChannels,:,:) = [];
                app.channelLabels(app.rejectedChannels) = [];
            end
            app.thinkinPeriods = double(app.thinkinPeriods);
            
            
            % preprocessing
            app.ShowMessage('Pre proccessing ...');
            
            tic
            if strcmp(app.source,'EEG')
                filter_type = app.ddFilterEEG.Value;
                selectedSource = [app.cbEEG.Value app.cbEOG.Value app.cbEMG.Value];
                
                wideband.low = [];
                wideband.high = [];
                delta.low = [];
                delta.high = [];
                theta.low = [];
                theta.high = [];
                alpha.low = [];
                alpha.high = [];
                beta.low = [];
                beta.high = [];
                
        
                wideband.low = app.edfWidebandLow.Value;
                wideband.high = app.edfWidebandHigh.Value;
                wideband.used = app.cbWideband.Value;
            
                delta.low = app.edfDeltaLow.Value;
                delta.high = app.edfDeltaHigh.Value;
                delta.used = app.cbDelta.Value;
            
                theta.low = app.edfThetaLow.Value;
                theta.high = app.edfThetaHigh.Value;
                theta.used = app.cbTheta.Value;
            
                alpha.low = app.edfAlphaLow.Value;
                alpha.high = app.edfAlphaHigh.Value;
                alpha.used = app.cbAlpha.Value;
            
                beta.low = app.edfBetaLow.Value;
                beta.high = app.edfBetaHigh.Value;
                beta.used = app.cbBeta.Value;
                    
                    
                app.selectedBands = [wideband delta theta alpha beta];
                
                
                [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_EEG_Online_Preprocessing(app.baseLines, app.thinkinPeriods, app.channelLabels, app.sRate, filter_type, app.lbPreprocessing_After.Items, selectedSource, app.selectedBands);
                
            elseif strcmp(app.source,'NIRS')
                conversion_type = app.ddOxyDeoxyConversion.Value;
                baseline_reference = app.ddBaselineReference.Value;
                filter_type = app.ddNIRSFilter.Value;
                filter_cut_freqs = [app.edfNIRSLowcut.Value, app.edfNIRSHighcut.Value];
                
%                 if isempty(conversion_type) || isempty(baseline_reference)
%                     errordlg('OxyDeoxy must be set in preprocessing panel.','Preprocessing Error');
%                     return;
%                 end
                
                [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_NIRS_Online_Preprocessing(app.baseLines, app.thinkinPeriods, app.sRate, conversion_type, baseline_reference, filter_type, filter_cut_freqs, app.lbNIRSPreprocessingAfter.Items);
            else
                %TBC
            end
            app.ShowMessage(['Pre proccessed in ' num2str(toc) ' seconds']);
            app.UpateProgressBasr(1);
            
            
            % Extracting Features
            app.ShowMessage('Extracting Features ...');
            tic
            if strcmp(app.source,'EEG')
                %wu
                selectedFeatures = app.lbFeatures.Value';
                if size(app.lbFeatures.Value,2) == 1
                    selectedFeatures = selectedFeatures;
                end
                
                [features, featuresLabels]= CLISLAB_EEG_Online_ExtractFeatures(preprocessedThinkingData,app.sRate,selectedFeatures);
            elseif strcmp(app.source,'NIRS')
                selectedSource = [app.cbHbO.Value app.cbHbR.Value app.cbHbT.Value];
                selectedFeatures = app.lbNIRSFeatures.Value;
                
                [features, featuresLabels]= CLISLAB_NIRS_Online_ExtractFeatures(preprocessedThinkingData,app.sRate,selectedSource,selectedFeatures);
            else
                %TBC
            end
            app.ShowMessage(['Features Extracted in ' num2str(toc) ' seconds']);
            
            app.UpateProgressBasr(2);
            
            trainFeatures = [];
            trainFeatures.features   = features;
            trainFeatures.featuresLabels = featuresLabels;
            trainFeatures.classLabels = app.qLables';
            
            %Calling to Feature Consistency & Dimensionality Reduction
            if app.cbConsistency.Value
                tic
                app.ShowMessage('Checking Feature Consistency ...');
                if strcmp(app.source,'EEG')
                    trainFeatures = feval(app.ddConsistency.Value, trainFeatures, app.trialsPerBlock.eeg);
                elseif strcmp(app.source,'NIRS')
                    trainFeatures = feval(app.ddConsistency.Value, trainFeatures, app.trialsPerBlock.nirs);
                end
                toc
                app.ShowMessage(['Feature Consistency done in ' num2str(toc) ' seconds']);
            end
            
            if app.cbApplyDimensionalityReduction.Value
                tic
                app.ShowMessage('Applying Dimensionality Reduction ...');
                trainFeatures = feval(app.ddDimensionalityReductionMethod.Value, trainFeatures, app.sldFeatures.Value);
                toc
                app.ShowMessage(['Dimensionality Reduction done in ' num2str(toc) ' seconds']);
            end
            
            app.UpateProgressBasr(3);
            
            % Create Model
            app.ShowMessage('Builidng a Model ...');
            
            tic
            model = CLISLAB_BuildModel(trainFeatures,app.ddClassifier.Value);
            app.ShowMessage(['Model built in ' num2str(toc) ' seconds']);
            app.ShowMessage(['Classification accuracy is ' num2str(model.accuracy)])
            app.UpateProgressBasr(4);
            
            % Save Model
            nameModel = inputdlg(sprintf('Save as: (Leave empty for default name)',model.accuracy),'Save model');
            if ~isempty(nameModel)
                model.rejectedChannels               = app.rejectedChannels;
                model.selectedFeatures               = trainFeatures.featuresLabels;
                model.dateTime                       = datetime;
                model.patientID                      = app.pID;
                model.trainingData                   = app.lbData.Value;
                model.selectedSource                 = selectedSource;
                
                if app.cbApplyDimensionalityReduction.Value
                    model.dimensionalityFunction     = app.ddDimensionalityReductionMethod.Value;
                else
                    model.dimensionalityFunction     = [];
                end
                
                if app.cbConsistency.Value
                    model.consistencyFunction        = app.ddConsistency.Value;
                else
                    model.consistencyFunction        = [];
                end
                
                model.features                       = selectedFeatures;
                model.filterType                     = filter_type;
                % Added by Ale
                model.sRate                          = app.sRate;
                % We add the channels used for building the model
                % Added by Jayro Martínez
                model.channels                       = app.channelLabels;
                model.usedFeatures                   = selectedFeatures;
                % Add % of features used in dimension reduction
                model.ratioFeaturesUsed              = app.sldFeatures;
                if strcmp(app.source,'EEG')
                    model.processing        = app.lbPreprocessing_After.Items;
                    model.selectedBands     = app.selectedBands;
                elseif strcmp(app.source,'NIRS')
                    model.conversionType        = conversion_type;
                    model.baselineReference     = baseline_reference;
                    model.filterCutFreqs        = filter_cut_freqs;
                    model.processing            = app.lbNIRSPreprocessingAfter.Items;   
                else
                    %TBC
                end
                app.SaveModel(model,char(nameModel));
                app.ddModel.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'Subjects' filesep app.pID filesep 'Models' filesep app.source], 'mat', 0);
            end
            
            
            % clear variables
            app.lbData.Value = '-';
            %app.selectedBlocks = [];
            app.lbChannels.Items = {};
            app.thinkinPeriods = [];
            app.baseLines      = [];
            app.qLables        = [];
            app.channelLabels  = [];
            
            
        end

        % Value changed function: lbData
        function lbDataValueChanged(app, event)
            selectedBlockNames  = app.lbData.Value;
            app.thinkinPeriods = [];
            app.baseLines      = [];
            app.qLables        = [];
            app.channelLabels  = [];
            app.sRate          = 0;
            
            app.trialsPerBlock.nirs = [];
            app.trialsPerBlock.eeg = [];
            
            if ~isempty(selectedBlockNames) && ~any(strcmp(selectedBlockNames,'-'))
                
                for i = 1:size(selectedBlockNames,2)
                    blockName = dir(fullfile(app.subpath,'**',selectedBlockNames{:,i}));
                    newBlock = load(fullfile(blockName.folder,blockName.name));
                    
                    % Only for Test - To be removed
                    %newBlock.dataRecived.sRate = 500;
                    
                    % If the new block has no channel labels we create it
                    if ~isfield(newBlock.dataRecived,'ChannelLabels')
                        newBlock.dataRecived.ChannelLabels = string(1:size(newBlock.dataRecived.baseLines,1));
                    end
                    
                    % Check that trial number is the same in thinkingPeriods and baseLines
                    % Added by Jayro Martínez
                    if(size(newBlock.dataRecived.thinkingPeriodes,3) ~= size(newBlock.dataRecived.baseLines,3))
                        app.lbData.Value = app.lbData.Items(1);
                        msgbox('Trial number is not the same in ThinkingPeriods and BaseLines','Error','error', 'modal');
                        % Check if samplingRate of the new dataset is the same than the old
                        % Added by Alessandro Tonin
                    elseif(i>1) && app.sRate ~= newBlock.dataRecived.sRate
                        app.lbData.Value = app.lbData.Items(1);
                        msgbox('The sampling rate in the datasets are not the same. Please change file selection', 'Error','error', 'modal');
                        
                        % Check if the number of timepoints of the new dataset is the same than the old
                        % Added by Jayro Martínez
                    elseif(i>1) && ((size(app.thinkinPeriods,2) ~= size(newBlock.dataRecived.thinkingPeriodes,2)) || (size(app.baseLines,2) ~= size(newBlock.dataRecived.baseLines,2)))
                        app.lbData.Value = app.lbData.Items(1);
                        msgbox('The number of timepoints in the datasets are not the same. Please change file selection', 'Error','error', 'modal');
                        
                        % If all dimensions are OK, we check channel consistency and concatenate the data
                        % Added by Jayro
                    elseif i>1
                        % Check consistency and extract common channels before concatenate it in the 3rd dimension
                        CheckFileConsistency_ModelBuilding(app, newBlock);
                        
                        app.lbChannels.Items = app.channelLabels;
                        
                        % If there is only one dataset we save it
                        % Added by Jayro
                    else
                        app.thinkinPeriods = newBlock.dataRecived.thinkingPeriodes;
                        app.baseLines = newBlock.dataRecived.baseLines;
                        app.qLables = newBlock.dataRecived.questionLabels;
                        app.channelLabels  = newBlock.dataRecived.ChannelLabels;
                        app.lbChannels.Items = app.channelLabels;
                        app.sRate = newBlock.dataRecived.sRate;
                        
                        if strcmp(app.source,'NIRS')
                            app.trialsPerBlock.nirs = [size(newBlock.dataRecived.thinkingPeriodes,3)];
                        elseif strcmp(app.source, 'EEG')
                            app.trialsPerBlock.eeg = [size(newBlock.dataRecived.thinkingPeriodes,3)];
                        end
                    end
                    
                end
                
            % If there is no data selected
            else
                app.lbChannels.Items = {};
            end
            
        end

        % Value changed function: cbBadChannels
        function cbBadChannelsValueChanged(app, event)
            app.rejectChannel = app.cbBadChannels.Value;
            if app.rejectChannel
                app.lbChannels.Enable = 'on';
            else
                app.lbChannels.Enable = 'off';
            end
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
            
            value = app.cbWideband.Value;
            if value
                for i = 1:size(app.NewModelConfigPanel.Children,1)
                    app.NewModelConfigPanel.Children(i).Enable = 'on';
                end
            else
                for i = 1:size(app.NewModelConfigPanel.Children,1)
                    app.NewModelConfigPanel.Children(i).Enable = 'off';
                end
            end
        end

        % Button pushed function: btnClear
        function btnClearPushed(app, event)
            app.ResultsTextArea.Value = '';
            app.UpateProgressBasr(0);
            drawnow;
        end

        % Close request function: ModelBuildingUIFigure
        function ModelBuildingUIFigureCloseRequest(app, event)
            % Added by Ale
            % Update list of models
            UpdateModelList(app.CallingApp);
            
            % Enable model drop down in hybrid bci
            app.CallingApp.ddModel.Enable = 'On';
            app.CallingApp.ddModel.Value = 'None';
            close(app.progBar)
            % Close app
            delete(app)
            
        end

        % Button pushed function: btnNext
        function btnNextPushed(app, event)
            
            switch app.tbgMain.SelectedTab
                case app.tbData
                    app.btnLoadModel.Visible = 'Off'
                    
                    %If no file is slected
                    if isempty(app.thinkinPeriods)
                        msgbox('Please select at least one block.', 'Error','error', 'modal');
                        return;
                    else
                        if strcmp(app.source,'EEG')
                            app.tbgMain.SelectedTab = app.tbEEGPreprocessing;
                            app.ddFilterEEG.Value = {'CLISLAB_EEG_IIR_butter_filter'};
                            %app.ddAmpCorrection.Value = {'CLISLAB_EEG_normalization'};
                        elseif strcmp(app.source,'NIRS')
                            app.tbgMain.SelectedTab = app.tbNIRSPreprocessing;
                        else
                            %TBC
                        end
                        
                    end
                case app.tbEEGPreprocessing
                    if strcmp(app.source,'EEG')
                        app.tbgMain.SelectedTab = app.tbEEGFeature;
                    else
                        %TBC
                    end
                case app.tbNIRSPreprocessing
                    if strcmp(app.source,'NIRS')
                        app.tbgMain.SelectedTab = app.tbNIRSFeature;
                    else
                        %TBC
                    end
                case app.tbEEGFeature
                    %If no feature is selected
                    if isempty(app.lbFeatures.Value)
                        msgbox('Please select at least one feature to be extracted.','Error','error', 'modal');
                        return;
                    else
                        app.tbgMain.SelectedTab = app.tbDimensionality;
                    end
                case app.tbNIRSFeature
                    %If no feature is selected
                    if isempty(app.lbNIRSFeatures.Value)
                        msgbox('Please select at least one feature to be extracted.','Error','error', 'modal');
                        return;
                        % If no feature is selected
                    else
                        app.tbgMain.SelectedTab = app.tbDimensionality;
                    end
                    
                 case app.tbDimensionality
                    app.tbgMain.SelectedTab = app.tbClassifier;
                        
                 case app.tbClassifier
                    app.tbgMain.SelectedTab = app.tbValidation;
            
            end
        end

        % Button pushed function: btnValidate
        function btnValidatePushed(app, event)
            % Added by Jayro Martínez
            
            data = [];
            
            data_names = app.lbValidationData.Value;
            
            if isempty(data_names)
                msgbox('Please select at least one dataset.','Error','error', 'modal');
                return;
            end
            
            % Loading for New Folder Structure (EEG & NIRS folders)
            model = load(fullfile(pwd, 'Subjects', app.pID, 'Models', app.source, [app.ddModel.Value '.mat']));
            
            % Loop over all the data selected
            for data_index = 1:length(data_names)
                curr_name = string(data_names(data_index));
                new_data_name = extractBefore(curr_name,"_");
                data = load(fullfile(pwd, 'Subjects', app.pID, new_data_name,app.source, 'mat', curr_name));
                data_concat(:,data_index) = data;
            end
            
            % Check the channel consistency between the data selected
            [results, consistency_level] = CheckFileConsistency_ModelValidation(app, data_concat);
            
            % If there is channel consistency in the data &
            % the common channels are exactly the same channels used for building the model
            if(consistency_level == 1) && (sum(ismember(model.model.channels,results)) == length(model.model.channels))
                
                n_total = 0;
                n_correct = 0;
                
                for iter = 1:length(data_concat)
                    iter_correct = 0;
                    
                    % We only use the common channels between the data and the model
                    chan_index = ismember(data_concat(iter).dataRecived.ChannelLabels, model.model.channels);
                    
                    app.ShowValidationMessage('Preprocessing...');
                    if strcmp(app.source,'EEG')
                        % Preprocessing using ony the channels present in the model
                        [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_EEG_Online_Preprocessing(data_concat(iter).dataRecived.baseLines(chan_index,:,:), data_concat(iter).dataRecived.thinkingPeriodes(chan_index,:,:), data_concat(iter).dataRecived.ChannelLabels(:,chan_index), model.model.sRate, model.model.filterType, model.model.processing, model.model.selectedSource, model.model.selectedBands);
                    elseif strcmp(app.source,'NIRS')
                        % Preprocessing using ony the channels present in the model
                        [preprocessedBaselineData,preprocessedThinkingData] = CLISLAB_NIRS_Online_Preprocessing(data_concat(iter).dataRecived.baseLines(chan_index,:,:), data_concat(iter).dataRecived.thinkingPeriodes(chan_index,:,:), model.model.sRate, model.model.conversionType, model.model.baselineReference, model.model.filterType, model.model.filterCutFreqs, model.model.processing);
                    else
                        %TBC
                    end
                    
                    app.ShowValidationMessage('Extracting Features...');
                    % Feature Extraction
                    if strcmp(app.source,'EEG')
                        [features, featuresLabels]= CLISLAB_EEG_Online_ExtractFeatures(preprocessedThinkingData,model.model.sRate, model.model.selectedFeatures);
                    elseif strcmp(app.source,'NIRS')
                        % Feature Extraction
                        [features, featuresLabels]= CLISLAB_NIRS_Online_ExtractFeatures(preprocessedThinkingData,model.model.sRate,model.model.selectedSource ,model.model.selectedFeatures');
                    else
                        %TBC
                    end
                    
                    % Dimension reduction for features
                    [features,featuresLabels] = CLISLAB_SelectFeatures(features, featuresLabels,model.model.selectedFeatures);
                    
                    testFeatures = [];
                    testFeatures.features = features;
                    testFeatures.featuresLabels = featuresLabels;
                    testFeatures.classLabels = data_concat(iter).dataRecived.questionLabels';
                    
                    app.ShowValidationMessage('Validating...');
                    % Classification
                    [pred, prob, acc] = CLISLAB_PredictLabel(model.model,testFeatures,data_concat(iter).dataRecived.questionLabels);
                    % For calculating the total accuracy
                    n_total = n_total + length(pred);
                    iter_correct = acc / 100 * length(pred);
                    n_correct   = n_correct + iter_correct;
                end
                
                % We calculate the total accuracy
                total_acc = n_correct / n_total * 100;
                shw_msg = ['Accuracy is: '  + string(round(total_acc, 2)) + '%'];
                app.ShowValidationMessage(shw_msg);
                
            else
                msgbox('Model and/or files selected are not consistent','Error','error', 'modal');
            end
        end

        % Selection change function: tbgMain
        function tbgMainSelectionChanged(app, event)
            selectedTab = app.tbgMain.SelectedTab;
            % Enable or disable "Next" button
            if selectedTab == app.tbClassifier || selectedTab == app.tbValidation
                app.btnNext.Visible = 0;
            else
                app.btnNext.Visible = 1;
            end
            
            % Enable or disable "Load Model" button
            if selectedTab ~= app.tbData
                app.btnLoadModel.Visible = 0;
            else
                app.btnLoadModel.Visible = 1;
            end
            
            % Enable or disable all editfields in EEG Preprocessing
            if selectedTab == app.tbEEGPreprocessing
               app.edfWidebandHigh.Enable = app.cbWideband.Value; 
               app.edfWidebandLow.Enable  = app.cbWideband.Value;
               app.edfThetaHigh.Enable    = app.cbTheta.Value;
               app.edfThetaLow.Enable     = app.cbTheta.Value;
               app.edfBetaHigh.Enable     = app.cbBeta.Value;
               app.edfBetaLow.Enable      = app.cbBeta.Value;
               app.edfDeltaHigh.Enable    = app.cbDelta.Value;
               app.edfDeltaLow.Enable     = app.cbDelta.Value;
               app.edfAlphaHigh.Enable    = app.cbAlpha.Value;
               app.edfAlphaLow.Enable     = app.cbAlpha.Value;
            end
            
            % Enable or disable slider and dropdowns in Dimension Reduction Tab
            if selectedTab == app.tbDimensionality
               app.sldFeatures.Enable                     = app.cbApplyDimensionalityReduction.Value;
               app.ddDimensionalityReductionMethod.Enable = app.cbApplyDimensionalityReduction.Value;
               app.ddConsistency.Enable                   = app.cbConsistency.Value;
            end
        end

        % Button pushed function: btnClearValidation
        function btnClearValidationPushed(app, event)
            % Added by Jayro Martínez
            app.TextArea.Value = '';
            app.UpateProgressBasr(0);
            drawnow;
        end

        % Value changed function: cbEEG
        function cbEEGValueChanged(app, event)
            % Added by Jayro
            value = app.cbEEG.Value;
            app.cbWideband.Enable = value;
            app.cbDelta.Enable = value;
            app.cbTheta.Enable = value;
            app.cbAlpha.Enable = value;
            app.cbBeta.Enable = value;
            
            if value
                app.edfAlphaHigh.Enable = app.cbAlpha.Value;
                app.edfAlphaLow.Enable = app.cbAlpha.Value;
                app.edfBetaHigh.Enable = app.cbBeta.Value;
                app.edfBetaLow.Enable = app.cbBeta.Value;
                app.edfDeltaHigh.Enable = app.cbDelta.Value;
                app.edfDeltaLow.Enable = app.cbDelta.Value;
                app.edfThetaHigh.Enable = app.cbTheta.Value;
                app.edfThetaLow.Enable = app.cbTheta.Value;
                app.edfWidebandHigh.Enable = app.cbWideband.Value;
                app.edfWidebandLow.Enable = app.cbWideband.Value;
            else
                app.edfAlphaHigh.Enable = 'Off';
                app.edfAlphaLow.Enable = 'Off';
                app.edfBetaHigh.Enable = 'Off';
                app.edfBetaLow.Enable = 'Off';
                app.edfDeltaHigh.Enable = 'Off';
                app.edfDeltaLow.Enable = 'Off';
                app.edfThetaHigh.Enable = 'Off';
                app.edfThetaLow.Enable = 'Off';
                app.edfWidebandHigh.Enable = 'Off';
                app.edfWidebandLow.Enable = 'Off';
            end
            
        end

        % Value changed function: lbChannels
        function lbChannelsValueChanged(app, event)
            % Added by Ale, if you remove one wavelength you have to remove also the other one
            if strcmp(app.source,'NIRS')
                value = string(app.lbChannels.Value);
                items = string(app.lbChannels.Items);
                channelName = regexp(value,'\d*-\d*','match');
                channelName = string(channelName);
                %channelName = extractBetween(value,cell2mat(s),cell2mat(e));
                %chIdx = find(contains(items,channelName));
                app.lbChannels.Value = app.lbChannels.Items(contains(items,channelName));
            end
        end

        % Value changed function: ddDimensionalityReductionMethod
        function ddDimensionalityReductionMethodValueChanged(app, event)
            value = app.ddDimensionalityReductionMethod.Value;
            
        end

        % Button pushed function: btnLoadModel
        function btnLoadModelButtonPushed(app, event)
            
            models_path = fullfile(app.subpath, 'Models' ,app.source, filesep, '*.mat');
            selected_model = uigetfile(models_path, 'Select model');
            
            if size(selected_model,2)>1
                selected_model = erase(selected_model, ".mat");
            else
                return
            end
            
            app.ddModel.Items = {selected_model};
            app.ddModel.Value = selected_model;
            app.ddModel.Enable = 'Off';
            app.btnLoadModel.Visible = 'Off';
            
            model = load(fullfile(pwd, 'Subjects', app.pID, 'Models', app.source, [app.ddModel.Value '.mat']));
            
            app.lbData.Value = model.model.trainingData;
            
            % Set channel list for reject bad channels
            for i=1:length(model.model.trainingData)
                curr_name = string(model.model.trainingData(i));
                new_data_name = extractBefore(curr_name,"_");
                data = load(fullfile(pwd, 'Subjects', app.pID, new_data_name,app.source, 'mat', curr_name));
                data_concat(i) = data;
            end
            [results, consistency_level] = CheckFileConsistency_ModelValidation(app, data_concat);
            app.lbChannels.Items = results;
            if size(model.model.rejectedChannels)>0
                app.lbChannels.Value = app.lbChannels.Items(model.model.rejectedChannels);
                app.lbChannels.Enable = true;
                app.cbBadChannels.Value = true
            end
            
            if ~isempty(model.model.consistencyFunction)
                app.ddConsistency.Value = model.model.consistencyFunction;
                app.ddConsistency.Enable = true;
                app.cbConsistency.Value = true;
            else
                app.cbConsistency.Value = false;
                app.ddConsistency.Enable = false;
            end
            
            if ~isempty(model.model.dimensionalityFunction)
                app.ddDimensionalityReductionMethod.Value = model.model.dimensionalityFunction;
                app.ddDimensionalityReductionMethod.Enable = true;
                app.sldFeatures.Value = model.model.ratioFeaturesUsed.Value;
                app.cbApplyDimensionalityReduction.Value = true;
            else
                app.cbApplyDimensionalityReduction.Value = false;
                app.ddDimensionalityReductionMethod.Enable = false;
                app.sldFeatures.Enable = false;
            end
                
            app.ddClassifier.Value = model.model.classifier;
            
            if strcmp(app.source, 'EEG')
                app.ddFilterEEG.Value = model.model.filterType;
                
                if size(model.model.processing)>0
                    preprocessingAfterInBefore = strcmp(app.lbPreprocessing_Before.Items,model.model.processing);
                    app.lbPreprocessing_Before.Items = app.lbPreprocessing_Before.Items(~preprocessingAfterInBefore);
                    app.lbPreprocessing_After.Items = model.model.processing;
                end
                
                app.cbEEG.Value = model.model.selectedSource(1);
                app.cbEOG.Value = model.model.selectedSource(2);
                app.cbEMG.Value = model.model.selectedSource(3);
                
                if  ~model.model.selectedBands(1).used
                    app.cbWideband.Value = false;
                else
                    app.cbWideband.Value = true;
                    app.edfWidebandLow.Value = model.model.selectedBands(1).low;
                    app.edfWidebandHigh.Value = model.model.selectedBands(1).high;
                end
                
                if ~model.model.selectedBands(2).used
                    app.cbDelta.Value = false;
                else
                    app.cbDelta.Value = true;
                    app.edfDeltaLow.Value = model.model.selectedBands(2).low;
                    app.edfDeltaHigh.Value = model.model.selectedBands(2).high;
                end
                
                if ~model.model.selectedBands(3).used
                    app.cbTheta.Value = false;
                else
                    app.cbTheta.Value = true;
                    app.edfThetaLow.Value = model.model.selectedBands(3).low;
                    app.edfThetaHigh.Value = model.model.selectedBands(3).high;
                end
                
                if ~model.model.selectedBands(4).used
                    app.cbAlpha.Value = false;
                else
                    app.cbAlpha.Value = true;
                    app.edfAlphaLow.Value = model.model.selectedBands(4).low;
                    app.edfAlphaHigh.Value = model.model.selectedBands(4).high;
                end
                
                if ~model.model.selectedBands(5).used
                    app.cbBeta.Value = false;
                else
                    app.cbBeta.Value = true;
                    app.edfBetaLow.Value = model.model.selectedBands(5).low;
                    app.edfBetaHigh.Value = model.model.selectedBands(5).high;
                end
                
                app.lbFeatures.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'lib' filesep 'EEG' filesep 'Features'],'m',0);
                if(size(model.model.features,1)>1)
                    app.lbFeatures.Value = model.model.features;
                else
                    app.lbFeatures.Value = model.model.features{1};
                end
                                
            else
                app.ddOxyDeoxyConversion.Value = model.model.conversionType;
                app.ddBaselineReference.Value = model.model.baselineReference;
                
                app.ddNIRSFilter.Value = model.model.filterType;
                app.edfNIRSLowcut.Value = model.model.filterCutFreqs(1);
                app.edfNIRSHighcut.Value = model.model.filterCutFreqs(2);
                
                % NOT TESTED!!
                if size(model.model.processing)>0
                    preprocessingAfterInBefore = strcmp(app.lbNIRSPreprocessingBefore.Items,model.model.processing);
                    app.lbNIRSPreprocessingBefore.Items = app.lbNIRSPreprocessingBefore.Items(~preprocessingAfterInBefore);
                    app.lbNIRSPreprocessingAfter.Items = model.model.processing;
                end
                
                app.lbNIRSPreprocessingAfter.Items = model.model.processing;
                app.lbNIRSPreprocessingAfter.Value = model.model.processing;
                
                app.lbNIRSFeatures.Items = CLISLAB_GetListFilesFromFolder([pwd filesep 'lib' filesep 'NIRS' filesep 'Features'],'m',0);
                app.lbNIRSFeatures.Value = model.model.features;
                
                app.cbHbO.Value = model.model.selectedSource(1);
                app.cbHbR.Value = model.model.selectedSource(2);
                app.cbHbT.Value = model.model.selectedSource(3);
                                
            end
            
            app.tbgMain.SelectedTab = app.tbValidation;
            app.btnNext.Visible = 'Off';
            
        end

        % Value changed function: cbWideband
        function cbWidebandValueChanged(app, event)
            value = app.cbWideband.Value;
            app.edfWidebandHigh.Enable = value;
            app.edfWidebandLow.Enable = value;
        end

        % Value changed function: cbDelta
        function cbDeltaValueChanged(app, event)
            value = app.cbDelta.Value;
            app.edfDeltaHigh.Enable = value;
            app.edfDeltaLow.Enable = value;
        end

        % Value changed function: cbAlpha
        function cbAlphaValueChanged(app, event)
            value = app.cbAlpha.Value;
            app.edfAlphaHigh.Enable = value;
            app.edfAlphaLow.Enable = value;
        end

        % Value changed function: cbBeta
        function cbBetaValueChanged(app, event)
            value = app.cbBeta.Value;
            app.edfBetaHigh.Enable = value;
            app.edfBetaLow.Enable = value;
        end

        % Value changed function: cbTheta
        function cbThetaValueChanged(app, event)
            value = app.cbTheta.Value;
            app.edfThetaHigh.Enable = value;
            app.edfThetaLow.Enable = value;
        end

        % Button pushed function: btnAdd
        function btnAddButtonPushed(app, event)
            app.lbPreprocessing_After.Items = cat(2,app.lbPreprocessing_After.Items, app.lbPreprocessing_Before.Value);
            app.lbPreprocessing_Before.Items(ismember(app.lbPreprocessing_Before.Items,app.lbPreprocessing_Before.Value)) = [];
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            app.lbPreprocessing_Before.Items = cat(2,app.lbPreprocessing_Before.Items, app.lbPreprocessing_After.Value);
            app.lbPreprocessing_After.Items(ismember(app.lbPreprocessing_After.Items,app.lbPreprocessing_After.Value)) = [];
        end

        % Value changed function: cbApplyDimensionalityReduction
        function cbApplyDimensionalityReductionValueChanged(app, event)
            value = app.cbApplyDimensionalityReduction.Value;
            app.sldFeatures.Enable = value;
            app.ddDimensionalityReductionMethod.Enable = value;
        end

        % Value changed function: cbConsistency
        function cbConsistencyValueChanged(app, event)
            value = app.cbConsistency.Value;
            app.ddConsistency.Enable = value;
        end

        % Button pushed function: btnNIRSadd
        function btnNIRSaddButtonPushed(app, event)
            app.lbNIRSPreprocessingAfter.Items = cat(2,app.lbNIRSPreprocessingAfter.Items, app.lbNIRSPreprocessingBefore.Value);
            app.lbNIRSPreprocessingBefore.Items(ismember(app.lbNIRSPreprocessingBefore.Items,app.lbNIRSPreprocessingBefore.Value)) = [];
        end

        % Button pushed function: btnNIRSremove
        function btnNIRSremoveButtonPushed(app, event)
            app.lbNIRSPreprocessingBefore.Items = cat(2,app.lbNIRSPreprocessingBefore.Items, app.lbNIRSPreprocessingAfter.Value);
            app.lbNIRSPreprocessingAfter.Items(ismember(app.lbNIRSPreprocessingAfter.Items,app.lbNIRSPreprocessingAfter.Value)) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelBuildingUIFigure = uifigure('Visible', 'off');
            app.ModelBuildingUIFigure.Name = 'Model Building';
            app.ModelBuildingUIFigure.CloseRequestFcn = createCallbackFcn(app, @ModelBuildingUIFigureCloseRequest, true);
            app.tbgMain = uitabgroup(app.ModelBuildingUIFigure);
            app.tbgMain.SelectionChangedFcn = createCallbackFcn(app, @tbgMainSelectionChanged, true);
            app.tbData = uitab(app.tbgMain);
            app.tbData.Title = 'Data';
            app.DataListBoxLabel = uilabel(app.tbData);
            app.DataListBoxLabel.Text = 'Data';
            app.lbData = uilistbox(app.tbData);
            app.lbData.Items = {};
            app.lbData.Multiselect = 'on';
            app.lbData.ValueChangedFcn = createCallbackFcn(app, @lbDataValueChanged, true);
            app.lbData.Value = {};
            app.lbChannels = uilistbox(app.tbData);
            app.lbChannels.Items = {};
            app.lbChannels.Multiselect = 'on';
            app.lbChannels.ValueChangedFcn = createCallbackFcn(app, @lbChannelsValueChanged, true);
            app.lbChannels.Enable = 'off';
            app.lbChannels.Value = {};
            app.cbBadChannels = uicheckbox(app.tbData);
            app.cbBadChannels.ValueChangedFcn = createCallbackFcn(app, @cbBadChannelsValueChanged, true);
            app.cbBadChannels.Text = {'Bad Channels'; ''};
            app.tbEEGPreprocessing = uitab(app.tbgMain);
            app.tbEEGPreprocessing.Title = 'EEG Preprocessing';
            app.FilterDropDownLabel = uilabel(app.tbEEGPreprocessing);
            app.FilterDropDownLabel.Text = 'Filter';
            app.ddFilterEEG = uidropdown(app.tbEEGPreprocessing);
            app.ddFilterEEG.Items = {};
            app.ddFilterEEG.Value = {};
            app.cbEEG = uicheckbox(app.tbEEGPreprocessing);
            app.cbEEG.ValueChangedFcn = createCallbackFcn(app, @cbEEGValueChanged, true);
            app.cbEEG.Text = 'EEG';
            app.cbEEG.Value = true;
            app.cbEMG = uicheckbox(app.tbEEGPreprocessing);
            app.cbEMG.Text = 'EMG';
            app.cbEOG = uicheckbox(app.tbEEGPreprocessing);
            app.cbEOG.Text = 'EOG';
            app.cbEOG.Value = true;
            app.lblSource = uilabel(app.tbEEGPreprocessing);
            app.lblSource.Text = 'Source';
            app.lblEEGBands = uilabel(app.tbEEGPreprocessing);
            app.lblEEGBands.Text = 'EEG Bands';
            app.cbWideband = uicheckbox(app.tbEEGPreprocessing);
            app.cbWideband.ValueChangedFcn = createCallbackFcn(app, @cbWidebandValueChanged, true);
            app.cbWideband.Text = 'Wideband';
            app.cbWideband.Value = true;
            app.cbDelta = uicheckbox(app.tbEEGPreprocessing);
            app.cbDelta.ValueChangedFcn = createCallbackFcn(app, @cbDeltaValueChanged, true);
            app.cbDelta.Text = 'Delta';
            app.cbDelta.Value = true;
            app.cbTheta = uicheckbox(app.tbEEGPreprocessing);
            app.cbTheta.ValueChangedFcn = createCallbackFcn(app, @cbThetaValueChanged, true);
            app.cbTheta.Text = 'Theta';
            app.cbTheta.Value = true;
            app.cbAlpha = uicheckbox(app.tbEEGPreprocessing);
            app.cbAlpha.ValueChangedFcn = createCallbackFcn(app, @cbAlphaValueChanged, true);
            app.cbAlpha.Text = 'Alpha';
            app.cbBeta = uicheckbox(app.tbEEGPreprocessing);
            app.cbBeta.ValueChangedFcn = createCallbackFcn(app, @cbBetaValueChanged, true);
            app.cbBeta.Text = 'Beta';
            app.edfWidebandLow = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfWidebandLow.Value = 0.5;
            app.edfDeltaLow = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfDeltaLow.Value = 1;
            app.Label = uilabel(app.tbEEGPreprocessing);
            app.Label.Text = '-';
            app.Label_2 = uilabel(app.tbEEGPreprocessing);
            app.Label_2.Text = '-';
            app.edfWidebandHigh = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfWidebandHigh.Value = 30;
            app.edfDeltaHigh = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfDeltaHigh.Value = 4;
            app.edfThetaLow = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfThetaLow.Value = 4;
            app.edfAlphaLow = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfAlphaLow.Enable = 'off';
            app.edfAlphaLow.Value = 7;
            app.Label_11 = uilabel(app.tbEEGPreprocessing);
            app.Label_11.Text = '-';
            app.Label_12 = uilabel(app.tbEEGPreprocessing);
            app.Label_12.Text = '-';
            app.edfThetaHigh = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfThetaHigh.Value = 7;
            app.edfAlphaHigh = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfAlphaHigh.Enable = 'off';
            app.edfAlphaHigh.Value = 13;
            app.edfBetaLow = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfBetaLow.Enable = 'off';
            app.edfBetaLow.Value = 13;
            app.Label_13 = uilabel(app.tbEEGPreprocessing);
            app.Label_13.Text = '-';
            app.edfBetaHigh = uieditfield(app.tbEEGPreprocessing, 'numeric');
            app.edfBetaHigh.Enable = 'off';
            app.edfBetaHigh.Value = 30;
            app.btnAdd = uibutton(app.tbEEGPreprocessing, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @btnAddButtonPushed, true);
            app.btnAdd.Text = '>>';
            app.btnRemove = uibutton(app.tbEEGPreprocessing, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Text = '<<';
            app.lbPreprocessing_Before = uilistbox(app.tbEEGPreprocessing);
            app.lbPreprocessing_Before.Items = {};
            app.lbPreprocessing_Before.Multiselect = 'on';
            app.lbPreprocessing_Before.Value = {};
            app.lbPreprocessing_After = uilistbox(app.tbEEGPreprocessing);
            app.lbPreprocessing_After.Items = {};
            app.lbPreprocessing_After.Multiselect = 'on';
            app.lbPreprocessing_After.Value = {};
            app.ProcessingpipelineLabel = uilabel(app.tbEEGPreprocessing);
            app.ProcessingpipelineLabel.Text = 'Processing pipeline';
            app.tbNIRSPreprocessing = uitab(app.tbgMain);
            app.tbNIRSPreprocessing.Title = 'NIRS Preprocessing';
            app.lblBaselineCorrection = uilabel(app.tbNIRSPreprocessing);
            app.lblBaselineCorrection.Text = 'Baseline Reference Selection';
            app.ddBaselineReference = uidropdown(app.tbNIRSPreprocessing);
            app.ddBaselineReference.Items = {'Self', 'Whole Signal', 'Baseline'};
            app.ddBaselineReference.Value = 'Self';
            app.OxyDeoConversionLabel = uilabel(app.tbNIRSPreprocessing);
            app.OxyDeoConversionLabel.Text = 'OxyDeo Conversion';
            app.ddOxyDeoxyConversion = uidropdown(app.tbNIRSPreprocessing);
            app.ddOxyDeoxyConversion.Items = {};
            app.ddOxyDeoxyConversion.Value = {};
            app.FilterLabel = uilabel(app.tbNIRSPreprocessing);
            app.FilterLabel.Text = 'Filter';
            app.ddNIRSFilter = uidropdown(app.tbNIRSPreprocessing);
            app.ddNIRSFilter.Items = {};
            app.ddNIRSFilter.Value = {};
            app.FilterRangeEditFieldLabel = uilabel(app.tbNIRSPreprocessing);
            app.FilterRangeEditFieldLabel.Text = 'Filter Range';
            app.edfNIRSLowcut = uieditfield(app.tbNIRSPreprocessing, 'numeric');
            app.edfNIRSLowcut.Value = 0.001;
            app.edfNIRSHighcut = uieditfield(app.tbNIRSPreprocessing, 'numeric');
            app.edfNIRSHighcut.Value = 0.3;
            app.Label_14 = uilabel(app.tbNIRSPreprocessing);
            app.Label_14.Text = '-';
            app.PreprocessingPipelineListBoxLabel = uilabel(app.tbNIRSPreprocessing);
            app.PreprocessingPipelineListBoxLabel.Text = 'Preprocessing Pipeline';
            app.lbNIRSPreprocessingBefore = uilistbox(app.tbNIRSPreprocessing);
            app.lbNIRSPreprocessingBefore.Items = {};
            app.lbNIRSPreprocessingBefore.Value = {};
            app.btnNIRSadd = uibutton(app.tbNIRSPreprocessing, 'push');
            app.btnNIRSadd.ButtonPushedFcn = createCallbackFcn(app, @btnNIRSaddButtonPushed, true);
            app.btnNIRSadd.Text = '>>';
            app.btnNIRSremove = uibutton(app.tbNIRSPreprocessing, 'push');
            app.btnNIRSremove.ButtonPushedFcn = createCallbackFcn(app, @btnNIRSremoveButtonPushed, true);
            app.btnNIRSremove.Text = '<<';
            app.lbNIRSPreprocessingAfter = uilistbox(app.tbNIRSPreprocessing);
            app.lbNIRSPreprocessingAfter.Items = {};
            app.lbNIRSPreprocessingAfter.Value = {};
            app.tbEEGFeature = uitab(app.tbgMain);
            app.tbEEGFeature.Title = 'EEG Feature';
            app.lbFeatures = uilistbox(app.tbEEGFeature);
            app.lbFeatures.Items = {};
            app.lbFeatures.Multiselect = 'on';
            app.lbFeatures.Value = {};
            app.FeatureextractionLabel = uilabel(app.tbEEGFeature);
            app.FeatureextractionLabel.Text = 'Feature extraction';
            app.tbNIRSFeature = uitab(app.tbgMain);
            app.tbNIRSFeature.Title = 'NIRS Feature';
            app.lbNIRSFeatures = uilistbox(app.tbNIRSFeature);
            app.lbNIRSFeatures.Items = {};
            app.lbNIRSFeatures.Multiselect = 'on';
            app.lbNIRSFeatures.Value = {};
            app.cbHbO = uicheckbox(app.tbNIRSFeature);
            app.cbHbO.Text = 'HbO';
            app.cbHbO.Value = true;
            app.cbHbT = uicheckbox(app.tbNIRSFeature);
            app.cbHbT.Text = 'HbT';
            app.cbHbT.Value = true;
            app.cbHbR = uicheckbox(app.tbNIRSFeature);
            app.cbHbR.Text = 'HbR';
            app.cbHbR.Value = true;
            app.NIRS_FeatureextractionLabel = uilabel(app.tbNIRSFeature);
            app.NIRS_FeatureextractionLabel.Text = 'Feature extraction';
            app.tbDimensionality = uitab(app.tbgMain);
            app.tbDimensionality.Title = 'Dimensionality';
            app.DimensionalityreductionmethodDropDownLabel = uilabel(app.tbDimensionality);
            app.DimensionalityreductionmethodDropDownLabel.Text = 'Dimensionality reduction method';
            app.ddDimensionalityReductionMethod = uidropdown(app.tbDimensionality);
            app.ddDimensionalityReductionMethod.Items = {};
            app.ddDimensionalityReductionMethod.ValueChangedFcn = createCallbackFcn(app, @ddDimensionalityReductionMethodValueChanged, true);
            app.ddDimensionalityReductionMethod.Value = {};
            app.ofFeaturesSliderLabel = uilabel(app.tbDimensionality);
            app.ofFeaturesSliderLabel.Text = '% of Features';
            app.sldFeatures = uislider(app.tbDimensionality);
            app.sldFeatures.Value = 50;
            app.cbApplyDimensionalityReduction = uicheckbox(app.tbDimensionality);
            app.cbApplyDimensionalityReduction.ValueChangedFcn = createCallbackFcn(app, @cbApplyDimensionalityReductionValueChanged, true);
            app.cbApplyDimensionalityReduction.Text = 'Apply Dimensionality Reduction';
            app.cbApplyDimensionalityReduction.Value = true;
            app.cbConsistency = uicheckbox(app.tbDimensionality);
            app.cbConsistency.ValueChangedFcn = createCallbackFcn(app, @cbConsistencyValueChanged, true);
            app.cbConsistency.Text = 'Check Feature Consistency over blocks';
            app.cbConsistency.Value = true;
            app.FeatureConsistencymethodDropDownLabel = uilabel(app.tbDimensionality);
            app.FeatureConsistencymethodDropDownLabel.Text = 'Feature Consistency method';
            app.ddConsistency = uidropdown(app.tbDimensionality);
            app.ddConsistency.Items = {};
            app.ddConsistency.Value = {};
            app.tbClassifier = uitab(app.tbgMain);
            app.tbClassifier.Title = 'Classifier';
            app.ClassifierDropDownLabel = uilabel(app.tbClassifier);
            app.ClassifierDropDownLabel.Text = 'Classifier';
            app.ddClassifier = uidropdown(app.tbClassifier);
            app.ddClassifier.Items = {};
            app.ddClassifier.Value = {};
            app.btnBuildModel = uibutton(app.tbClassifier, 'push');
            app.btnBuildModel.ButtonPushedFcn = createCallbackFcn(app, @btnBuildModelButtonPushed, true);
            app.btnBuildModel.Text = 'Build';
            app.ResultsTextArea = uitextarea(app.tbClassifier);
            app.ResultsTextArea.Editable = 'off';
            app.EditField = uieditfield(app.tbClassifier, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.tbClassifier, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_3 = uieditfield(app.tbClassifier, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_4 = uieditfield(app.tbClassifier, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Enable = 'off';
            app.btnClear = uibutton(app.tbClassifier, 'push');
            app.btnClear.ButtonPushedFcn = createCallbackFcn(app, @btnClearPushed, true);
            app.btnClear.Text = 'Clear';
            app.tbValidation = uitab(app.tbgMain);
            app.tbValidation.Title = 'Validation';
            app.ValidationDataLabel = uilabel(app.tbValidation);
            app.ValidationDataLabel.Text = 'Validation Data';
            app.lbValidationData = uilistbox(app.tbValidation);
            app.lbValidationData.Items = {};
            app.lbValidationData.Multiselect = 'on';
            app.lbValidationData.Value = {};
            app.TextArea = uitextarea(app.tbValidation);
            app.TextArea.Editable = 'off';
            app.btnValidate = uibutton(app.tbValidation, 'push');
            app.btnValidate.ButtonPushedFcn = createCallbackFcn(app, @btnValidatePushed, true);
            app.btnValidate.Text = 'Validate';
            app.ModelDropDownLabel = uilabel(app.tbValidation);
            app.ModelDropDownLabel.Text = 'Model';
            app.ddModel = uidropdown(app.tbValidation);
            app.ddModel.Items = {};
            app.ddModel.Value = {};
            app.btnClearValidation = uibutton(app.tbValidation, 'push');
            app.btnClearValidation.ButtonPushedFcn = createCallbackFcn(app, @btnClearValidationPushed, true);
            app.btnClearValidation.Text = 'Clear';
            app.btnNext = uibutton(app.ModelBuildingUIFigure, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNextPushed, true);
            app.btnNext.Text = 'Next';
            app.btnClearTest = uibutton(app.ModelBuildingUIFigure, 'push');
            app.btnClearTest.Visible = 'off';
            app.btnClearTest.Text = 'Clear';
            app.btnLoadModel = uibutton(app.ModelBuildingUIFigure, 'push');
            app.btnLoadModel.ButtonPushedFcn = createCallbackFcn(app, @btnLoadModelButtonPushed, true);
            app.btnLoadModel.Text = 'Load Model';
            % Show the figure after all components are created
            app.ModelBuildingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelBuilder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelBuildingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelBuildingUIFigure)
        end
    end
end