classdef selectQuestions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectOpenQuestionsUIFigure  matlab.ui.Figure
        QuestionTable                matlab.ui.control.Table
        SelectthequestionsyouwanttopresentLabel  matlab.ui.control.Label
        questionsrepeatedEditFieldLabel  matlab.ui.control.Label
        questionsrepeatedEditField   matlab.ui.control.NumericEditField
        timesLabel                   matlab.ui.control.Label
        questionsEditField           matlab.ui.control.NumericEditField
        TotalnumberofquestionsLabel  matlab.ui.control.Label
        TotalnumberofquestionsEditField  matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
        inDropDownLabel              matlab.ui.control.Label
        orderDropDown                matlab.ui.control.DropDown
        orderLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        oqApp % Description
        waitBar % Wait bar in the main window
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, oqApp)
            app.oqApp = oqApp;
            
            app.waitBar = uiprogressdlg(oqApp.FigHybridBCI, 'Title','Select open questions', 'Indeterminate','on');
            
            wavPath = oqApp.wavPath;
            allQuestions = dir([wavPath filesep '*.wav']);
            allNames = {allQuestions.name};
            allCheckBoxes = cell(size(allNames));
            allCheckBoxes(:) = {false};
            allCheckBoxes(ismember(allNames,oqApp.selectedQuestions)) = {true};
            
            app.QuestionTable.Data = [allCheckBoxes; allNames]';
            
            app.questionsEditField.Value = oqApp.numQuestions;
            
            app.questionsrepeatedEditField.Value = oqApp.numRepetition;
            
            app.questionsrepeatedEditFieldValueChanged();
        end

        % Cell edit callback: QuestionTable
        function QuestionTableCellEdit(app, event)
            newData = event.NewData;
            % Update the questions counter
            if newData == 1
                % If is selected add one
                app.questionsEditField.Value = app.questionsEditField.Value+1;
            else
                % Else subtract one
                app.questionsEditField.Value = app.questionsEditField.Value-1;
            end
            
            % Update the total questions counter
            app.TotalnumberofquestionsEditField.Value = app.questionsEditField.Value*app.questionsrepeatedEditField.Value;
        end

        % Value changed function: questionsrepeatedEditField
        function questionsrepeatedEditFieldValueChanged(app, event)
            % Update the total questions counter
            app.TotalnumberofquestionsEditField.Value = app.questionsEditField.Value*app.questionsrepeatedEditField.Value;
            
            % Check if enable/disable the order dropdown
            if app.questionsrepeatedEditField.Value == 1
                app.orderDropDown.Enable = 'Off';
            else
                app.orderDropDown.Enable = 'On';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Update the total number of questions
            app.questionsrepeatedEditFieldValueChanged()
            
            % Close the app
            app.closeRequestFcn();
        end

        % Close request function: SelectOpenQuestionsUIFigure
        function closeRequestFcn(app, event)
            % Find the selected questions
            indexTF = cell2mat(app.QuestionTable.Data(:,1));
            questions = app.QuestionTable.Data(indexTF,2);
            setQuestions(app.oqApp,questions,app.questionsrepeatedEditField.Value,app.orderDropDown.Value);
            
            close(app.waitBar);
            
            % Close the app
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectOpenQuestionsUIFigure = uifigure;
            app.SelectOpenQuestionsUIFigure.Name = 'Select Open Questions';
            app.SelectOpenQuestionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequestFcn, true);
            app.QuestionTable = uitable(app.SelectOpenQuestionsUIFigure);
            app.QuestionTable.ColumnName = {'Select'; 'Question name'};
            app.QuestionTable.RowName = {};
            app.QuestionTable.ColumnEditable = [true false];
            app.QuestionTable.CellEditCallback = createCallbackFcn(app, @QuestionTableCellEdit, true);
            app.SelectthequestionsyouwanttopresentLabel = uilabel(app.SelectOpenQuestionsUIFigure);
            app.SelectthequestionsyouwanttopresentLabel.Text = 'Select the questions you want to present';
            app.questionsrepeatedEditFieldLabel = uilabel(app.SelectOpenQuestionsUIFigure);
            app.questionsrepeatedEditFieldLabel.Text = 'questions repeated';
            app.questionsrepeatedEditField = uieditfield(app.SelectOpenQuestionsUIFigure, 'numeric');
            app.questionsrepeatedEditField.ValueChangedFcn = createCallbackFcn(app, @questionsrepeatedEditFieldValueChanged, true);
            app.questionsrepeatedEditField.Value = 1;
            app.timesLabel = uilabel(app.SelectOpenQuestionsUIFigure);
            app.timesLabel.Text = 'times';
            app.questionsEditField = uieditfield(app.SelectOpenQuestionsUIFigure, 'numeric');
            app.questionsEditField.Limits = [0 Inf];
            app.questionsEditField.Editable = 'off';
            app.TotalnumberofquestionsLabel = uilabel(app.SelectOpenQuestionsUIFigure);
            app.TotalnumberofquestionsLabel.Text = 'Total number of questions';
            app.TotalnumberofquestionsEditField = uieditfield(app.SelectOpenQuestionsUIFigure, 'numeric');
            app.TotalnumberofquestionsEditField.Editable = 'off';
            app.SaveButton = uibutton(app.SelectOpenQuestionsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.inDropDownLabel = uilabel(app.SelectOpenQuestionsUIFigure);
            app.inDropDownLabel.Text = 'in';
            app.orderDropDown = uidropdown(app.SelectOpenQuestionsUIFigure);
            app.orderDropDown.Items = {'Random', 'Sequential', 'Stacked'};
            app.orderDropDown.Enable = 'off';
            app.orderDropDown.Tooltip = {'Random: all questions presentend randomly (e.g. abaccb)'; 'Sequential: if questions are repeated the repetition is sequential (e.g. aaccbb)'; 'Stacked: if questions are repeated the repetition is block-wise (e.g. acbcba)'};
            app.orderDropDown.Value = 'Random';
            app.orderLabel = uilabel(app.SelectOpenQuestionsUIFigure);
            app.orderLabel.Text = 'order';
        end
    end

    methods (Access = public)

        % Construct app
        function app = selectQuestions(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectOpenQuestionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectOpenQuestionsUIFigure)
        end
    end
end