classdef initializeSpeller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InitializeSpellerFigure         matlab.ui.Figure
        Layout                          matlab.ui.container.Panel
        LayoutTable                     matlab.ui.control.Table
        SearchLayout                    matlab.ui.control.Button
        LayoutLabel                     matlab.ui.control.Label
        LayoutEditField                 matlab.ui.control.EditField
        SavelayoutButton                matlab.ui.control.Button
        ModifylayoutButton              matlab.ui.control.StateButton
        Settings                        matlab.ui.container.Panel
        SearchCorpus                    matlab.ui.control.Button
        CorpusLabel                     matlab.ui.control.Label
        CorpusEditField                 matlab.ui.control.EditField
        VoiceLabel                      matlab.ui.control.Label
        VoiceDropDown                   matlab.ui.control.DropDown
        RequiredYesLabel                matlab.ui.control.Label
        RequiredYesEditField            matlab.ui.control.NumericEditField
        RequiredNoLabel                 matlab.ui.control.Label
        RequiredNoEditField             matlab.ui.control.NumericEditField
        IfoppositeanswersDropDownLabel  matlab.ui.control.Label
        IfoppositeanswersDropDown       matlab.ui.control.DropDown
        TextEditField                   matlab.ui.control.EditField
        PlayButton                      matlab.ui.control.Button
        PaceLabel                       matlab.ui.control.Label
        PaceSpinner                     matlab.ui.control.Spinner
        StartingstringEditFieldLabel    matlab.ui.control.Label
        StartingstringEditField         matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        SetupthespellerLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        spellerFolder
        corpus % Description
        speller % Description
        spApp % Description
    end
    
    methods (Access = private)
        
        function layout = table2layout(app)
            data = app.LayoutTable.Data;
            layout.sector = struct('name',[],'letters',[]);
            layout.sector = repmat(layout.sector,1,size(data,1));
            for i = 1:size(data,1)
                layout.sector(i).name = data(i,1);
                layout.sector(i).letters = split(data(i,2))';
            end
        end
        
        function app = layout2table(app,layout)
            n = [layout.sector(:).name];
            l = cell(size(n));
            for i = 1:length(n)
                l(i) = join(layout.sector(i).letters);
            end
            app.LayoutTable.Data = [n;l]';
        end
    
        function app = wavSpeller(app)
%             if isempty(app.speller) 
%                 msgbox('Please select at the layout','Error','error', 'modal');
%                 return;
%             end
            
            % create wav names
            for s = 1:length(app.speller.sector)
                app.speller.sector(s).name_wav{1} = [app.spApp.wavPath filesep 'sector_' app.speller.sector(s).name{1} '.wav'];
                for l = 1:length(app.speller.sector(s).letters)
                    app.speller.sector(s).letters_wav{l} = [app.spApp.wavPath filesep 'letter_' app.speller.sector(s).letters{l} '.wav'];
                end
            end
            
            app.speller.exit.sector = [app.spApp.wavPath filesep 'exit_sectors.wav'];
            app.speller.exit.letter = [app.spApp.wavPath filesep 'exit_letters.wav'];
        end
    
        function [bag1,bag2,bag3] = createNgrams(app)
            % Load corpus
            str = extractFileText(app.CorpusEditField.Value);
            str = lower(str);
            str = erasePunctuation(str);
            textData = split(str,newline);
            textData = extractAfter(textData,sprintf('\t'));
            document = tokenizedDocument(textData);
            
            % Create ngrams
            bag1 = bagOfNgrams(document,'NgramLengths',1);
            bag2 = bagOfNgrams(document,'NgramLengths',2);
            bag3 = bagOfNgrams(document,'NgramLengths',3);
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, spApp)
            
            try
                corpusLanguage('درود');
            catch ME
                msgbox('You need TextAnalyticTookbox for the speller to work.','Error','error', 'modal');
                return;
            end
            
            % Change to Speller folder
            app.spellerFolder = which('initializeSpeller.mlapp');
            app.spellerFolder = extractBefore(app.spellerFolder,'initializeSpeller.mlapp');
            
            % Find all the voices
            [~,allVoices] = tts('','List');
            app.VoiceDropDown.Items = [allVoices(:).name] + " - " + [allVoices(:).description];
            app.VoiceDropDown.ItemsData = [allVoices(:).name];
            if ismember(spApp.voice,app.VoiceDropDown.ItemsData)
                app.VoiceDropDown.Value = spApp.voice;
            end
            
            % Check if exist a default layout
            if ~isempty(spApp.layoutPath)
                app.LayoutEditField.Value = spApp.layoutPath;
                app.LayoutEditFieldValueChanged();
            else
                defLayout = dir(fullfile(app.spellerFolder, 'Layout', 'Default.mat'));
                if ~isempty(defLayout)
                    app.LayoutEditField.Value = [defLayout.folder filesep defLayout.name];
                    app.LayoutEditFieldValueChanged();
                end
            end
            
            % Set the corpus
            if ~isempty(spApp.corpusPath)
                app.CorpusEditField.Value = spApp.corpusPath;
            end
            
            % Check if number of yes, no and conflict was already selected
            if ~isempty(spApp.nYes)
                app.RequiredYesEditField.Value = spApp.nYes;
            end
            if ~isempty(spApp.nNo)
                app.RequiredNoEditField.Value = spApp.nNo;
            end
            if ~isempty(spApp.conflict) && ismember(spApp.conflict,app.IfoppositeanswersDropDown.ItemsData)
                app.IfoppositeanswersDropDown.Value = spApp.conflict;
            end
            
            app.spApp = spApp;
        end

        % Button pushed function: SearchLayout
        function SearchLayoutButtonPushed(app, event)
            pathLayout = fullfile(app.spellerFolder, 'Layout', '*.mat');
            [layout_name, layout_path] = uigetfile(pathLayout,'Select a layout');
            app.LayoutEditField.Value = [layout_path filesep layout_name];
            app.LayoutEditFieldValueChanged();
        end

        % Value changed function: LayoutEditField
        function LayoutEditFieldValueChanged(app, event)
            value = app.LayoutEditField.Value;
            try
                layout = importdata(value);
                app.layout2table(layout);
                app.speller = layout;
            catch ME
                uialert(app.InitializeSpellerFigure,ME.message,'Invalid file');
            end
        end

        % Value changed function: CorpusEditField
        function CorpusEditFieldValueChanged(app, event)
            value = app.CorpusEditField.Value;
            app.corpus = value;
        end

        % Button pushed function: SearchCorpus
        function SearchCorpusButtonPushed(app, event)
            pathCorpora = fullfile(app.spellerFolder, 'Corpora', '*.txt');
            [corpus_name, corpus_path] = uigetfile(pathCorpora,'Select a corpus');
            app.CorpusEditField.Value = [corpus_path filesep corpus_name];
            app.CorpusEditFieldValueChanged();
        end

        % Value changed function: ModifylayoutButton
        function ModifylayoutButtonValueChanged(app, event)
            value = app.ModifylayoutButton.Value;
            if value
                app.LayoutTable.ColumnEditable = true;
                app.SavelayoutButton.Enable = true;
            else
                app.LayoutTable.ColumnEditable = false;
                app.SavelayoutButton.Enable = false;
            end
        end

        % Button pushed function: SavelayoutButton
        function SavelayoutButtonPushed(app, event)
            layout = app.table2layout();
            [file,path] = uiputfile(fullfile(app.spellerFolder, 'Layout', '*.mat'),'Save layout');
            if file
                if ~endsWith(file,'.mat')
                    file = [file '.mat'];
                end
                save([path filesep file],'layout')
                app.LayoutEditField.Value = [path filesep file '.mat'];
            end
        end

        % Cell edit callback: LayoutTable
        function LayoutTableCellEdit(app, event)
            layout = app.table2layout();
            app.speller = layout;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.InitializeSpellerFigureCloseRequest();
        end

        % Close request function: InitializeSpellerFigure
        function InitializeSpellerFigureCloseRequest(app, event)
            prog = uiprogressdlg(app.InitializeSpellerFigure,'Title','Saving speller','Indeterminate','on');
            % Save speller
            app.wavSpeller();
            app.spApp.speller = app.speller;
            
            % Save num of sectors and letters
            app.spApp.numSectors = length(app.speller.sector);
            app.spApp.numLetters = cellfun('length',{app.speller.sector(:).letters});
            
            % Save ngrams
            [bag1,bag2,bag3] = app.createNgrams();
            top1 = topkngrams(bag1,bag1.NumNgrams);
            top2 = topkngrams(bag2,bag2.NumNgrams);
            top3 = topkngrams(bag3,bag3.NumNgrams);
            app.spApp.bag1 = top1;
            app.spApp.bag2 = top2;
            app.spApp.bag3 = top3;
            
            % Save voice
            app.spApp.voice = app.VoiceDropDown.Value;
            app.spApp.pace = app.PaceSpinner.Value;
            
            % Save string
            app.spApp.startingString = string(app.StartingstringEditField.Value());
            
            % Save corpus and layout paths
            app.spApp.corpusPath = app.CorpusEditField.Value;
            app.spApp.layoutPath = app.LayoutEditField.Value;
            
            % Save number of yes and no and conflict resolution
            app.spApp.nYes = app.RequiredYesEditField.Value;
            app.spApp.nNo = app.RequiredNoEditField.Value;
            app.spApp.conflict = app.IfoppositeanswersDropDown.Value;
            
            close(prog)
            delete(app)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            text = app.TextEditField.Value;
            voice = app.VoiceDropDown.Value;
            pace = app.PaceSpinner.Value;
            tts(text,voice,pace);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InitializeSpellerFigure = uifigure('Visible', 'off');
            app.InitializeSpellerFigure.Name = 'Speller Configuration';
            app.InitializeSpellerFigure.CloseRequestFcn = createCallbackFcn(app, @InitializeSpellerFigureCloseRequest, true);
            app.Layout = uipanel(app.InitializeSpellerFigure);
            app.Layout.Title = 'Layout';
            app.LayoutTable = uitable(app.Layout);
            app.LayoutTable.ColumnName = {'Sectors'; 'Letters'};
            app.LayoutTable.RowName = {};
            app.LayoutTable.ColumnEditable = false;
            app.LayoutTable.CellEditCallback = createCallbackFcn(app, @LayoutTableCellEdit, true);
            app.SearchLayout = uibutton(app.Layout, 'push');
            app.SearchLayout.ButtonPushedFcn = createCallbackFcn(app, @SearchLayoutButtonPushed, true);
            app.SearchLayout.Text = {'...'; ''};
            app.LayoutLabel = uilabel(app.Layout);
            app.LayoutLabel.Text = 'Layout:';
            app.LayoutEditField = uieditfield(app.Layout, 'text');
            app.LayoutEditField.ValueChangedFcn = createCallbackFcn(app, @LayoutEditFieldValueChanged, true);
            app.SavelayoutButton = uibutton(app.Layout, 'push');
            app.SavelayoutButton.ButtonPushedFcn = createCallbackFcn(app, @SavelayoutButtonPushed, true);
            app.SavelayoutButton.Enable = 'off';
            app.SavelayoutButton.Text = 'Save layout';
            app.ModifylayoutButton = uibutton(app.Layout, 'state');
            app.ModifylayoutButton.ValueChangedFcn = createCallbackFcn(app, @ModifylayoutButtonValueChanged, true);
            app.ModifylayoutButton.Text = 'Modify layout';
            app.Settings = uipanel(app.InitializeSpellerFigure);
            app.Settings.Title = 'Settings';
            app.SearchCorpus = uibutton(app.Settings, 'push');
            app.SearchCorpus.ButtonPushedFcn = createCallbackFcn(app, @SearchCorpusButtonPushed, true);
            app.SearchCorpus.Text = {'...'; ''};
            app.CorpusLabel = uilabel(app.Settings);
            app.CorpusLabel.Text = 'Corpus:';
            app.CorpusEditField = uieditfield(app.Settings, 'text');
            app.CorpusEditField.ValueChangedFcn = createCallbackFcn(app, @CorpusEditFieldValueChanged, true);
            app.VoiceLabel = uilabel(app.Settings);
            app.VoiceLabel.Text = 'Voice:';
            app.VoiceDropDown = uidropdown(app.Settings);
            app.VoiceDropDown.Items = {};
            app.VoiceDropDown.Value = {};
            app.RequiredYesLabel = uilabel(app.Settings);
            app.RequiredYesLabel.Text = 'Required Yes:';
            app.RequiredYesEditField = uieditfield(app.Settings, 'numeric');
            app.RequiredYesEditField.Value = 2;
            app.RequiredNoLabel = uilabel(app.Settings);
            app.RequiredNoLabel.Text = 'Required No:';
            app.RequiredNoEditField = uieditfield(app.Settings, 'numeric');
            app.RequiredNoEditField.Value = 1;
            app.IfoppositeanswersDropDownLabel = uilabel(app.Settings);
            app.IfoppositeanswersDropDownLabel.Text = 'If opposite answers:';
            app.IfoppositeanswersDropDown = uidropdown(app.Settings);
            app.IfoppositeanswersDropDown.Items = {'Use last answer', 'Ask another time'};
            app.IfoppositeanswersDropDown.ItemsData = {'last', 'majority'};
            app.IfoppositeanswersDropDown.Value = 'last';
            app.TextEditField = uieditfield(app.Settings, 'text');
            app.TextEditField.Tooltip = {'hhvk'};
            app.TextEditField.Value = 'Hello I am a computer voice';
            app.PlayButton = uibutton(app.Settings, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PaceLabel = uilabel(app.Settings);
            app.PaceLabel.Text = 'Pace:';
            app.PaceSpinner = uispinner(app.Settings);
            app.StartingstringEditFieldLabel = uilabel(app.Settings);
            app.StartingstringEditFieldLabel.Text = 'Starting string:';
            app.StartingstringEditField = uieditfield(app.Settings, 'text');
            app.SaveButton = uibutton(app.InitializeSpellerFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SetupthespellerLabel = uilabel(app.InitializeSpellerFigure);
            app.SetupthespellerLabel.Text = 'Setup the speller';
            % Show the figure after all components are created
            app.InitializeSpellerFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initializeSpeller(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InitializeSpellerFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InitializeSpellerFigure)
        end
    end
end