classdef BrainVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrainVisionRecorderUIFigure  matlab.ui.Figure
        lblPatient                   matlab.ui.control.Label
        taReport                     matlab.ui.control.TextArea
        IPEditFieldLabel             matlab.ui.control.Label
        efIP                         matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        efPort                       matlab.ui.control.NumericEditField
        SRateEditFieldLabel          matlab.ui.control.Label
        efSRate                      matlab.ui.control.NumericEditField
        btnStart                     matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function ShowMessage(app,msg)
            msg = strcat({'- '}, msg);
            app.taReport.Value = [msg; app.taReport.Value; ];
            drawnow;
        end
        
        function hdr = ReadHeader(app, con)
            
            % con    tcpip connection object
            % define a struct for the header
            hdr = struct('uid',[],'size',[],'type',[]);
            
            % read id, size and type of the message
            % swapbytes is important for correct byte order of MATLAB variables
            % pnet behaves somehow strange with byte order option
            
            hdr.uid = pnet(con,'read', 16);
            hdr.size = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
            hdr.type = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
            
        end
        
        function props = ReadStartMessage(app,con, hdr)
            % con    tcpip connection object
            % hdr    message header
            % props  returned eeg properties
            
            % define a struct for the EEG properties
            props = struct('channelCount',[],'samplingInterval',[],'resolutions',[],'channelNames',[]);
            
            % read EEG properties
            props.channelCount = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
            props.samplingInterval = swapbytes(pnet(con,'read', 1, 'double', 'network'));
            props.resolutions = swapbytes(pnet(con,'read', props.channelCount, 'double', 'network'));
            allChannelNames = pnet(con,'read', hdr.size - 36 - props.channelCount * 8);
            props.channelNames = app.SplitChannelNames(allChannelNames);
        end
        
        function  channelNames = SplitChannelNames(app, allChannelNames)
            % allChannelNames   all channel names together in an array of char
            % channelNames      channel names splitted in a cell array of strings
            
            % cell array to return
            channelNames = {};
            
            % helper for actual name in loop
            name = [];
            
            % loop over all chars in array
            for i = 1:length(allChannelNames)
                if allChannelNames(i) ~= 0
                    % if not a terminating zero, add char to actual name
                    name = [name allChannelNames(i)];
                else
                    % add name to cell array and clear helper for reading next name
                    channelNames = [channelNames {name}];
                    name = [];
                end
            end
        end
        
        function [datahdr, data, markers] = ReadDataMessage(app, con, hdr, props)
            % con       tcpip connection object
            % hdr       message header
            % props     eeg properties
            % datahdr   data header with information on datalength and number of markers
            % data      data as one dimensional arry
            % markers   markers as array of marker structs
            
            % Define data header struct and read data header
            datahdr = struct('block',[],'points',[],'markerCount',[]);
            
            datahdr.block = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
            datahdr.points = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
            datahdr.markerCount = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
            
            % Read data in float format
            data = swapbytes(pnet(con,'read', props.channelCount * datahdr.points, 'single', 'network'));
            
            % Define markers struct and read markers
            markers = struct('size',[],'position',[],'points',[],'channel',[],'type',[],'description',[]);
            for m = 1:datahdr.markerCount
                marker = struct('size',[],'position',[],'points',[],'channel',[],'type',[],'description',[]);
                
                % Read integer information of markers
                marker.size = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
                marker.position = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
                marker.points = swapbytes(pnet(con,'read', 1, 'uint32', 'network'));
                marker.channel = swapbytes(pnet(con,'read', 1, 'int32', 'network'));
                
                % type and description of markers are zero-terminated char arrays
                % of unknown length
                c = pnet(con,'read', 1);
                while c ~= 0
                    marker.type = [marker.type c];
                    c = pnet(con,'read', 1);
                end
                
                c = pnet(con,'read', 1);
                while c ~= 0
                    marker.description = [marker.description c];
                    c = pnet(con,'read', 1);
                end
                
                % Add marker to array
                markers(m) = marker;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnStart
        function btnStartButtonPushed(app, event)
            while ~exist([pwd filesep 'tempFile.mat'])
                app.ShowMessage('Experiment info  not found!');
                WaitSecs(5);
            end
            
            % Fixed by Ale
            tempFile = importdata([pwd filesep 'tempFile.mat']);
            while tempFile.isEEGUpdated
                app.ShowMessage('The main interface is not updated!');
                WaitSecs(5);
                tempFile = importdata([pwd filesep 'tempFile.mat']);
            end
            load(tempFile.pathToConfigFile);
            
            app.ShowMessage('Connecting to BrainVision');
            con = pnet('tcpconnect', app.efIP.Value, app.efPort.Value);
            if con < 0
                app.ShowMessage('Error occured connecting to EEG. Try again.');
                pnet('closeall');
                return;
            end
            
            tempFile.isEEGUpdated = true;
            save([pwd filesep 'tempFile.mat'],'tempFile');
            
            subpath = configinfo.subpath;
            conf_path = [subpath filesep date filesep];
            
            srate = app.efSRate.Value;
            epoch = configinfo.EEGDuration;
            bsepoch = configinfo.BaselineSentence;
            bbepoch = configinfo.BaselineBlock;
            bpepoch = configinfo.BaselinePresentation;
            blocknum = configinfo.blocknum;
            
            fileoutname = [conf_path filesep 'EEG' filesep 'mat' filesep date '_Block' num2str(blocknum) '_EEGdata' ];
            
            %TBF: 1000 -> srate
            bb_length = bbepoch*1000;
            bs_length = bsepoch*1000;
            bp_length = bpepoch*1000;
            epoch_length = epoch*1000;
            
            header_size = 24;
            finish = false;
            m1=1;
            [m2,m3,m4,m5,m6,m7,m8,m9,m10,m11,m12,m13,m15]=deal(m1);
            BB = []; % baseline block
            BT = []; % baseline true
            BF = []; % baseline false
            BO = []; % baseline open
            LWT = [];
            LWF = [];
            LWO = [];
            LWS = []; % variable for sleep detection
            
            dataRecived.baseLines = [];
            dataRecived.thinkingPeriodes = [];
            dataRecived.questionLabels = [];
            dataRecived.sRate = 0;
            dataRecived.ChannelLabels = {};
            dataRecived.presentationBaseline = []; % To save presentation baseline
            
            totaltrial = 0;
            
            while ~finish
                
                % check for existing data in socket buffer
                tryheader = pnet(con, 'read', header_size, 'byte', 'network', 'view', 'noblock');
                while ~isempty(tryheader) && ~finish
                    
                    % Read header of RDA message
                    hdr = app.ReadHeader(con);
                    
                    switch hdr.type
                        case 1       % Start, Setup information like EEG properties
                            app.ShowMessage('Connection Started');
                            
                            % Read and display EEG properties
                            props = app.ReadStartMessage(con, hdr);
                            app.ShowMessage(['No of Channels: ' num2str(props.channelCount)]);
                            app.ShowMessage(['Channels: ' strjoin(props.channelNames,', ')]);
                            app.ShowMessage(['Sampling Rate: ' num2str(1/props.samplingInterval*1000000)]);
                            dataRecived.ChannelLabels = props.channelNames;
                            dataRecived.sRate = 1/props.samplingInterval*1000000;
                            
                            % Reset block counter to check overflows
                            lastBlock = -1;
                            
                        case 4       % 32Bit Data block
                            
                            % Read data and markers from message
                            [datahdr, data, markers] = app.ReadDataMessage(con, hdr, props);
                            
                            % check tcpip buffer overflow
                            if lastBlock ~= -1 && datahdr.block > lastBlock + 1
                                app.ShowMessage(['******* Overflow with ' int2str(datahdr.block - lastBlock) ' blocks ******']);
                            end
                            
                            lastBlock = datahdr.block;
                            
                            % Check triggers
                            if datahdr.markerCount > 0
                                mark = markers.description;
                                switch mark
                                    case 'S  1' % head TRUE sentence
                                        p1 = markers.position;
                                        app.ShowMessage(['True sentence: ' mark ' position: ' num2str(p1)]);
                                        
                                    case 'S  2' % head FALSE sentence
                                        p2 = markers.position;
                                        app.ShowMessage(['False sentence: ' mark ' position: ' num2str(p2)]);
                                        
                                    case 'S  3' % head OPEN sentence
                                        p3 = markers.position;
                                        app.ShowMessage(['Open sentence: ' mark ' position: ' num2str(p3)]);
                                        
                                    case 'S  4' % ISI (end TRUE sentence)
                                        p4 = markers.position;
                                        app.ShowMessage(['ISI true sentence: ' mark ' position: ' num2str(p4)]);
                                        
                                    case 'S  5' % LW TRUE sentence
                                        p5 = markers.position;
                                        app.ShowMessage(['LW true sentence: ' mark ' position: ' num2str(p5)]);
                                        
                                    case 'S  6' % LW FALSE sentence
                                        p6 = markers.position;
                                        app.ShowMessage(['LW false sentence: ' mark ' position: ' num2str(p6)]);
                                        
                                    case 'S  7' % LW OPEN sentence
                                        p7 = markers.position;
                                        app.ShowMessage(['LW open sentence: ' mark ' position: ' num2str(p7)]);
                                        
                                    case 'S  8' % ISI (end FALSE sentence)
                                        p8 = markers.position;
                                        app.ShowMessage(['ISI false sentence: ' mark ' position: ' num2str(p8)]);
                                        
                                    case 'S  9' % baseline before block start
                                        p9 = markers.position;
                                        app.ShowMessage(['Block Start: ' mark ' position: ' num2str(p9)]);
                                        
                                    case 'S 10' % baseline before TRUE sentence (~10s)
                                        p10 = markers.position;
                                        app.ShowMessage(['Baseline true sentence: ' mark ' position: ' num2str(p10)]);
                                        
                                    case 'S 11' % baseline before FALSE sentence (~10s)
                                        p11 = markers.position;
                                        app.ShowMessage(['Baseline false sentence: ' mark ' position: ' num2str(p11)]);
                                        
                                    case 'S 12' % baseline before OPEN sentence (~10s)
                                        p12 = markers.position;
                                        app.ShowMessage(['Baseline open sentence: ' mark ' position: ' num2str(p12)]);
                                        
                                    case 'S 13' % ISI OPEN sentence
                                        p13 = markers.position;
                                        app.ShowMessage(['ISI open sentence: ' mark ' position: ' num2str(p13)]);
                                    
                                    % Added by Ale
                                    case 'S 14'
                                        p14 = markers.position;
                                        app.ShowMessage(['Block stopped: ' mark ' position: ' num2str(p14)]);
                                        
                                    case 'S 15'
                                        p15 = markers.position;
                                        app.ShowMessage(['Block end: ' mark ' position: ' num2str(p15)]);
                                        
                                    otherwise
                                        app.ShowMessage(['Unknown marker: ' mark]);
                                end
                            end
                            
                            % 1. baseline before block start (1min)
                            if exist('p9','var') & m9 == 1
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BB = [BB Data(:,p9+1:end)];
                                m9 = m9 + 1;
                            elseif exist('p9','var')
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BB = [BB Data];
                            end
                            dim9 = size(BB);
                            if dim9(2) > bb_length*1000 / props.samplingInterval
                                R.BB = BB(:,1:bb_length*1000 / props.samplingInterval); % extract BB here
                                BB = []; % reset the data buffer
                                m9 = 1;
                                clear Pxx p9 ix f Pxx Data
                            end
                            
                            % 2. baseline before TRUE sentece onset (10s)
                            if exist('p10','var') & m10 == 1
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BT = [BT Data(:,p10+1:end)];
                                m10 = m10 + 1;
                            elseif exist('p10','var')
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BT = [BT Data];
                            end
                            dim10 = size(BT);
                            if dim10(2) > bs_length*1000 / props.samplingInterval
                                
                                tr = BT(:,1:bs_length*1000 / props.samplingInterval); % extract BB here
                                BT = []; % reset the data buffer
                                dataRecived.baseLines = cat(3, dataRecived.baseLines, tr);
                                dataRecived.questionLabels = [dataRecived.questionLabels 0];
                                m10 = 1;
                                clear Pxx p10 ix f Pxx tr
                            end
                            
                            % 3. baseline before FALSE sentece onset (10s)
                            if exist('p11','var') & m11 == 1
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BF = [BF Data(:,p11+1:end)];
                                m11 = m11 + 1;
                            elseif exist('p11','var')
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BF = [BF Data];
                            end
                            dim11 = size(BF);
                            if dim11(2) > bs_length*1000 / props.samplingInterval
                                tr = BF(:,1:bs_length*1000 / props.samplingInterval); % extract BB here
                                BF = []; % reset the data buffer
                                dataRecived.baseLines = cat(3, dataRecived.baseLines, tr);
                                dataRecived.questionLabels = [dataRecived.questionLabels 1];
                                m11 = 1;
                                clear Pxx p11 ix f Pxx tr
                            end
                            
                            % 4. baseline before OPEN sentece onset (10s)
                            if exist('p12','var') & m12 == 1
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BO = [BO Data(:,p12+1:end)];
                                m12 = m12 + 1;
                            elseif exist('p12','var')
                                Data = reshape(data, props.channelCount, length(data)/props.channelCount);
                                BO = [BO Data];
                            end
                            dim12 = size(BO);
                            if dim12(2) > bs_length*1000 / props.samplingInterval
                                tr = BO(:,1:bs_length*1000 / props.samplingInterval); % extract BB here
                                BO = []; % reset the data buffer
                                dataRecived.baseLines = cat(3, dataRecived.baseLines, tr);
                                dataRecived.questionLabels = [dataRecived.questionLabels 2];
                                m12 = 1;
                                clear Pxx p12 ix f Pxx tr
                            end
                            
                            % 5. LW of TRUE sentences
                            if exist('p5','var') & m5==1
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWS = [LWS Data(:,p5+1:end)];
                                m5 = m5 + 1;
                            elseif exist('p5','var')
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWS = [LWS Data];
                            end
                            dim5 = size(LWS);
                            
                            % LW of FALSE sentences
                            if exist('p6','var') & m6==1
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWS = [LWS Data(:,p6+1:end)];
                                m6 = m6 + 1;
                            elseif exist('p6','var')
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWS = [LWS Data];
                            end
                            dim5 = size(LWS);  % dim5 is the length of LW data
                            
                            %  LW of OPEN sentences
                            if exist('p7','var') & m7==1
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWS = [LWS Data(:,p7+1:end)];
                                m7 = m7 + 1;
                            elseif exist('p7','var')
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWS = [LWS Data];
                            end
                            dim5 = size(LWS);
                            
                            
                            % 6. ISI of TRUE sentences
                            if exist('p4','var') & m4==1
                                if size(LWS,2) == (bp_length*1000/props.samplingInterval)
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBasline, LWS); % We save the data in presentation baseline
                                    LWS = []; % Reset baseline buffer
                                elseif size(LWS,2) > (bp_length*1000/props.samplingInterval)
                                    newPresentationBaseline = LWS(:,size(LWS,2)-(bp_length*1000/props.samplingInterval)+1:end);
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBaseline,newPresentationBaseline);
                                    LWS = []; % Reset baseline buffer
                                    clear newPresentationBaseline;
                                else
                                    sizeDifference = bp_length*1000/props.samplingInterval - size(LWS,2);
                                    differenceBaseline = dataRecived.baseLines(:,size(dataRecived.baseLines,2)-sizeDifference+1:end);
                                    newPresentationBaseline = cat(2,differenceBaseline,LWS);
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBaseline,newPresentationBaseline);
                                    LWS = []; % Reset baseline buffer
                                    clear newPresentationBaseline differenceBaseline sizeDifference;
                                end
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWT = [LWT Data(:,p4+1:end)];
                                m4 = m4 + 1;
                            elseif exist('p4','var')
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWT = [LWT Data];
                            end
                            dim6 = size(LWT);
                            
                            if dim6(2) > epoch_length*1000 / props.samplingInterval
                                tr = LWT(:,1:epoch_length*1000 / props.samplingInterval);
                                LWT = [];
                                dataRecived.thinkingPeriodes = cat(3, dataRecived.thinkingPeriodes, tr);
                                m4 = 1;
                                totaltrial = totaltrial+1;
                                lastTrial.baseline = dataRecived.baseLines(:,:,end);
                                lastTrial.thinkingPeriod = dataRecived.thinkingPeriodes(:,:,end);
                                lastTrial.questionLabel = dataRecived.questionLabels(end);
                                lastTrial.channelLabels = dataRecived.ChannelLabels;
                                save([pwd filesep 'lastTrialEEG.mat'],'lastTrial');
                                clear Pxx p4 tr dim6 alpha Data
                            end
                            
                            
                            % 7. ISI of FALSE sentences
                            if exist('p8','var') & m8==1
                                if size(LWS,2) == (bp_length*1000/props.samplingInterval)
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBasline, LWS); % We save the data in presentation baseline
                                    LWS = []; % Reset baseline buffer
                                elseif size(LWS,2) > (bp_length*1000/props.samplingInterval)
                                    newPresentationBaseline = LWS(:,size(LWS,2)-(bp_length*1000/props.samplingInterval)+1:end);
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBaseline,newPresentationBaseline);
                                    LWS = []; % Reset baseline buffer
                                    clear newPresentationBaseline;
                                else
                                    sizeDifference = bp_length*1000/props.samplingInterval - size(LWS,2);
                                    differenceBaseline = dataRecived.baseLines(:,size(dataRecived.baseLines,2)-sizeDifference+1:end);
                                    newPresentationBaseline = cat(2,differenceBaseline,LWS);
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBaseline,newPresentationBaseline);
                                    LWS = []; % Reset baseline buffer
                                    clear newPresentationBaseline differenceBaseline sizeDifference;
                                end
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWF = [LWF Data(:,p8+1:end)];
                                m8 = m8 + 1;
                            elseif exist('p8','var')
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWF = [LWF Data];
                            end
                            dim7 = size(LWF);
                            if dim7(2) > epoch_length*1000 / props.samplingInterval
                                tr = LWF(:,1:epoch_length*1000 / props.samplingInterval);
                                LWF = [];
                                dataRecived.thinkingPeriodes = cat(3, dataRecived.thinkingPeriodes, tr);
                                m8 = 1;
                                totaltrial = totaltrial+1;
                                lastTrial.baseline = dataRecived.baseLines(:,:,end);
                                lastTrial.thinkingPeriod = dataRecived.thinkingPeriodes(:,:,end);
                                lastTrial.questionLabel = dataRecived.questionLabels(end);
                                lastTrial.channelLabels = dataRecived.ChannelLabels;
                                save([pwd filesep 'lastTrialEEG.mat'],'lastTrial');
                                clear Pxx p8 tr dim7 alpha Data
                            end
                            
                            % 8. ISI of OPEN sentences
                            if exist('p13','var') & m13==1
                                if size(LWS,2) == (bp_length*1000/props.samplingInterval)
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBasline, LWS); % We save the data in presentation baseline
                                    LWS = []; % Reset baseline buffer
                                elseif size(LWS,2) > (bp_length*1000/props.samplingInterval)
                                    newPresentationBaseline = LWS(:,size(LWS,2)-(bp_length*1000/props.samplingInterval)+1:end);
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBaseline,newPresentationBaseline);
                                    LWS = []; % Reset baseline buffer
                                    clear newPresentationBaseline;
                                else
                                    sizeDifference = bp_length*1000/props.samplingInterval - size(LWS,2);
                                    differenceBaseline = dataRecived.baseLines(:,size(dataRecived.baseLines,2)-sizeDifference+1:end);
                                    newPresentationBaseline = cat(2,differenceBaseline,LWS);
                                    dataRecived.presentationBaseline = cat(3, dataRecived.presentationBaseline,newPresentationBaseline);
                                    LWS = []; % Reset baseline buffer
                                    clear newPresentationBaseline differenceBaseline sizeDifference;
                                end
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWO = [LWO Data(:,p13+1:end)];
                                m13 = m13 + 1;
                            elseif exist('p13','var')
                                Data = reshape(data, props.channelCount, length(data) / props.channelCount);
                                LWO = [LWO Data];
                            end
                            dim8 = size(LWO);
                            if dim8(2) > epoch_length*1000 / props.samplingInterval
                                tr = LWO(:,1:epoch_length*1000 / props.samplingInterval);
                                LWO = [];
                                dataRecived.thinkingPeriodes = cat(3, dataRecived.thinkingPeriodes, tr);
                                m13 = 1;
                                totaltrial = totaltrial+1;
                                lastTrial.baseline = dataRecived.baseLines(:,:,end);
                                lastTrial.thinkingPeriod = dataRecived.thinkingPeriodes(:,:,end);
                                lastTrial.questionLabel = dataRecived.questionLabels(end);
                                lastTrial.channelLabels = dataRecived.ChannelLabels;
                                save([pwd filesep 'lastTrialEEG.mat'],'lastTrial');
                                clear Pxx alpha tr Data p13 dim8
                            end
                            
                            % 9. End of Block\\
                            if exist('p15','var')
                                app.ShowMessage('End of block');
                                save(fileoutname,'dataRecived');
                                data = pnet(con, 'read', hdr.size - header_size);
                                finish = true;
                            end
                            
                            % Added by Ale
                            if exist('p14','var')
                                app.ShowMessage('Block stopped');
                                save(fileoutname,'dataRecived');
                                data = pnet(con, 'read', hdr.size - header_size);
                                finish = true;
                            end
                            
                        case 3       % Stop message
                            app.ShowMessage('Stop');
                            
                            %TBF: check if you have save datarecived here also
                            %save(fileoutname,'R');
                            
                            data = pnet(con, 'read', hdr.size - header_size);
                            finish = true;
                            
                        otherwise    % ignore all unknown types, but read the package from buffer
                            data = pnet(con, 'read', hdr.size - header_size);
                    end
                    tryheader = pnet(con, 'read', header_size, 'byte', 'network', 'view', 'noblock');
                end
            end
            
            pnet('closeall');
            
        end

        % Close request function: BrainVisionRecorderUIFigure
        function BrainVisionRecorderUIFigureCloseRequest(app, event)

            delete(app)
            quit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BrainVisionRecorderUIFigure = uifigure('Visible', 'off');
            app.BrainVisionRecorderUIFigure.Name = 'BrainVisionRecorder';
            app.BrainVisionRecorderUIFigure.CloseRequestFcn = createCallbackFcn(app, @BrainVisionRecorderUIFigureCloseRequest, true);
            app.lblPatient = uilabel(app.BrainVisionRecorderUIFigure);
            app.lblPatient.Text = 'Patient:  ';
            app.taReport = uitextarea(app.BrainVisionRecorderUIFigure);
            app.taReport.Editable = 'off';
            app.IPEditFieldLabel = uilabel(app.BrainVisionRecorderUIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.efIP = uieditfield(app.BrainVisionRecorderUIFigure, 'text');
            app.efIP.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.BrainVisionRecorderUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.efPort = uieditfield(app.BrainVisionRecorderUIFigure, 'numeric');
            app.efPort.Limits = [1 Inf];
            app.efPort.ValueDisplayFormat = '%.0f';
            app.efPort.Value = 51244;
            app.SRateEditFieldLabel = uilabel(app.BrainVisionRecorderUIFigure);
            app.SRateEditFieldLabel.Text = 'SRate';
            app.efSRate = uieditfield(app.BrainVisionRecorderUIFigure, 'numeric');
            app.efSRate.Limits = [1 5000];
            app.efSRate.Value = 500;
            app.btnStart = uibutton(app.BrainVisionRecorderUIFigure, 'push');
            app.btnStart.ButtonPushedFcn = createCallbackFcn(app, @btnStartButtonPushed, true);
            app.btnStart.Text = 'Update';
            % Show the figure after all components are created
            app.BrainVisionRecorderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainVision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrainVisionRecorderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrainVisionRecorderUIFigure)
        end
    end
end