classdef NIRSport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NIRSportUIFigure    matlab.ui.Figure
        lblPatient          matlab.ui.control.Label
        taReport            matlab.ui.control.TextArea
        IPEditFieldLabel    matlab.ui.control.Label
        efIP                matlab.ui.control.EditField
        PortEditFieldLabel  matlab.ui.control.Label
        efPort              matlab.ui.control.NumericEditField
        btnStart            matlab.ui.control.Button
    end

    
    properties (Access = private)
        isStopped % If the program is stopped by the user
    end
    
    methods (Access = private)
        
        function ShowMessage(app,msg)
            msg = strcat({'- '}, msg);
            app.taReport.Value = [msg; app.taReport.Value; ];
            drawnow;
        end
        
        function stopnirs(app)
            if libisloaded('TomographyAPI')
                TM_stop();
                TM_disconnect();
                TM_close();
            end
        end
        
        function [error, numFrames, numChannels, numWav, chName, sampleRate ] = initial(app)
            % Modified by Ale
            error = 0;
            numFrames = 0;
            numChannels = 0;
            numWav = 0;
            chName = {};
            sampleRate = 0;
            
            address = app.efIP.Value;
            port = app.efPort.Value;
            timeout = 5000;
            
            % Load SDK API
            if libisloaded('TomographyAPI')== 0
                loadlibrary('TomographyAPI.dll','TomographyAPI.h');
            end
            app.ShowMessage('SDK API library loaded');
            
            % Initialize
            error_out = TM_initialize();
            if error_out~=0
                errorMsg = TM_getErrorMsg(error_out);
                app.ShowMessage(['Error occured initializing NIRS: ' errorMsg]);
                app.stopnirs();
                error = 1;
                return
            end
            app.ShowMessage('Initialization: OK');
            
            %Initialize the API
            error_out=TM_connect(address, port, timeout);
            if error_out~=0
                errorMsg = TM_getErrorMsg(error_out);
                app.ShowMessage(['Error occured connecting to NIRS: ' errorMsg]);
                app.stopnirs();
                error = 1;
                return
            end
            app.ShowMessage('Connection: OK');
            
            %Connect to server
            [error_out, statusFlagO, sampleRate] = TM_getStatus();             %Check the server status
            if error_out~=0
                errorMsg = TM_getErrorMsg(error_out);
                app.ShowMessage(['Error occured connecting to server: ' errorMsg]);
                app.stopnirs();
                error = 1;
                return
            end
            app.ShowMessage('Status server: OK')
            
            % [error_out2,pName] = TM_getName();                                  %Check desired parameters names
            [error_out, numFrames, numChannels, numWav] = TM_getChannels(); %Retrieve the number of available channels
            if error_out~=0
                errorMsg = TM_getErrorMsg(error_out);
                app.ShowMessage(['Error occured retrieving channels: ' errorMsg]);
                app.stopnirs();
                error = 1;
                return
            end
            frameSize = numFrames*numChannels*numWav;
            app.ShowMessage('Retrieve channels: OK')
            
            % Modified by Ale: it retrievs the name of the channels
            chName = {1,numChannels};
            for c = 1:numChannels
                [error_out(c),chName{c}] = TM_getName(1,c-1);                                  %Check desired parameters names
            end
            if any(error_out)
                errorMsg = TM_getErrorMsg(error_out);
                app.ShowMessage(['Error occured retrieving channels'' names: ' errorMsg]);
                app.stopnirs();
                error = 1;
                return
            end
            app.ShowMessage('Retrive channels'' names: OK')
            
            v = int32([]); pv = libpointer('int32Ptr',v);                 % Set up a pointer
            %Ask the server to start streaming
            [error_out, sources, detectors, wavelengths, elementsPerFrame]  = TM_start(pv, pv, pv, numFrames, numChannels, numWav, frameSize);
            if error_out~=0
                errorMsg = TM_getErrorMsg(error_out);
                app.ShowMessage(['Error occured starting streaming: ' errorMsg]);
                app.stopnirs();
                error = 1;
                return
            end
            app.ShowMessage('Start streaming: OK');
            
% %             Check if the data acquisition is ok
%             reqFrames = 1;
%             bufferSize = reqFrames * frameSize;
%             [error_out, frameCount, timestamps, timingBytes, tempdata, dataBufferSize]  = TM_getNFrames(reqFrames,timeout,bufferSize);
%             if error_out~=0
%                 errorMsg = TM_getErrorMsg(error_out);
%                 app.ShowMessage(['Error occured acquiring: ' errorMsg]);
%                 app.stopnirs();
%                 error = 1;
%                 return
%             elseif ~any(tempdata)
%                 app.ShowMessage('No data acquired: check acquisition mode')
%                 app.stopnirs();
%                 error = 1;
%             end
            app.ShowMessage('API prepared to receive data.');
        end
        
        function [ frameCount,ttimestamps, timingBytes, data] = getdata(app, numFrames, numChannels, numWav,framecount,timeout,fs )
            %GETDATA Summary of this function goes here
            %   Detailed explanation goes here
            reqFrames = 1;
            data=[];
            ttimestamps=[];
            tempdata=[];
            timestamps=[];
            frameSize = numFrames*numChannels*numWav;
            bufferSize = reqFrames * frameSize;
            n=1;
            % tic
            while framecount
                
                [error_out, frameCount, timestamps, timingBytes, tempdata, dataBufferSize]  = TM_getNFrames(reqFrames,timeout,bufferSize);
                
                
                data=[data;tempdata];
                ttimestamps=[ttimestamps;timestamps];
                
                if dataBufferSize ~= bufferSize                          %Check buffer Size
                    bufferSize = dataBufferSize;
                    
                end
                if frameCount == 0
                    app.ShowMessage('The data is not acquired');
                    app.stopnirs();
                    return
                end
                framecount=framecount-1;
                if framecount==0
                    break
                end
                
            end
            data = data';
            %HbX = app.LBG_data(app,data,fs);
            %data = HbX.dat';
        end
        
%         function [ hbohb ] = LBG_data(app, dat,fs )
%             %LBG_DATA Summary of this function goes here
%             %   Detailed explanation goes here
%             ni.wl = [850 760]; %inverted the values (2017-11-14)
%             ni.LBsf = fs;
%             ni.LBodistance= 30; %changed from 3 to 30 (2017-11-14)
%             ni.LBlpfreq= 0.5;
%             ni.LBdpf= [5.9800 7.1500];
%             ni.LBepsilon = [2.5264    1.7986; 1.4866 3.8437]; %inverted the rows (2017-11-14)
%             ni.LBmode=1;
%             
%             hbohb=app.onlineLBG(dat, ni);
%         end
        
%         function ni=onlineLBG(app,dat,param)
%             %         calculate OD/Intensity and concentration changes
%             %----------------------------------------------------------------
%             % fun:     u_LBG / u_popLBG
%             %
%             %
%             %----------------------------------
%             %            function
%             %----------------------------------
%             %    [inter-optode distance]          : between S&D in cm
%             %    [baseline]             : In the online version, the activation is
%             %    computed with all the given points. That means that only those desired
%             %    to be in the baseline should be given
%             %    [select from spectrum] : select source of absorbtion-coefficients/epsilon
%             %       ->the absorbtion coefficients are plottet below (epsilon of oxy-Hb / deoxy-Hb)
%             %    NOTE     : if not indicated before : select specific wavelengths before selecting a spectrum
%             %    [DPF]    : select DP-factor (essenpreis)
%             %
%             %=======================================================================
%             %                                                      Paul, BNIC 2007
%             %=======================================================================
%             
%             
%             
%             
%             %----------------------------------
%             %         calc concentration changes
%             %----------------------------------
%             
%             e              =  param.LBepsilon;
%             DPF            =  param.LBdpf;
%             
%             Loptoddistance  =  param.LBodistance;
%             lpfreq          =  param.LBlpfreq;
%             sf              =  param.LBsf;
%             
%             %----------------------------------
%             %      1. split data to lower and higher wavelength
%             %----------------------------------
%             
%             s1=size(dat,1);
%             s2=size(dat,2);
%             
%             dat=dat+eps;
%             dat = double(dat);
%             
%             %Obtain the basleine
%             bl_loweWL =    mean(dat(:,1     :  s2/2));%lower Wavelength
%             bl_highWL =    mean(dat(:,s2/2+1:  end));%higher Wavelength
%             
%             %Low pass filter the activation signal
%             
%             lk = lpfreq*2/sf;
%             
%             %butterworth filter of 3rd order
%             [m,n]=butter(3,lk,'low');
%             
%             lp_loweWL=    filtfilt(m,n,(dat(:,1     :  s2/2)));%lower Wavelength
%             lp_highWL=    filtfilt(m,n,(dat(:,s2/2+1:  end)));%higher Wavelength
%             
%             flag = isfield(param,'LBmode');
%             %----------------------------------
%             %       2.OD
%             %----------------------------------
%             
%             for i = 1:s2/2
%                 normHighWL(:,i) = lp_highWL(:,i) ./ bl_highWL(1,i);
%                 normlowWL(:,i) = lp_loweWL(:,i) ./ bl_loweWL(1,i);
%             end
%             
%             Att_highWL= real(-log( normHighWL ))    ; %changed to natural log (2017-11-14)
%             
%             Att_loweWL= real(-log( normlowWL ))    ; %changed to natural log (2017-11-14)
%             
%             A= [Att_highWL Att_loweWL];
%             
%             
%             
%             
%             if 1
%                 e=e/10;
%                 
%                 e2=   e.* [DPF' DPF']  .*  Loptoddistance;
%                 for i = 1:s2/2
%                     c(i).dat = ( inv(e2)*[Att_highWL(:,i)';Att_loweWL(:,i)']) ;
%                     cc_oxy (:,i) =c(1,i).dat(1,:)'; %in mmol/l
%                     cc_deo (:,i) =c(1,i).dat(2,:)'; %in mmol/l
%                 end
%             end
%             
%             
%             if 0
%                 tot_ecoef =  [0.9291    0.7987
%                     0.3123    2.1382]
%                 coefMat = pinv(tot_ecoef);
%                 
%                 for  i = 1:size(dat_highWL,2)
%                     dum=[];
%                     dum = [Att_highWL(:,i)';Att_loweWL(:,i)']'*coefMat;
%                     cc_oxy(:,i)=dum(:,1);
%                     cc_deo(:,i)=dum(:,2);
%                     
%                     
%                 end
%                 
%                 
%             end
%             
%             
%             
%             
%             % Backward Abacktrafo= c/inv(e2');back to OD
%             
%             %----------------------------------
%             %         tags
%             %----------------------------------
%             
%             
%             ni.dat=[cc_oxy cc_deo];
%             
%             if isfield(ni,'functions')==0
%                 ni.functions{1,1}=[    'ni=u_LBG(1,ni);  % calc concentration changes'];
%             else
%                 ni.functions{end+1,1}=[ 'ni=u_LBG(1,ni); % calc concentration changes'];
%             end
%             
%             if isfield(ni,'info')==0
%                 ni.info{1,1}=    ['**  concentration changes calculated' ];
%             else
%                 ni.info{end+1,1}=['**  concentration changes calculated' ];
%             end
%             
%         end
    end
    

    methods (Access = private)

        % Button pushed function: btnStart
        function btnStartButtonPushed(app, event)
            if strcmp(app.btnStart.Text, 'Update')
                app.isStopped = false;
                app.btnStart.Text = 'Stop';
                drawnow;
            while ~exist([pwd filesep 'tempFile.mat'],'file')
                if app.isStopped
                    return
                end
                app.ShowMessage('Experiment info  not found!');
                WaitSecs(5);
            end
            
            tempFile = importdata([pwd filesep 'tempFile.mat']);
            while tempFile.isNIRSUpdated
                if app.isStopped
                    return
                end
                app.ShowMessage('The main interface is not updated!');
                WaitSecs(5);
                tempFile = importdata([pwd filesep 'tempFile.mat']);
            end
            configinfo = importdata(tempFile.pathToConfigFile);
            
            app.ShowMessage('Connecting to NIRSport');
            
            addpath(genpath('NIRX API'));
            
            [error, numFrames, numChannels, numWav, chName, srate] = app.initial();
            
            if error
                app.ShowMessage('Not connected')
                app.btnStart.Text = 'Update';
                return
            end
            
            msg = sprintf('Number of channels: %d, Sampling Rate: %.3fHz\n',length(chName), srate);
            app.ShowMessage(msg);
            app.ShowMessage('Connection Started')
            
            tempFile.isNIRSUpdated = true;
            save([pwd filesep 'tempFile.mat'],'tempFile');
            
            subpath = configinfo.subpath;
            conf_path = [subpath filesep date filesep];
            
            %srate = app.efSRate.Value;
            epoch = configinfo.NIRSDuration;
            bsepoch = configinfo.BaselineSentence;
            
            % Added by Ale to avoid missing triggers
            %epoch = configinfo.NIRSDuration-0.1;
            %bsepoch = configinfo.BaselineSentence-0.1;
            
            bbepoch = configinfo.BaselineBlock;
            blocknum = configinfo.blocknum;
            
            bframecount = floor(srate*bsepoch);
            dframecount = floor(srate*epoch);
            timeout = 5000;
            
            % Added by Ale to avoid missing triggers
            bframecount = bframecount-1;
            dframecount = dframecount-1;
            
            baslitrig=[10,11,12];
            datrig=[4,8,13];
            finish = [14,15];
            
            % the default paramater for NIRSport
            reqFrames = 1;
            
            % the framesize of one sample
            frameSize = numFrames*numChannels*numWav;
            bufferSize = reqFrames * frameSize;
            
            fileoutname = [conf_path filesep 'NIRS' filesep 'mat' filesep date '_Block' num2str(blocknum) '_NIRSdata' ];
            
            
            dataRecived.baseLines = [];
            dataRecived.thinkingPeriodes = [];
            dataRecived.questionLabels = [];
            dataRecived.sRate = srate;
            nch = length(chName);
            for n = 1:nch
                chName{nch+n} = [chName{n} ' > 850'];
                chName{n} = [chName{n} ' > 760'];
            end
            dataRecived.ChannelLabels = chName;
            
            totaltrial = 0;
            
            while ~app.isStopped
                % monitor cycle
                [error_out, frameCount, timestamps, timingBytes, tempdata, dataBufferSize]  = TM_getNFrames(reqFrames,timeout,bufferSize);
                
                %get the trigger
                if ~isempty(timingBytes)
                    trig = zeros(size(timingBytes));
                    for nt = 1:length(timingBytes)
                        trig(nt) = cast(timingBytes(nt),'uint16');
                    end
                    app.ShowMessage([num2str(length(timingBytes)) ' triggers: ' num2str(trig)])
                    tB_i = cast(timingBytes(1,1),'uint16');
                    
                    app.ShowMessage(['Mark: ' num2str(tB_i)]);
                    
                    % get the baseline data
                    if ismember(tB_i,baslitrig)
                        
                        [  bframeCount,btimestamps, btimingBytes, bdata] = app.getdata(  numFrames, numChannels, numWav,bframecount,timeout, srate );
                        
                        %record the baseline data
                        dataRecived.baseLines=cat(3,dataRecived.baseLines,bdata);
                        switch tB_i
                            case 10
                                dataRecived.questionLabels = [dataRecived.questionLabels 0];
                            case 11
                                dataRecived.questionLabels = [dataRecived.questionLabels 1];
                            case 12
                                dataRecived.questionLabels = [dataRecived.questionLabels 2];
                        end
                        
                    end
                    
                    % get the feedback data
                    if ismember(tB_i,datrig)
                        
                        [  frameCount,ttimestamps, timingBytes, tdata] = app.getdata(  numFrames, numChannels, numWav,dframecount,timeout, srate );
                        
                        %record the task data
                        dataRecived.thinkingPeriodes=cat(3,dataRecived.thinkingPeriodes,tdata);
                        
                        % save last trial
                        totaltrial = totaltrial+1;
                        lastTrial.baseline = dataRecived.baseLines(:,:,end);
                        lastTrial.thinkingPeriod = dataRecived.thinkingPeriodes(:,:,end);
                        lastTrial.questionLabel = dataRecived.questionLabels(end);
                        lastTrial.channelLabels = dataRecived.ChannelLabels;
                        save([pwd filesep 'lastTrialNIRS.mat'],'lastTrial');
                        
                    end
                    
                    % end the loop if the system receives the finish label
                    if ismember(tB_i,finish)
                        switch tB_i
                            case 14
                                app.ShowMessage('Block stopped');
                            case 15
                                app.ShowMessage('End of block');
                        end
                        
                        save(fileoutname,'dataRecived');
                        app.isStopped = true;
                        app.stopnirs();
                        
                    end
                    
                end
                
            end
            app.btnStart.Text = 'Update';
            elseif strcmp(app.btnStart.Text, 'Stop')
                app.isStopped = true;
                app.stopnirs()
                app.btnStart.Text = 'Update';
            end
            
        end

        % Close request function: NIRSportUIFigure
        function NIRSportUIFigureCloseRequest(app, event)
            
            app.stopnirs()
            delete(app)
            quit;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NIRSportUIFigure = uifigure;
            app.NIRSportUIFigure.Name = 'NIRSport';
            app.NIRSportUIFigure.CloseRequestFcn = createCallbackFcn(app, @NIRSportUIFigureCloseRequest, true);
            app.lblPatient = uilabel(app.NIRSportUIFigure);
            app.lblPatient.Text = 'Patient:  ';
            app.taReport = uitextarea(app.NIRSportUIFigure);
            app.taReport.Editable = 'off';
            app.IPEditFieldLabel = uilabel(app.NIRSportUIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.efIP = uieditfield(app.NIRSportUIFigure, 'text');
            app.efIP.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.NIRSportUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.efPort = uieditfield(app.NIRSportUIFigure, 'numeric');
            app.efPort.Limits = [1 Inf];
            app.efPort.ValueDisplayFormat = '%.0f';
            app.efPort.Value = 45342;
            app.btnStart = uibutton(app.NIRSportUIFigure, 'push');
            app.btnStart.ButtonPushedFcn = createCallbackFcn(app, @btnStartButtonPushed, true);
            app.btnStart.Text = 'Update';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NIRSport

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NIRSportUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NIRSportUIFigure)
        end
    end
end